classdef DefSampScale < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        OKButton   matlab.ui.control.Button
        DefineSampleSpecificScalingFactorsLabel  matlab.ui.control.Label
        SampTable  matlab.ui.control.Table
    end

    
    properties (Access = private)
        CallingApp % MainApp object
        maxSamp    % largest number of Samples in groups
        nGroups      % number of Groups
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, nGroups, maxSamp)
            %Store input values
            app.CallingApp = mainapp;
            app.nGroups = nGroups;
            app.maxSamp = maxSamp;
            %Build Table
            dat = ones(app.maxSamp,app.nGroups);
            ColumnNames(1:app.nGroups)= "Group ";
            nums=[1:1:app.nGroups];
            ColumnNames = ColumnNames + nums;
            app.SampTable.ColumnName = ColumnNames;
            trueness=true(size(nums));
            app.SampTable.ColumnEditable=trueness;
            RowNames(1:app.maxSamp,1) = "Sample ";
            nums=[1:1:app.maxSamp];
            RowNames=RowNames+nums';
            app.SampTable.RowName=RowNames;
            app.SampTable.Data=dat;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            %%Check Correct Inputs
            %Check Scaling Factor 0
            if any(app.SampTable.Data(:,2)==0) == true
                uialert(app.UIFigure,'Scaling Factor can´t be 0, use 1 instead','Invalid Input');
            else
                UIFigureCloseRequest(app)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %get numbers of samples per group
            nSamp=cellfun('size', app.CallingApp.RawResults(1,:),1);
            %assign TableData to group/sample
            for G=1:app.nGroups
                app.CallingApp.SampScaleVal{1,G}(:,1)=app.SampTable.Data(1:nSamp(G),G);
            end
            %Close Dialoge box
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SampTable = uitable(app.UIFigure);
            app.SampTable.ColumnName = '';
            app.SampTable.RowName = {};
            app.DefineSampleSpecificScalingFactorsLabel = uilabel(app.UIFigure);
            app.DefineSampleSpecificScalingFactorsLabel.Text = 'Define Sample Specific Scaling Factors';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DefSampScale(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end