classdef AriumMS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow                     matlab.ui.Figure
        DEBUGButton                    matlab.ui.control.Button
        DataProcessingPanel            matlab.ui.container.Panel
        ProcessFilesButton             matlab.ui.control.Button
        RemoveSelectedGroupButton      matlab.ui.control.Button
        CopySettingstoallGroupsButton  matlab.ui.control.Button
        DefineAugmentationandReferencingButton  matlab.ui.control.Button
        SwitchView                     matlab.ui.control.Switch
        SwitchViewLabel                matlab.ui.control.Label
        FeatureScalingPanel            matlab.ui.container.Panel
        FDR                            matlab.ui.control.NumericEditField
        FalseDiscoveryRateLabel        matlab.ui.control.Label
        Hochberg                       matlab.ui.control.CheckBox
        ApplyChangesButton             matlab.ui.control.Button
        minimumFoldChangeEditField     matlab.ui.control.NumericEditField
        minimumFoldChangeEditFieldLabel  matlab.ui.control.Label
        maximumpValueEditField         matlab.ui.control.NumericEditField
        maximumpValueEditFieldLabel    matlab.ui.control.Label
        TransformationDropDown         matlab.ui.control.DropDown
        TransformationDropDownLabel    matlab.ui.control.Label
        ScalingDropDown                matlab.ui.control.DropDown
        ScalingDropDownLabel           matlab.ui.control.Label
        GeneralOptionsPanel            matlab.ui.container.Panel
        SaveButton                     matlab.ui.control.Button
        SelectSaveLoc                  matlab.ui.control.Button
        ImportData                     matlab.ui.control.Button
        alpha60Label                   matlab.ui.control.Label
        AriumMSLabel                   matlab.ui.control.Label
    end

    properties (Access = public)
        % Internal States
        groupCounter            {mustBeInteger} = 0
        selectedGroup           {mustBeInteger,mustBePositive} = 1
        currentPlot             (2,:) logical = false
        selectedFolder          {mustBeFolder} =  "C:\Users"
        saveLocation            (1,1) string
        dataBaseFile            (1,1) string
        colorPalette            (12,3) double

        % Data
        MassData                (1,:) cell
        FeatureData             (1,:) FeatData
        FullFeatureData         (1,1) struct

        transformationType      (1,1) string {mustBeMember(transformationType,["none","logn","log10","power","cube","reciprocal"])} = "none"
        dataScalingType         (1,1) string {mustBeMember(dataScalingType,["none","Centering","Auto","Pareto","Vast","Range","Level"])} = "none"
        maxP                    (1,1) double {mustBeInRange(maxP,0,1)} = 0.05
        minFold                 (1,1) double {mustBePositive} = 2
        hochbergFilterFlag      (1,1) logical = false
        falseDiscoveryRate      (1,1) double {mustBeInRange(falseDiscoveryRate,0,1)} = 0.1

        augmentationAssignment  (:,2) categorical
        mergedAssignment        (:,2) categorical
        mergeMassTolerance      (1,1) double {mustBePositive} = 0.01
        mergeMassUnit           (1,1) string {mustBeMember(mergeMassUnit,["Da","ppm"])} = "Da"
        mergeTimeTolerance      (1,1) double {mustBePositive} = 5
        
        %Panels
        MainOptionsPanel        (1,:) MainOptions
        ROIOptionsPanel         (1,:) ROIOptions
        IntegrationOptionsPanel (1,:) IntegrationOptions
        ISPanel                 (1,:) ISOptions
        PeakAlignPanel          (1,:) PeakAlignOptions
        MSAlignPanel            (1,:) MSAlignOptions
        BaselinePanel           (1,:) BaseCorrOptions
        GolayPanel              (1,:) GolayOptions
        AddNLossPanel           (1,:) AddNLossOptions
        ScalingPanel            (1,:) SampScaleOptions
        AugmentOptions
        ReportTable
        ResultSubDisplay
        %% Group UI Elements
        % RawData Stage
        TabContainer        (1,1) matlab.ui.container.TabGroup
        Tab                 (1,:) matlab.ui.container.Tab
        GroupLabel          (1,:) matlab.graphics.Graphics
        InfoLabels          (5,:) matlab.graphics.Graphics
        GroupNameField      (1,:) matlab.ui.control.EditField
        SelectFilesButton   (1,:) matlab.ui.control.Button
        PreviewButton       (1,:) matlab.ui.control.Button
        EICButton           (1,:) matlab.ui.control.Button
        PreviewAxes         (1,:) matlab.ui.control.UIAxes
        PreviewTiled        (1,:) matlab.graphics.layout.TiledChartLayout
        PreviewChrom        (1,:) matlab.graphics.Graphics
        PreviewMZ           (1,:) matlab.graphics.Graphics
        % evaluation plots
        VulcanoPlot         (1,:) matlab.graphics.axis.Axes
        PCA3Dplot           (1,1) matlab.graphics.axis.Axes
        PCAMultiplot        (1,2) matlab.graphics.layout.TiledChartLayout
        ScatterPlot         (1,:) matlab.graphics.axis.Axes
        CloudPlot           (1,:) matlab.graphics.axis.Axes
        % FeatData Stage
        EvaluationObject    EvaluationOptions
        DataBase            MSDataBase


    end
    methods (Access = private)
        %% Constructor Functions
        function app = TabSelection(app,src,event)
            % identify current tab and construct new one when + tab is selected
            t = event.NewValue;
            app.selectedGroup = t.UserData;
            if t.Title == '+'
                delete(app.TabContainer.Children(end))
                app.MassData(app.groupCounter+1) = {[]};
                app.constructNewTab;
                app.TabContainer.SelectedTab = app.Tab(app.groupCounter);

                %readd +-Tab
                uitab(src,'Title','+',Tooltip="Add additional group");
                app.selectedGroup = app.TabContainer.SelectedTab.UserData;

                app.CheckReady;
            end
        end

        function app = constructNewTab(app)
            app.groupCounter = app.groupCounter + 1;
            if app.groupCounter > 1
                app.RemoveSelectedGroupButton.Enable = 'on';
            end
            %construct new Tab
            app.Tab(app.groupCounter) = uitab(app.TabContainer,'Title',"Group " + app.groupCounter,'UserData',app.groupCounter);

            app.GroupNameField(app.groupCounter) = uieditfield(app.Tab(app.groupCounter),"text",...
                Position=[10,610,110,20],...
                CharacterLimits=[1,16],...
                Value="Group " + app.groupCounter,...
                HorizontalAlignment="center",...
                Tooltip="Change the group name");
            app.GroupNameField(app.groupCounter).ValueChangedFcn = @(src,event) {groupNameChange(app,src,event,app.selectedGroup)};

            app.GroupLabel(1,app.groupCounter) = uilabel(app.Tab(app.groupCounter),"Text","Group Label:",...
                "Position",[10,630,110,20],...
                "HorizontalAlignment","center",...
                "VerticalAlignment","center");

            app.SelectFilesButton(app.groupCounter) = uibutton(app.Tab(app.groupCounter),...
                "Text","Select Files",...
                "Position",[10,565,110,40]);
            app.SelectFilesButton(app.groupCounter).ButtonPushedFcn = @(src,event) {selectFiles(app,src,event,app.selectedGroup)};

            %build preview Parameter Button
            app.PreviewButton(app.groupCounter) = uibutton(app.Tab(app.groupCounter),...
                Position=[385,565,75,50],...
                Text=["Preview"; "Parameters"],...
                Visible=false);
            app.PreviewButton(app.groupCounter).ButtonPushedFcn = @(src,event) {testParameters(app,src,event,app.selectedGroup)};

            app.EICButton(app.groupCounter) = uibutton(app.Tab(app.groupCounter),...
                Position=[300,565,75,50],...
                Text=["Plot"; "EICs"],...
                Visible=false);
            app.EICButton(app.groupCounter).ButtonPushedFcn = @(src,event) {plotEIC(app,src,event,app.selectedGroup)};

            app.PreviewAxes(app.groupCounter) = uiaxes(app.Tab(app.groupCounter), ...
                OuterPosition=[470 200 680 420], ...
                PositionConstraint="outerposition", ...
                Visible="off");

            app.PreviewTiled(app.groupCounter) = tiledlayout(app.Tab(app.groupCounter),2,1, ...
                "TileSpacing","compact", ...
                Padding="compact", ...
                Units="pixels", ...
                OuterPosition=[470 200 680 420], ...
                PositionConstraint="outerposition", ...
                Visible="off");

            app.currentPlot(1:2,app.groupCounter) = false;
            app.InfoLabels(:,app.groupCounter) = gobjects(5,1);

            %add placeholder panels
            app.MainOptionsPanel(app.groupCounter) = MainOptions;
            app.ROIOptionsPanel(app.groupCounter) = ROIOptions;
            app.IntegrationOptionsPanel(app.groupCounter) = IntegrationOptions;
            app.MSAlignPanel(app.groupCounter) = MSAlignOptions;
            app.PeakAlignPanel(app.groupCounter) = PeakAlignOptions;
            app.BaselinePanel(app.groupCounter) = BaseCorrOptions;
            app.GolayPanel(app.groupCounter) = GolayOptions;
            app.ScalingPanel(app.groupCounter) = SampScaleOptions;
            app.ISPanel(app.groupCounter) = ISOptions;
            app.AddNLossPanel(app.groupCounter) = AddNLossOptions;
        end

        function addMainOptions(app,CurrentTab)
            %construct UI elements
            app.MainOptionsPanel(CurrentTab) = MainOptions(app.Tab(CurrentTab),app.MassData{CurrentTab});
            %add callbackfcn
            app.MainOptionsPanel(CurrentTab).BlankTickBox.ValueChangedFcn = @(src,event) {useBlankChange(app,src,event,CurrentTab)};
            app.MainOptionsPanel(CurrentTab).PeakAlignmentTickBox.ValueChangedFcn = @(src,event) {usePeakAlignmentChange(app,src,event,CurrentTab)};
            app.MainOptionsPanel(CurrentTab).MSAlignmentTickBox.ValueChangedFcn = @(src,event) {useMassAlignmentChange(app,src,event,CurrentTab)};
            app.MainOptionsPanel(CurrentTab).BaseCorrTickBox.ValueChangedFcn = @(src,event) {useBaselineCorrectionChange(app,src,event,CurrentTab)};
            app.MainOptionsPanel(CurrentTab).GolayTickBox.ValueChangedFcn = @(src,event) {useSmoothingChange(app,src,event,CurrentTab)};
            app.MainOptionsPanel(CurrentTab).ISTDTickBox.ValueChangedFcn = @(src,event) {useInternalStandardChange(app,src,event,CurrentTab)};
            app.MainOptionsPanel(CurrentTab).IsotopeFilterTickBox.ValueChangedFcn = @(src,event) {useIsotopeFilterChange(app,src,event,CurrentTab)};
            app.MainOptionsPanel(CurrentTab).ScalingTickBox.ValueChangedFcn = @(src,event) {useScalingChange(app,src,event,CurrentTab)};
            app.MainOptionsPanel(CurrentTab).AddNLossTickBox.ValueChangedFcn = @(src,event) {useAddNLossChange(app,src,event,CurrentTab)};
            app.MainOptionsPanel(CurrentTab).ContaminantTickBox.ValueChangedFcn = @(src,event) {useContaminantFilterChange(app,src,event,CurrentTab)};
        end

        function addROIOptions(app,CurrentTab)
            %construct UI elements
            app.ROIOptionsPanel(CurrentTab) = ROIOptions(app.Tab(CurrentTab),app.MassData{CurrentTab});
            %add Callback functions
            app.ROIOptionsPanel(CurrentTab).ThreshField.ValueChangedFcn = @(src,event) {ThreshChange(app,src,event,CurrentTab)};
            app.ROIOptionsPanel(CurrentTab).mzerrorField.ValueChangedFcn = @(src,event) {mzerrorChange(app,src,event,CurrentTab)};
            app.ROIOptionsPanel(CurrentTab).mzerrorUnitDrop.ValueChangedFcn = @(src,event) {mzerrorUnitChange(app,src,event,CurrentTab)};
            app.ROIOptionsPanel(CurrentTab).minROIField.ValueChangedFcn = @(src,event) {minROIChange(app,src,event,CurrentTab)};
            app.ROIOptionsPanel(CurrentTab).StartTimeField.ValueChangedFcn = @(src,event) {STimeChange(app,src,event,CurrentTab)};
            app.ROIOptionsPanel(CurrentTab).EndTimeField.ValueChangedFcn = @(src,event) {ETimeChange(app,src,event,CurrentTab)};
        end

        function addIntegrationOptions(app,CurrentTab)
            %construct UI elements
            app.IntegrationOptionsPanel(CurrentTab) = IntegrationOptions(app.Tab(CurrentTab),app.MassData{CurrentTab});
            %add Callback functions
            app.IntegrationOptionsPanel(CurrentTab).EntropyCheck.ValueChangedFcn = @(src,event) {useEntropyChange(app,src,event,CurrentTab)};
            app.IntegrationOptionsPanel(CurrentTab).EntropyDrop.ValueChangedFcn = @(src,event) {entropyStrengthChange(app,src,event,CurrentTab)};
            app.IntegrationOptionsPanel(CurrentTab).OccurrenceField.ValueChangedFcn = @(src,event) {minOccurrenceChange(app,src,event,CurrentTab)};
            app.IntegrationOptionsPanel(CurrentTab).minSNField.ValueChangedFcn = @(src,event) {minSNChange(app,src,event,CurrentTab)};
            app.IntegrationOptionsPanel(CurrentTab).maxWidthField.ValueChangedFcn = @(src,event) {maxWidthChange(app,src,event,CurrentTab)};
            app.IntegrationOptionsPanel(CurrentTab).minWidthField.ValueChangedFcn = @(src,event) {minWidthChange(app,src,event,CurrentTab)};
            app.IntegrationOptionsPanel(CurrentTab).EvalParamDrop.ValueChangedFcn = @(src,event) {evalParameterChange(app,src,event,CurrentTab)};
            app.IntegrationOptionsPanel(CurrentTab).mzTolField.ValueChangedFcn = @(src,event) {betweenFileMassToleranceChange(app,src,event,CurrentTab)};
            app.IntegrationOptionsPanel(CurrentTab).mzTolUnitDrop.ValueChangedFcn = @(src,event) {betweenFileMassUnitChange(app,src,event,CurrentTab)};
            app.IntegrationOptionsPanel(CurrentTab).RTTolField.ValueChangedFcn = @(src,event) {peakTimeToleranceChange(app,src,event,CurrentTab)};
        end

        function addMSAlignPanel(app,CurrentTab)
            %construct UI elements
            app.MSAlignPanel(CurrentTab) = MSAlignOptions(app.Tab(CurrentTab),app.MassData{CurrentTab});
            %add Callback functions
            app.MSAlignPanel(CurrentTab).QuantilField.ValueChangedFcn = @(src,event) {massAlignmentQuantilChange(app,src,event,CurrentTab)};
            app.MSAlignPanel(CurrentTab).EstimationDrop.ValueChangedFcn = @(src,event) {massAlignmentEstimMethodChange(app,src,event,CurrentTab)};
            app.MSAlignPanel(CurrentTab).CorrectionDrop.ValueChangedFcn = @(src,event) {massAlignmentCorrectionMethodChange(app,src,event,CurrentTab)};
        end

        function addPeakAlignPanel(app,CurrentTab)
            %construct UI elements
            app.PeakAlignPanel(CurrentTab) = PeakAlignOptions(app.Tab(CurrentTab),app.MassData{CurrentTab});
            %add Callback functions
            app.PeakAlignPanel(CurrentTab).MaxShiftFieldPos.ValueChangedFcn = @(src,event) {peakAlignmentMaxShiftPositiveChange(app,src,event,CurrentTab)};
            app.PeakAlignPanel(CurrentTab).MaxShiftFieldNeg.ValueChangedFcn = @(src,event) {peakAlignmentMaxShiftNegativeChange(app,src,event,CurrentTab)};
            app.PeakAlignPanel(CurrentTab).PulseWidthField.ValueChangedFcn = @(src,event) {peakAlignmentPulseWidthChange(app,src,event,CurrentTab)};
            app.PeakAlignPanel(CurrentTab).WindowSizeField.ValueChangedFcn = @(src,event) {peakAlignmentWindowSizeRatioChange(app,src,event,CurrentTab)};
            app.PeakAlignPanel(CurrentTab).SearchSpaceDrop.ValueChangedFcn = @(src,event) {peakAlignmentSearchSpaceChange(app,src,event,CurrentTab)};
            app.PeakAlignPanel(CurrentTab).IterationField.ValueChangedFcn = @(src,event) {peakAlignmentIterationChange(app,src,event,CurrentTab)};
            app.PeakAlignPanel(CurrentTab).GridStepField.ValueChangedFcn = @(src,event) {peakAlignmentGridStepsChange(app,src,event,CurrentTab)};
        end

        function addBaselinePanel(app,CurrentTab)
            %construct UI elements
            app.BaselinePanel(CurrentTab) = BaseCorrOptions(app.Tab(CurrentTab),app.MassData{CurrentTab});
            %add Callback functions
            app.BaselinePanel(CurrentTab).WindowSizeField.ValueChangedFcn = @(src,event) {baselineWindowSizeChange(app,src,event,CurrentTab)};
            app.BaselinePanel(CurrentTab).StepSizeField.ValueChangedFcn = @(src,event) {baselineStepSizeChange(app,src,event,CurrentTab)};
            app.BaselinePanel(CurrentTab).RegressionDrop.ValueChangedFcn = @(src,event) {baselineRegressionMethodChange(app,src,event,CurrentTab)};
            app.BaselinePanel(CurrentTab).EstimationDrop.ValueChangedFcn = @(src,event) {baselineEstimationMethodChange(app,src,event,CurrentTab)};
            app.BaselinePanel(CurrentTab).SmoothDrop.ValueChangedFcn = @(src,event) {baselineSmoothMethodChange(app,src,event,CurrentTab)};
            app.BaselinePanel(CurrentTab).QuantilValField.ValueChangedFcn = @(src,event) {baselineQuantilChange(app,src,event,CurrentTab)};
        end

        function addGolayPanel(app,CurrentTab)
            %construct UI elements
            app.GolayPanel(CurrentTab) = GolayOptions(app.Tab(CurrentTab),app.MassData{CurrentTab});
            %add Callback functions
            app.GolayPanel(CurrentTab).Degree.ValueChangedFcn = @(src,event) {smoothingDegreeChanged(app,src,event,CurrentTab)};
            app.GolayPanel(CurrentTab).FrameSize.ValueChangedFcn = @(src,event) {smoothingFrameSizeChanged(app,src,event,CurrentTab)};
        end

        function addScalingPanel(app,CurrentTab)
            app.ScalingPanel(CurrentTab) = SampScaleOptions(app.Tab(CurrentTab));
            %add Callback functions
            app.ScalingPanel(CurrentTab).SelectButton.ButtonPushedFcn = @(src,event) {constructScalingFigure(app,src,event,CurrentTab)};
        end

        function addISPanel(app,CurrentTab)
            %construct UI elements
            app.ISPanel(CurrentTab) = ISOptions(app.Tab(CurrentTab),app.MassData{CurrentTab});
            %add Callback functions
            app.ISPanel(CurrentTab).DefineButton.ButtonPushedFcn = @(src,event) {constructISFigure(app,src,event,CurrentTab)};
            app.ISPanel(CurrentTab).NumberSelect.ValueChangedFcn = @(src,event) {isLampAction(app,src,event,CurrentTab)};
            app.ISPanel(CurrentTab).MassCalCheck.ValueChangedFcn = @(src,event) {useISMassCorrectionChange(app,src,event,CurrentTab)};
            app.ISPanel(CurrentTab).ApplyDrop.ValueChangedFcn = @(src,event) {applyIStoChange(app,src,event,CurrentTab)};
            app.ISPanel(CurrentTab).OrderDrop.ValueChangedFcn = @(src,event) {internalStandardOrderChange(app,src,event,CurrentTab)};
        end

        function addAddNLossPanel(app,CurrentTab)
            app.AddNLossPanel(CurrentTab) = AddNLossOptions(app.Tab(CurrentTab));
            %add Callback functions
            app.AddNLossPanel(CurrentTab).SelectButton.ButtonPushedFcn = @(src,event) {constructAddNLossFigure(app,src,event,CurrentTab)};
        end

        function addEvaluationPanel(app)
            % construct UI elements
            app.EvaluationObject(1) = EvaluationOptions(app);
            %add Callback functions
            
        end

        %% App Functions
        function UpdateCallbacks(app,CurrentTab)
            %Tab Elements
            app.SelectFilesButton(CurrentTab).ButtonPushedFcn = @(src,event) {selectFiles(app,src,event,CurrentTab)};
            app.PreviewButton(CurrentTab).ButtonPushedFcn = @(src,event) {testParameters(app,src,event,CurrentTab)};
            app.EICButton(CurrentTab).ButtonPushedFcn = @(src,event) {plotEIC(app,src,event,CurrentTab)};
            app.GroupNameField(CurrentTab).ValueChangedFcn = @(src,event) {groupNameChange(app,src,event,CurrentTab)};

            %check if current tab has generated UI elements that need to update
            if ~isempty(app.MainOptionsPanel(CurrentTab).Container)

                %MainCallbacks
                app.MainOptionsPanel(CurrentTab).BlankTickBox.ValueChangedFcn = @(src,event) {useBlankChange(app,src,event,CurrentTab)};
                app.MainOptionsPanel(CurrentTab).PeakAlignmentTickBox.ValueChangedFcn = @(src,event) {usePeakAlignmentChange(app,src,event,CurrentTab)};
                app.MainOptionsPanel(CurrentTab).MSAlignmentTickBox.ValueChangedFcn = @(src,event) {useMassAlignmentChange(app,src,event,CurrentTab)};
                app.MainOptionsPanel(CurrentTab).BaseCorrTickBox.ValueChangedFcn = @(src,event) {useBaselineCorrectionChange(app,src,event,CurrentTab)};
                app.MainOptionsPanel(CurrentTab).GolayTickBox.ValueChangedFcn = @(src,event) {useSmoothingChange(app,src,event,CurrentTab)};
                app.MainOptionsPanel(CurrentTab).ISTDTickBox.ValueChangedFcn = @(src,event) {useInternalStandardChange(app,src,event,CurrentTab)};
                app.MainOptionsPanel(CurrentTab).IsotopeFilterTickBox.ValueChangedFcn = @(src,event) {useIsotopeFilterChange(app,src,event,CurrentTab)};
                app.MainOptionsPanel(CurrentTab).ScalingTickBox.ValueChangedFcn = @(src,event) {useScalingChange(app,src,event,CurrentTab)};
                app.MainOptionsPanel(CurrentTab).AddNLossTickBox.ValueChangedFcn = @(src,event) {useAddNLossChange(app,src,event,CurrentTab)};
                app.MainOptionsPanel(CurrentTab).ContaminantTickBox.ValueChangedFcn = @(src,event) {useContaminantFilterChange(app,src,event,CurrentTab)};
                %ROI Callbacks
                app.ROIOptionsPanel(CurrentTab).ThreshField.ValueChangedFcn = @(src,event) {ThreshChange(app,src,event,CurrentTab)};
                app.ROIOptionsPanel(CurrentTab).mzerrorField.ValueChangedFcn = @(src,event) {mzerrorChange(app,src,event,CurrentTab)};
                app.ROIOptionsPanel(CurrentTab).mzerrorUnitDrop.ValueChangedFcn = @(src,event) {mzerrorUnitChange(app,src,event,CurrentTab)};
                app.ROIOptionsPanel(CurrentTab).minROIField.ValueChangedFcn = @(src,event) {minROIChange(app,src,event,CurrentTab)};
                app.ROIOptionsPanel(CurrentTab).StartTimeField.ValueChangedFcn = @(src,event) {STimeChange(app,src,event,CurrentTab)};
                app.ROIOptionsPanel(CurrentTab).EndTimeField.ValueChangedFcn = @(src,event) {ETimeChange(app,src,event,CurrentTab)};
                %Integration Callbacks
                app.IntegrationOptionsPanel(CurrentTab).EntropyCheck.ValueChangedFcn = @(src,event) {useEntropyChange(app,src,event,CurrentTab)};
                app.IntegrationOptionsPanel(CurrentTab).EntropyDrop.ValueChangedFcn = @(src,event) {entropyStrengthChange(app,src,event,CurrentTab)};
                app.IntegrationOptionsPanel(CurrentTab).OccurrenceField.ValueChangedFcn = @(src,event) {minOccurrenceChange(app,src,event,CurrentTab)};
                app.IntegrationOptionsPanel(CurrentTab).minSNField.ValueChangedFcn = @(src,event) {minSNChange(app,src,event,CurrentTab)};
                app.IntegrationOptionsPanel(CurrentTab).maxWidthField.ValueChangedFcn = @(src,event) {maxWidthChange(app,src,event,CurrentTab)};
                app.IntegrationOptionsPanel(CurrentTab).minWidthField.ValueChangedFcn = @(src,event) {minWidthChange(app,src,event,CurrentTab)};
                app.IntegrationOptionsPanel(CurrentTab).EvalParamDrop.ValueChangedFcn = @(src,event) {evalParameterChange(app,src,event,CurrentTab)};
                app.IntegrationOptionsPanel(CurrentTab).mzTolField.ValueChangedFcn = @(src,event) {betweenFileMassToleranceChange(app,src,event,CurrentTab)};
                app.IntegrationOptionsPanel(CurrentTab).mzTolUnitDrop.ValueChangedFcn = @(src,event) {betweenFileMassUnitChange(app,src,event,CurrentTab)};
                app.IntegrationOptionsPanel(CurrentTab).RTTolField.ValueChangedFcn = @(src,event) {peakTimeToleranceChange(app,src,event,CurrentTab)};
                %MSAlign Callbacks
                app.MSAlignPanel(CurrentTab).QuantilField.ValueChangedFcn = @(src,event) {massAlignmentQuantilChange(app,src,event,CurrentTab)};
                app.MSAlignPanel(CurrentTab).EstimationDrop.ValueChangedFcn = @(src,event) {massAlignmentEstimMethodChange(app,src,event,CurrentTab)};
                app.MSAlignPanel(CurrentTab).CorrectionDrop.ValueChangedFcn = @(src,event) {massAlignmentCorrectionMethodChange(app,src,event,CurrentTab)};
                %PeakAlign Callbacks
                app.PeakAlignPanel(CurrentTab).MaxShiftFieldPos.ValueChangedFcn = @(src,event) {peakAlignmentMaxShiftPositiveChange(app,src,event,CurrentTab)};
                app.PeakAlignPanel(CurrentTab).MaxShiftFieldNeg.ValueChangedFcn = @(src,event) {peakAlignmentMaxShiftNegativeChange(app,src,event,CurrentTab)};
                app.PeakAlignPanel(CurrentTab).PulseWidthField.ValueChangedFcn = @(src,event) {peakAlignmentPulseWidthChange(app,src,event,CurrentTab)};
                app.PeakAlignPanel(CurrentTab).WindowSizeField.ValueChangedFcn = @(src,event) {peakAlignmentWindowSizeRatioChange(app,src,event,CurrentTab)};
                app.PeakAlignPanel(CurrentTab).SearchSpaceDrop.ValueChangedFcn = @(src,event) {peakAlignmentSearchSpaceChange(app,src,event,CurrentTab)};
                app.PeakAlignPanel(CurrentTab).IterationField.ValueChangedFcn = @(src,event) {peakAlignmentIterationChange(app,src,event,CurrentTab)};
                app.PeakAlignPanel(CurrentTab).GridStepField.ValueChangedFcn = @(src,event) {peakAlignmentGridStepsChange(app,src,event,CurrentTab)};
                %Baseline Callbacks
                app.BaselinePanel(CurrentTab).WindowSizeField.ValueChangedFcn = @(src,event) {baselineWindowSizeChange(app,src,event,CurrentTab)};
                app.BaselinePanel(CurrentTab).StepSizeField.ValueChangedFcn = @(src,event) {baselineStepSizeChange(app,src,event,CurrentTab)};
                app.BaselinePanel(CurrentTab).RegressionDrop.ValueChangedFcn = @(src,event) {baselineRegressionMethodChange(app,src,event,CurrentTab)};
                app.BaselinePanel(CurrentTab).EstimationDrop.ValueChangedFcn = @(src,event) {baselineEstimationMethodChange(app,src,event,CurrentTab)};
                app.BaselinePanel(CurrentTab).SmoothDrop.ValueChangedFcn = @(src,event) {baselineSmoothMethodChange(app,src,event,CurrentTab)};
                app.BaselinePanel(CurrentTab).QuantilValField.ValueChangedFcn = @(src,event) {baselineQuantilChange(app,src,event,CurrentTab)};
                %Golay Callbacks
                app.GolayPanel(CurrentTab).Degree.ValueChangedFcn = @(src,event) {smoothingDegreeChanged(app,src,event,CurrentTab)};
                app.GolayPanel(CurrentTab).FrameSize.ValueChangedFcn = @(src,event) {smoothingFrameSizeChanged(app,src,event,CurrentTab)};
                %Scaling Callback
                app.ScalingPanel(CurrentTab).SelectButton.ButtonPushedFcn = @(src,event) {constructScalingFigure(app,src,event,CurrentTab)};
                %IS Callbacks
                app.ISPanel(CurrentTab).DefineButton.ButtonPushedFcn = @(src,event) {constructISFigure(app,src,event,CurrentTab)};
                app.ISPanel(CurrentTab).NumberSelect.ValueChangedFcn = @(src,event) {isLampAction(app,src,event,CurrentTab)};
                app.ISPanel(CurrentTab).MassCalCheck.ValueChangedFcn = @(src,event) {useISMassCorrectionChange(app,src,event,CurrentTab)};
                app.ISPanel(CurrentTab).ApplyDrop.ValueChangedFcn = @(src,event) {applyIStoChange(app,src,event,CurrentTab)};
                app.ISPanel(CurrentTab).OrderDrop.ValueChangedFcn = @(src,event) {internalStandardOrderChange(app,src,event,CurrentTab)};
                %AddNLoss Callback
                app.AddNLossPanel(CurrentTab).SelectButton.ButtonPushedFcn = @(src,event) {constructAddNLossFigure(app,src,event,CurrentTab)};
            end
        end

        function CheckReady(app)
            % determine if app state permits BatchProcessing
            data = app.MassData;
            if size(data,2) > 1
                app.DefineAugmentationandReferencingButton.Enable = "on";
                app.CopySettingstoallGroupsButton.Enable = "on";
                app.RemoveSelectedGroupButton.Enable = "on";
            else
                app.DefineAugmentationandReferencingButton.Enable = "off";
                app.CopySettingstoallGroupsButton.Enable = "off";
                app.RemoveSelectedGroupButton.Enable = "off";
            end
            checkSum = zeros(6,1);
            counter = 1;

            %check for empty DataClasses
            checkSum(6) = sum(cellfun(@isempty,app.MassData));

            %check for missing Files
            while sum(checkSum) <= 0 && counter <= width(app.MassData)
                % check for missing data files
                checkSum(1) = isempty(app.MassData{counter}.fileName); % true: contains files; false: no files
                %Check for missing Blank Files
                checkSum(2) = app.MassData{counter}.useBlankSubtraction;
                checkSum(2) = checkSum(2) - ~isempty(app.MassData{counter}.blankFile); % true: contains files; false: no files
                %Check for missing IS Data
                checkSum(3) = app.MassData{counter}.useInternalStandard;
                checkSum(3) = checkSum(3) - ~isempty(app.MassData{counter}.internalStandardData); % true: contains files; false: no files
                %Check for unspecified AddNLos options
                checkSum(4) = app.MassData{counter}.useAdductFilter;
                if checkSum(4)
                    checkSum(4) = checkSum(4) - app.AddNLossPanel(counter).AddNLossLamp.Color(1) == 0.39; % check if Lamp is green
                end

                %Check for unspecified Scaling factors
                checkSum(5) = app.MassData{counter}.useScaling;
                if checkSum(5)
                    checkSum(5) = checkSum(5) - app.ScalingPanel(counter).ScalingLamp.Color(1) == 0.39; % check if Lamp is green
                end
                counter = counter+1;
                checkSum = max(checkSum,0);
            end
            
            if sum(checkSum) == 0
                app.ProcessFilesButton.Enable = "on";
            else
                app.ProcessFilesButton.Enable = "off";
            end
        end

        function CheckDataState(app)
            % Check if preview Data is available
            isEmptyData = false;
            counter = 1;
            while isEmptyData == false && counter<=size(app.MassData,2)
                isEmptyData = isempty(app.MassData{counter}.RawDataFileObj.previewTICs);
                counter = counter + 1;
            end
            if isEmptyData == false
                app.EvaluationObject.TICButton.Enable = "on";
                if isempty(app.FeatureData) == false
                    app.EvaluationObject.ScatterButton.Enable = "on";
                end
            else
                app.EvaluationObject.TICButton.Enable = "off";
                app.EvaluationObject.ScatterButton.Enable = "off";
            end

            %check if ROI was generated
            isEmptyData = false;
            counter = 1;
            while isEmptyData == false && counter<=size(app.MassData,2)
                isEmptyData = isempty(app.MassData{counter}.ROIDataFile);
                counter = counter + 1;
            end
            if isEmptyData == false
                app.EvaluationObject.ROIButton.Enable = "on";
                app.EvaluationObject.IntegrationButton.Enable = "on";
                app.EvaluationObject.EntropyButton.Enable = "on";
                app.EvaluationObject.SNButton.Enable = "on";
                app.EvaluationObject.RejectedButton.Enable = "on";
            else
                app.EvaluationObject.ROIButton.Enable = "off";
                app.EvaluationObject.IntegrationButton.Enable = "off";
                app.EvaluationObject.EntropyButton.Enable = "off";
                app.EvaluationObject.SNButton.Enable = "off";
                app.EvaluationObject.RejectedButton.Enable = "off";
            end

            %check if IS was found
            isEmptyData = false(1,width(app.MassData));
            for iData = 1:width(app.MassData)
                isEmptyData(iData) = isempty(app.MassData{iData}.internalStandardMassDelta);
            end
            if ~any(isEmptyData)
                app.EvaluationObject.IsMassDeltaButton.Enable = "on";
            else
                app.EvaluationObject.IsMassDeltaButton.Enable = "off";
            end

            %check if FeatData was generated
            if isempty(app.FeatureData) == false
                app.EvaluationObject.DistributionButton.Enable = "on";
                app.EvaluationObject.ProbabilityButton.Enable = "on";
                app.EvaluationObject.HeatmapButton.Enable = "on";
                app.EvaluationObject.ExportButton.Enable = "on";
                app.EvaluationObject.DatabaseButton.Enable = "on";
                app.EvaluationObject.IntegrationButton.Enable = "on";
                app.ApplyChangesButton.Enable = "on";

                %check for more than one group
                if numel(app.FeatureData.GroupName) > 1
                    app.EvaluationObject.pDistributionButton.Enable = "on";
                    app.EvaluationObject.CloudButton.Enable = "on";
                    app.EvaluationObject.VolcanoButton.Enable = "on";
                    app.EvaluationObject.PCAButton.Enable = "on";
                else
                    app.EvaluationObject.pDistributionButton.Enable = "off";
                    app.EvaluationObject.CloudButton.Enable = "off";
                    app.EvaluationObject.VolcanoButton.Enable = "off";
                    app.EvaluationObject.PCAButton.Enable = "off";
                end
            else
                app.EvaluationObject.ScatterButton.Enable = "off";
                app.EvaluationObject.DistributionButton.Enable = "off";
                app.EvaluationObject.ProbabilityButton.Enable = "off";
                app.EvaluationObject.HeatmapButton.Enable = "off";
                app.EvaluationObject.ExportButton.Enable = "off";
                app.EvaluationObject.DatabaseButton.Enable = "off";
                app.EvaluationObject.pDistributionButton.Enable = "off";
                app.EvaluationObject.CloudButton.Enable = "off";
                app.EvaluationObject.VolcanoButton.Enable = "off";
                app.EvaluationObject.PCAButton.Enable = "off";
                app.EvaluationObject.IntegrationButton.Enable = "off";
                app.ApplyChangesButton.Enable = "off";
            end

        end
        function previewFile(app,CurrentTab)
            dialog = uiprogressdlg(app.MainWindow,'Title','Plotting Graphs','Message','Please Wait','Indeterminate','on');
            %Gather Data
            basePeakChromatogramArray = app.MassData{CurrentTab}.RawDataFileObj.previewBPCs;
            totalIonChromatogramArray = app.MassData{CurrentTab}.RawDataFileObj.previewTICs;
            timeArray = app.MassData{CurrentTab}.RawDataFileObj.previewTimes;
            % delete old Plot
            if app.currentPlot(1,CurrentTab) == true
                delete(app.PreviewAxes(CurrentTab).Children)
                delete(app.PreviewAxes(CurrentTab))
                app.currentPlot(1,CurrentTab) = false;
            elseif app.currentPlot(2,CurrentTab) == true
                delete(app.PreviewTiled(CurrentTab).Children)
                delete(app.PreviewTiled(CurrentTab))
                app.currentPlot(2,CurrentTab) = false;
            end
            % build new Plot
            app.PreviewAxes(CurrentTab) = uiaxes(app.Tab(CurrentTab),OuterPosition=[470 200 680 420],PositionConstraint="outerposition",Visible="off");
            colororder(app.PreviewAxes(CurrentTab),"gem12")
            hold(app.PreviewAxes(CurrentTab),"on")
            for iFiles = 1:size(timeArray,1)
                plot(app.PreviewAxes(CurrentTab),timeArray{iFiles},totalIonChromatogramArray{iFiles})
                app.PreviewAxes(CurrentTab).Children(1).DataTipTemplate.DataTipRows(1).Label = "RT:";
                app.PreviewAxes(CurrentTab).Children(1).DataTipTemplate.DataTipRows(2).Label = "Total ion intensity:";
            end
            TICLegend = "TIC: " + app.MassData{CurrentTab}.fileName;
            BPCLegend = [];
            if app.MassData{CurrentTab}.separationType ~= "GC"
                for iFiles=1:size(timeArray,1)
                    plot(app.PreviewAxes(CurrentTab),timeArray{iFiles},basePeakChromatogramArray{iFiles})
                    app.PreviewAxes(CurrentTab).Children(1).DataTipTemplate.DataTipRows(1).Label = "RT:";
                    app.PreviewAxes(CurrentTab).Children(1).DataTipTemplate.DataTipRows(2).Label = "Basepeak intensity:";
                end
                BPCLegend = "BPC: " + app.MassData{CurrentTab}.fileName;
            end
            hold(app.PreviewAxes(CurrentTab),"off")
            app.PreviewAxes(CurrentTab).Title.String = "Preview";
            legend(app.PreviewAxes(CurrentTab),[TICLegend;BPCLegend],Interpreter="none")
            ylabel(app.PreviewAxes(CurrentTab),"Intensity [arb]")
            xlabel(app.PreviewAxes(CurrentTab),"Time [sec]")
            app.PreviewAxes(CurrentTab).Visible = "on";
            drawnow
            close(dialog)
            % change Plot State
            app.currentPlot(1,CurrentTab) = true;
        end

        function activateParameterPanels(app)
            delete(app.TabContainer.Children(1:end))
            delete(app.Tab(1:end))
            app.groupCounter = 0;
            for iGroups = 1:width(app.MassData)
                %construct new Tab
                app.constructNewTab;
                % update GroupName
                app.Tab(iGroups).Title = app.MassData{iGroups}.GroupName;
                app.GroupNameField(iGroups).Value = app.MassData{iGroups}.GroupName;
                % add new UI elements
                app.addMainOptions(iGroups)
                app.addROIOptions(iGroups)
                app.addIntegrationOptions(iGroups)
                app.addPeakAlignPanel(app,iGroups)
                app.addMSAlignPanel(iGroups)
                app.addBaselinePanel(iGroups)
                app.addGolayPanel(iGroups)
                app.addScalingPanel(iGroups)
                app.addISPanel(iGroups)
                app.addAddNLossPanel(iGroups)
                
                %enable panels
                app.MainOptionsPanel(iGroups).Container.Enable = true;
                app.ROIOptionsPanel(iGroups).Container.Enable = true;
                app.IntegrationOptionsPanel(iGroups).Container.Enable = true;
                app.PeakAlignPanel(iGroups).Container.Enable = app.MainOptionsPanel(iGroups).PeakAlignmentTickBox.Value;
                app.MSAlignPanel(iGroups).Container.Enable = app.MainOptionsPanel(iGroups).MSAlignmentTickBox.Value;
                app.BaselinePanel(iGroups).Container.Enable = app.MainOptionsPanel(iGroups).BaseCorrTickBox.Value;
                app.GolayPanel(iGroups).Container.Enable = app.MainOptionsPanel(iGroups).GolayTickBox.Value;
                app.ScalingPanel(iGroups).Container.Enable = app.MainOptionsPanel(iGroups).ScalingTickBox.Value;
                app.ISPanel(iGroups).Container.Enable = app.MainOptionsPanel(iGroups).ISTDTickBox.Value;
                app.AddNLossPanel(iGroups).Container.Enable = app.MainOptionsPanel(iGroups).AddNLossTickBox.Value;
            end

            uitab(app.TabContainer,'Title','+');
            CheckReady(app)
        end

        function mergedStruct = mergeGroups(~,StructArray)
            mergedStruct = StructArray(1);  % Initialize merged struct with first struct from array
            DataSize = arrayfun(@(x) size(x.IntensityStorage),StructArray,'UniformOutput',false);
            DataSize = vertcat(DataSize{:});
            minData = min(DataSize(:,2));
            fieldNames = fieldnames(mergedStruct);
            % Iterate over field names and combine values from struct2 with merged struct
            for n = 2:size(StructArray,2)
                struct2 = StructArray(n);
                for i = 1:numel(fieldNames)
                    fieldName = fieldNames{i};
                    if strcmp(fieldName,'FeatIdentifiers') || strcmp(fieldName,'GroupName')  || strcmp(fieldName,'DataSize') || strcmp(fieldName,'FoundInGroup')
                        mergedStruct.(fieldName) = [mergedStruct.(fieldName); struct2.(fieldName)];

                    elseif strcmp(fieldName,'SampleNames')
                        mergedStruct.(fieldName) = [mergedStruct.(fieldName)(1:minData,:), struct2.(fieldName)(1:minData,:)];

                    else
                        mergedStruct.(fieldName) = [mergedStruct.(fieldName)(:,1:minData); struct2.(fieldName)(:,1:minData)];
                    end
                end
            end
            mergedStruct.DataSize = DataSize(:,1);
        end

        function MergedStructArray = BuildFullArray(app,StructArray)
            %check groups without features, remove them and throw warning
            %reshape DataSize to row vector
            check = vertcat(StructArray.DataSize) == 0;
            if any(check)
                emptyGroups = [StructArray(check).GroupName];
                emptyGroups = emptyGroups(1,:);
                StructArray(check)=[];
                %remove empty groups from augmentation
                app.augmentationAssignment(check,:)=[];
                %display warning
                header="Groups without Features identified";
                message = ["Process again with less strict settings","Affected Group(s):",emptyGroups];
                uialert(app.MainWindow,message,header,'Icon','warning');

            end
            %gather Tolerance
            mzTol = app.mergeMassTolerance;
            mzTolUnit = app.mergeMassUnit;
            RTtol = app.mergeTimeTolerance;
            % Initialize Merged Struct
            MergedStructArray = struct('GroupName',{},'FeatIdentifiers',{},'FoundInGroup',{},'SampleNames',{},'XIC',{},'IntensityStorage',{},'RetentionTimeStorage',{},'EntropyStorage',{},'Signal2NoiseStorage',{},'DataSize',{});
            NumberOfFilesArray = zeros(1,size(StructArray,2));
            NumberOfFilesArray(1) = size(StructArray(1).IntensityStorage,2);
            NameArray = strings(2,numel(NumberOfFilesArray));
            NameArray(:,1) = StructArray(1).GroupName;
            SampleNameArray = cell(size(NumberOfFilesArray));
            SampleNameArray{1} = StructArray(1).SampleNames;
            NumFeatureCell = cell(size(NumberOfFilesArray));
            NumFeatureCell{1} = StructArray(1).DataSize;
            for n=2:size(StructArray,2)
                % Define the matrices Struct A
                Identifier_A = StructArray(1).FeatIdentifiers;
                XIC_A = StructArray(1).XIC;
                Intensity_A = StructArray(1).IntensityStorage;
                Retention_A = StructArray(1).RetentionTimeStorage;
                Entropy_A = StructArray(1).EntropyStorage;
                SN_A = StructArray(1).Signal2NoiseStorage;
                InGroup_A = StructArray(1).FoundInGroup;

                % Define the matrices Struct B
                Identifier_B = StructArray(n).FeatIdentifiers;
                XIC_B = StructArray(n).XIC;
                Intensity_B = StructArray(n).IntensityStorage;
                Retention_B = StructArray(n).RetentionTimeStorage;
                Entropy_B = StructArray(n).EntropyStorage;
                SN_B = StructArray(n).Signal2NoiseStorage;
                NumberOfFilesArray(n) = size(Intensity_B,2);
                NameArray(:,n) = StructArray(n).GroupName;
                SampleNameArray{n} = StructArray(n).SampleNames;
                NumFeatureCell{n} = StructArray(n).DataSize;
                InGroup_B = StructArray(n).FoundInGroup;

                % Initialize an empty matrices to store the merged rows
                merged_Identifier = [];
                merged_XIC = [];
                merged_Intensities = [];
                merged_Retention = [];
                merged_SN = [];
                merged_Entropy = [];
                merged_FoundInGroup = [];
                % Iterate over the rows of Mat A
                for i = 1:size(Identifier_A, 1)
                    match_found = false;

                    % Iterate over the rows of Mat B
                    for j = 1:size(Identifier_B, 1)
                        % Check if the identifiers are within the tolerances
                        switch mzTolUnit
                            case "Da"
                                Check = abs(Identifier_A(i, 1) - Identifier_B(j, 1)) <= mzTol;
                            case "ppm"
                                Check = abs((Identifier_A(i, 1)-Identifier_B(j, 1))/Identifier_B(j, 1)*10^6)<=mzTol;
                        end
                        if Check == true && abs(Identifier_A(i, 2) - Identifier_B(j, 2)) <= RTtol
                            merged_Identifier = [merged_Identifier; Identifier_B(j, :)];
                            merged_FoundInGroup = [merged_FoundInGroup; [InGroup_A(i, :), InGroup_B(j, :)]];
                            merged_XIC = [merged_XIC; [XIC_A(i, :), XIC_B(j, :)]];
                            merged_Intensities = [merged_Intensities; [Intensity_A(i, :), Intensity_B(j, :)]];
                            merged_Retention = [merged_Retention; [Retention_A(i, :), Retention_B(j, :)]];
                            merged_Entropy = [merged_Entropy; [Entropy_A(i, :), Entropy_B(j, :)]];
                            merged_SN = [merged_SN; [SN_A(i, :), SN_B(j, :)]];
                            match_found = true;
                            break;
                        end
                    end

                    % If no match is found, append the row from Mat A and IntA with zeros
                    if ~match_found
                        merged_Identifier = [merged_Identifier; Identifier_A(i, :)];
                        merged_FoundInGroup = [merged_FoundInGroup; [InGroup_A(i, :), strings(1, size(InGroup_B, 2))]];
                        merged_XIC = [merged_XIC; [XIC_A(i, :), cell(1, size(XIC_B, 2))]];
                        merged_Intensities = [merged_Intensities; [Intensity_A(i, :), zeros(1, size(Intensity_B, 2))]];
                        merged_Retention = [merged_Retention; [Retention_A(i, :), zeros(1, size(Retention_B, 2))]];
                        merged_Entropy = [merged_Entropy; [Entropy_A(i, :), zeros(1, size(Entropy_B, 2))]];
                        merged_SN = [merged_SN; [SN_A(i, :), zeros(1, size(SN_B, 2))]];
                    end
                end

                % Append the remaining rows from Mat B and IntB (if any)
                for j = 1:size(Identifier_B, 1)
                    if ~ismember(Identifier_B(j, :), merged_Identifier, 'rows')
                        merged_Identifier = [merged_Identifier; Identifier_B(j, :)];
                        merged_FoundInGroup = [merged_FoundInGroup; [strings(1, size(InGroup_A, 2)), InGroup_B(j, :)]];
                        merged_XIC = [merged_XIC; [cell(1, size(XIC_A, 2)), XIC_B(j, :)]];
                        merged_Intensities = [merged_Intensities; [zeros(1, size(Intensity_A, 2)), Intensity_B(j, :)]];
                        merged_Retention = [merged_Retention; [zeros(1, size(Retention_A, 2)), Retention_B(j, :)]];
                        merged_Entropy = [merged_Entropy; [zeros(1, size(Entropy_A, 2)), Entropy_B(j, :)]];
                        merged_SN = [merged_SN; [zeros(1, size(SN_A, 2)), SN_B(j, :)]];
                    end
                end
                StructArray(1).FeatIdentifiers = merged_Identifier;
                StructArray(1).XIC = merged_XIC;
                StructArray(1).IntensityStorage = merged_Intensities;
                StructArray(1).RetentionTimeStorage = merged_Retention;
                StructArray(1).EntropyStorage = merged_Entropy;
                StructArray(1).Signal2NoiseStorage = merged_SN;
                StructArray(1).OriginalGroup = merged_FoundInGroup;
                StructArray(1).FoundInGroup = merged_FoundInGroup;
            end
            MergedStructArray(1).FeatIdentifiers = StructArray(1).FeatIdentifiers;
            MergedStructArray(1).XIC = StructArray(1).XIC;
            MergedStructArray(1).IntensityStorage = StructArray(1).IntensityStorage;
            MergedStructArray(1).RetentionTimeStorage = StructArray(1).RetentionTimeStorage;
            MergedStructArray(1).EntropyStorage = StructArray(1).EntropyStorage;
            MergedStructArray(1).Signal2NoiseStorage = StructArray(1).Signal2NoiseStorage;
            MergedStructArray(1).SampleNumbers = NumberOfFilesArray;
            MergedStructArray(1).GroupName = NameArray;
            MergedStructArray(1).SampleNames = SampleNameArray;
            MergedStructArray(1).OriginalGroup = StructArray(1).OriginalGroup;
            MergedStructArray(1).FoundInGroup = join(StructArray(1).FoundInGroup,",");
        end

        function app = ExportResults(app,~,~)
            %% select save location
            currentFolder = pwd;
            currentFolder = convertCharsToStrings(currentFolder);
            initFile = currentFolder+"\"+"ResultTable";
            [file,path] = uiputfile('*.xlsx','Select Save Location and Filename',initFile);
            figure(app.MainWindow);
            if file == 0 % when user cancels input set safe location to current folder and filename to workspace Variable name
                return
            else
                path = convertCharsToStrings(path);
                file = convertCharsToStrings(file);
                outputname = path+file;
            end
            %%
            NameString = app.FeatureData.NameStringArray;
            FoundInGroup = app.FeatureData.InGroup;
            ID = (1:1:length(NameString))';
            % replace inserted average Feature Intensities with 0
            Intensities = app.FeatureData.IntensityArray;
            idx = app.FeatureData.FullUnscaledIntensityArray == 0;
            Intensities(idx) = 0;
            AverageIntensities = app.FeatureData.AverageIntensities;
            STD = app.FeatureData.StandardDeviations;
            fold = horzcat(app.FeatureData.FullFoldChanges{:});
            pVal = horzcat(app.FeatureData.SignificantFeature{:});
            UniqueFeature = app.FeatureData.UniqueFeature;
            FoundInRef = horzcat(app.FeatureData.FoundInReferenceGroup{:});
            OccurenceAll = sum(app.FeatureData.OccurenceCount,2);
            OccurenceInGroup = app.FeatureData.OccurenceCount;
            mz = app.FeatureData.IdentifierArray(:,1);
            RT = app.FeatureData.IdentifierArray(:,2);
            colnames = ["ID",...
                "Feature",...
                "Found in Group",...
                "Intensities",...
                "Avg. Intensity",...
                "Standard Deviation",...
                "Fold Changes",...
                "p-Values",...
                "Unique Feature in Group",...
                "Feature not found in Reference",...
                "Occurences in Group",...
                "Occurences over all",...
                "Mass M [Da]",...
                "Retention Time [s]"];
            Out = table(ID,NameString,FoundInGroup,Intensities,AverageIntensities,STD,fold,pVal,UniqueFeature,FoundInRef,OccurenceInGroup,OccurenceAll,mz,RT, ...
                'VariableNames',colnames);

            writetable(Out,outputname)
            uialert(app.MainWindow,'Export Complete','Success','Icon','success');
        end

        function app = OpenDatabaseWindow(app,~,~)
            % if isempty(app.DataBase)
            app.addDataBaseWindow;
            % else
            %     app.DataBase.Window.Visible="on";
            % end
        end

        function generateReport(app)
            NameString = app.FeatureData.NameStringArray;
            ID = (1:1:length(NameString))';
            % replace inserted average Feature Intensities with 0
            Intensities = app.FeatureData.IntensityArray;
            idx = app.FeatureData.FullUnscaledIntensityArray == 0;
            Intensities(idx) = 0;
            AverageIntensities = app.FeatureData.AverageIntensities;
            STD = app.FeatureData.StandardDeviations;
            fold = horzcat(app.FeatureData.FullFoldChanges{:});
            pVal = horzcat(app.FeatureData.SignificantFeature{:});
            UniqueFeature = app.FeatureData.UniqueFeature;
            FoundInRef = horzcat(app.FeatureData.FoundInReferenceGroup{:});
            OccurenceAll = sum(app.FeatureData.OccurenceCount,2);
            OccurenceInGroup = app.FeatureData.OccurenceCount;
            IdentLevel = app.FeatureData.IdentificationLevel;
            MSSearch = cell(size(ID));

            if ~isempty(app.FeatureData.dataBaseResultsMS1)
                id = cellfun(@isempty,app.FeatureData.dataBaseResultsMS1);
                MSSearch(~id) = app.FeatureData.dataBaseResultsMS1(~id);
            end
            if ~isempty(app.FeatureData.dataBaseResultsMS2)
                id = cellfun(@isempty,app.FeatureData.dataBaseResultsMS2);
                MSSearch(~id) = app.FeatureData.dataBaseResultsMS2(~id);
            end

            mz = app.FeatureData.IdentifierArray(:,1);
            RT = app.FeatureData.IdentifierArray(:,2);
            colnames = ["ID","Feature","Avg. Intensity", "Standard Deviation","Fold Changes","p-Values","Identification Level","Database search results","Unique Feature in Group","Feature not found in Reference","Occurences in Group","Occurences over all","Intensities","Mass M [Da]","Retention Time [s]"];
            Out = table(ID,NameString,AverageIntensities,STD,fold,pVal,IdentLevel,MSSearch,UniqueFeature,FoundInRef,OccurenceInGroup,OccurenceAll,Intensities,mz,RT,'VariableNames',colnames);
            report = uifigure("Position",[100,100,1600,600],Name="Result Table");
            app.ReportTable = uitable(report,"Data",Out,Position=[5,5,1590,590]);
            app.ReportTable.ColumnSortable = true;
            app.ReportTable.CellSelectionCallback = @(src,event) {displayCellData(app,src,event)};
        end

        function app = displayCellData(app, ~, eventdata)
            %check for click outside table bounds
            if isempty(eventdata.Indices)
                return
            end
            % Get the selected cell indices
            selectedRow = eventdata.Indices(1,1);
            selectedColumn = eventdata.Indices(1,2);
            FeatName = app.FeatureData.NameStringArray(selectedRow);
            % highlight selection in volcano plot
            if ~isempty(app.VulcanoPlot)
                for n=1:size(app.VulcanoPlot,2)
                    if ~isvalid(app.VulcanoPlot(n))
                        continue
                    end
                    index = app.VulcanoPlot(n).Children(end).DataTipTemplate.DataTipRows(3,:).Value == FeatName;
                    % reset highlight
                    if size(app.VulcanoPlot(n).Children(1).XData,2) == 1
                        delete(app.VulcanoPlot(n).Children(1));
                    end
                    %plot new highlight
                    if any(index)
                        x = app.VulcanoPlot(n).Children(1).XData(index);
                        y = app.VulcanoPlot(n).Children(1).YData(index);
                        app.VulcanoPlot(n).NextPlot = "add";
                        scatter(app.VulcanoPlot(n),x,y,"red","filled")
                        app.VulcanoPlot(n).Children(1).DataTipTemplate.DataTipRows(1).Label = "log2 Fold Change:";
                        app.VulcanoPlot(n).Children(1).DataTipTemplate.DataTipRows(2).Label = "-log10 p:";
                        app.VulcanoPlot(n).Children(1).DataTipTemplate.DataTipRows(3) = dataTipTextRow("Feature:",FeatName);
                        app.VulcanoPlot(n).Children(1).DataTipTemplate.DataTipRows(4) = [];
                    end
                end
            end

            % highlight selection in scatter plot
            if ~isempty(app.ScatterPlot)
                for n=1:size(app.ScatterPlot,2)
                    if ~isvalid(app.ScatterPlot(n))
                        continue
                    end
                    % reset highlight
                    if size(app.ScatterPlot(n).Children(1).XData,2) == 1
                        while size(app.ScatterPlot(n).Children(1).XData,2) == 1
                            delete(app.ScatterPlot(n).Children(1));
                        end
                    end
                    for g = size(app.ScatterPlot(n).Children,1):-1:1
                        index = app.ScatterPlot(n).Children(g).DataTipTemplate.DataTipRows(3,:).Value == FeatName;
                        SizeDat = app.ScatterPlot(n).Children(g).SizeData(index);
                        %plot new highlight
                        if any(index)
                            x = app.ScatterPlot(n).Children(g).XData(index);
                            y = app.ScatterPlot(n).Children(g).YData(index);
                            app.ScatterPlot(n).NextPlot = "add";
                            scatter(app.ScatterPlot(n),x,y,SizeDat,0.5,"red","filled")
                            app.ScatterPlot(1).Children(1).DataTipTemplate.DataTipRows(1).Label = "RT:";
                            app.ScatterPlot(1).Children(1).DataTipTemplate.DataTipRows(2).Label = "mz:";
                            app.ScatterPlot(1).Children(1).DataTipTemplate.DataTipRows(3) = dataTipTextRow("Feature" , FeatName);
                            app.ScatterPlot(n).Children(1).DataTipTemplate.DataTipRows(4) = [];
                        end
                    end
                end
            end

            % Check if the selected cell contains a table then display
            % content
            if iscell(eventdata.Source.Data{selectedRow,selectedColumn})
                if istable(eventdata.Source.Data{selectedRow,selectedColumn}{:})
                    % Get the table from the cell
                    selectedTable = eventdata.Source.Data{selectedRow,selectedColumn}{:};
                    % Display the data in a new figure or dialog box
                    TableWindow = uifigure("Position",[100,100,920,400],Name="Database results for " + app.FeatureData.NameStringArray(selectedRow));
                    app.ResultSubDisplay = uitable(TableWindow,"Data", selectedTable, 'Position', [5,5,910,390],'UserData',selectedRow);
                    app.ResultSubDisplay.CellSelectionCallback = @(src,event) {SubDisplayCellPlot(app,src,event)};
                end
            end
        end

        function app = SubDisplayCellPlot(app, src, eventdata)
            if isempty(eventdata.Indices) % case when user clicks parts of table without cell
                return
            end
            selectedRow = eventdata.Indices(1);
            selectedColumn = eventdata.Indices(2);
            if selectedColumn == 8 %plot Spectra comparison
                FeatIndex = src.UserData;
                FeatName = app.FeatureData.NameStringArray(FeatIndex);
                Score = src.Data{selectedRow,selectedColumn};
                CompoundName = src.Data{selectedRow,1};

                DbSpectra = app.FeatureData.DataBaseSpectra{FeatIndex,1};
                ScoreAndID = app.FeatureData.DBScoreSpectraID{FeatIndex,1};

                MeasuredSpectra = app.FeatureData.FeatureMSnSpectra{FeatIndex,1};

                mzA = MeasuredSpectra(:,1);
                mzB = DbSpectra(:,1);
                IntA = MeasuredSpectra(:,ScoreAndID(selectedRow,2)+1);
                IntB = DbSpectra(:,ScoreAndID(selectedRow,3)+1);
                %scale
                IntA = IntA./max(IntA);
                IntB = IntB./max(IntB);
                idA = IntA ~= 0;
                idB = IntB ~= 0;

                SpecFig = uifigure("Name","MSn Spectra of "+FeatName);
                SpecAx = uiaxes(SpecFig,"Units","normalized","InnerPosition",[0.1,0.1,0.85,0.85]);
                xlabel(SpecAx,"m/z [Da]")
                ylabel(SpecAx,"normalized Intensity")
                stem(SpecAx,mzA(idA),IntA(idA),Marker="none")
                hold(SpecAx,"on")
                stem(SpecAx,mzB(idB),-IntB(idB),Marker="none")
                hold(SpecAx,"off")
                ylim(SpecAx,[-1.1,1.1])
                legend(SpecAx,["Measured Spectrum","Database Spectrum"],"Location","northwest")
                title(SpecAx,CompoundName + ", Score: "+ Score)
            end
        end
        
        %% PCA
        function PerformPCA(app)
            %preparation
            %get number of Samples of every group
            numSamps = app.FeatureData.NumberOfFilesArray;
            cumSamps = cumsum(numSamps);
            color = app.colorPalette(1:length(numSamps),:); %default color definition for gscatter
            labels = [app.FeatureData.GroupName]; %get group labels, when augmented groups replace combined name with abstract name
            idx = contains(labels,"+");
            labels(idx) = app.FeatureData.AbstractGroupName(idx);
            replabels = cell(numSamps(1),1); %preallocate then build cell array for gscatter grouping
            repColor = cell(length(numSamps),1);
            for n = 1:length(numSamps)
                replabels{1,n} = repmat(labels(n),numSamps(n),1);
                repColor{1,n} = repmat(color(n,:),numSamps(n),1);
            end
            replabels = vertcat(replabels{:}); %unpack cell array
            repColor = vertcat(repColor{:}); %unpack cell array

            %% perform pca
            PCAdata = app.FeatureData.IntensityArray'; %transpose so observations = rows, variables = columns
            [coeff,score,~,~,explained] = pca(PCAdata,"Centered",false);
            % check for meaningful result
            if size(score,2) == 1
                uialert(app.MainWindow,["Only one PC calculated"; "Add more Samples or use less restrictive Filters"],'Bad Result','Icon','warning');
                return
            end
            % plot
            % build figure
            PCAres = uifigure("Name","PCA Score Plots");
            app.PCAMultiplot(1)=tiledlayout(PCAres,2,2);
            app.PCAMultiplot(1).TileSpacing = 'compact';
            app.PCAMultiplot(1).Padding = 'compact';
            s1 = nexttile(app.PCAMultiplot(1));
            % PC1 vs 2
            gscatter(s1,score(:,1),score(:,2),replabels,color);
            s1.XAxisLocation = 'Origin';
            s1.YAxisLocation = 'Origin';
            xlabel(s1,'PC1')
            ylabel(s1,'PC2')
            title(s1,'Scoreplot PC1 vs PC2')
            grid(s1,'on')
            % PC1 vs 3
            s2 = nexttile(app.PCAMultiplot(1));
            if size(score,2)>=3
                gscatter(s2,score(:,1),score(:,3),replabels,color);
                s2.XAxisLocation = 'Origin';
                s2.YAxisLocation = 'Origin';
                xlabel(s2,'PC1')
                ylabel(s2,'PC3')
                title(s2,'Scoreplot PC1 vs PC3')
                grid(s2,'on')
                % PC2 vs 3
                s3 = nexttile(app.PCAMultiplot(1));
                gscatter(s3,score(:,2),score(:,3),replabels,color);
                s3.XAxisLocation = 'Origin';
                s3.YAxisLocation = 'Origin';
                xlabel(s3,'PC2')
                ylabel(s3,'PC3')
                title(s3,'Scoreplot PC2 vs PC3')
                grid(s3,'on')
            end
            %Explained Variance
            s4 = nexttile(app.PCAMultiplot(1));
            yyaxis(s4,"left")
            bar(s4,explained);
            ylabel(s4,'individual [%]')
            yyaxis(s4,"right")
            plot(s4,cumsum(explained),'LineWidth',2)
            title(s4,'Explained Variance')
            ylabel(s4,'Cumulative [%]')
            xlabel(s4,'Principle Component')
            %3D scatter
            if size(score,2) >= 3
                s3D=uifigure("Name","3D Score Plot");
                app.PCA3Dplot = uiaxes(s3D,'Position',[10 10 550 400]);
                scatter3(app.PCA3Dplot,score(1:numSamps(1),1),score(1:numSamps(1),2),score(1:numSamps(1),3),50,repColor(1:numSamps(1),:),'filled')
                hold(app.PCA3Dplot,"on")
                for n=1:length(numSamps)-1
                    scatter3(app.PCA3Dplot,score(cumSamps(n)+1:cumSamps(n+1),1),score(cumSamps(n)+1:cumSamps(n+1),2),score(cumSamps(n)+1:cumSamps(n+1),3),50,repColor(cumSamps(n)+1:cumSamps(n+1),:),'filled')
                end
                hold(app.PCA3Dplot(1),"off")
                app.PCA3Dplot.XAxisLocation = 'Origin';
                app.PCA3Dplot.YAxisLocation = 'Origin';
                legend(app.PCA3Dplot,labels,'Interpreter','none')
                title(app.PCA3Dplot,"3D Score Plot")
                grid(app.PCA3Dplot,'on')
            end
            drawnow

            %bi-plots
            %% ask for number of Loadings in Loading plot
            bigload = sum(abs(coeff(:,1:3)),2);
            NumLoadings = numel(bigload);
            prompt = 'Select the number of loadings shown in Biplots:';
            dlgtitle = "Number of Loadings: "+ NumLoadings;
            dims = [1 35];
            definput = {'15'};
            num2Plot = inputdlg(prompt,dlgtitle,dims,definput);
            num2Plot = str2double(num2Plot{:});
            %extract highest loadings

            [~,idx] = maxk(bigload,num2Plot);
            bigcoeff = coeff(idx,:);
            %get Var-names
            loadingNames = app.FeatureData.NameStringArray(idx);
            %create plot
            PCAbi = uifigure("Name","PCA Biplots");
            app.PCAMultiplot(2) = tiledlayout(PCAbi,2,2);
            app.PCAMultiplot(2).TileSpacing = 'compact';
            app.PCAMultiplot(2).Padding = 'compact';
            % Plot 1 - BiPlot 1vs2
            sb1 = nexttile(app.PCAMultiplot(2));
            biplot(sb1,bigcoeff(:,1:2),'Scores',score(:,1:2),'Varlabels',loadingNames,'MarkerSize',15)
            xlabel(sb1,'Component 1')
            ylabel(sb1,'Component 2')
            title(sb1,'Biplot PC1 vs PC2')

            if size(score,2)>=3  
                % Plot 2 - BiPlot 1vs3
                sb2 = nexttile(app.PCAMultiplot(2));
                biplot(sb2,[bigcoeff(:,1),bigcoeff(:,3)],'Scores',[score(:,1),score(:,3)],'Varlabels',loadingNames,'MarkerSize',15)
                xlabel(sb2,'Component 1')
                ylabel(sb2,'Component 3')
                title(sb2,'Biplot PC1 vs PC3')
                % Plot 3 - BiPlot 2vs3
                sb3 = nexttile(app.PCAMultiplot(2));
                biplot(sb3,bigcoeff(:,2:3),'Scores',score(:,2:3),'Varlabels',loadingNames,'MarkerSize',15)
                xlabel(sb3,'Component 2')
                ylabel(sb3,'Component 3')
                title(sb3,'Biplot PC2 vs PC3')
                 % Plot 4 - BiPlot 3D
                sb4 = nexttile(app.PCAMultiplot(2));
                biplot(sb4,bigcoeff(:,1:3),'Scores',score(:,1:3),'Varlabels',loadingNames,'MarkerSize',15)
                title(sb4,'Biplot PC1 vs PC2 vs PC3')
            end
            drawnow
        end


        function rebuildTempFiles(app,previewCells,varargin)
            %assign TemporaryFile names
            for n = 1:numel(app.MassData)
                app.MassData{n}.RawDataFile = tempname +".mat";
                app.MassData{n}.ROIDataFile = tempname +".mat";
            end
            if isscalar(varargin)
                roiCells = varargin{1};
                for n = 1:width(roiCells)
                    if ~isempty(roiCells{1,n})
                        app.MassData{n}.ROIDataFileObj = matfile(app.MassData{n}.ROIDataFile,Writable=true);
                        app.MassData{n}.ROIDataFileObj.ROIMat = roiCells{1,n};
                        app.MassData{n}.ROIDataFileObj.ROIMatBLK = roiCells{2,n};
                        app.MassData{n}.ROIDataFileObj.ROImzVec = roiCells{3,n};
                        app.MassData{n}.ROIDataFileObj.timeVec = roiCells{4,n};
                    end
                end
            end

            for n = 1:width(app.MassData)
                app.MassData{n}.RawDataFileObj = matfile(app.MassData{n}.RawDataFile,Writable=true);

                %predefine Variables in .mat file
                app.MassData{n}.RawDataFileObj.previewTICs = {[]};
                app.MassData{n}.RawDataFileObj.previewBPCs = {[]};
                app.MassData{n}.RawDataFileObj.previewTimes = {[]};

                app.MassData{n}.RawDataFileObj.profileDataMS1 = {[]};
                app.MassData{n}.RawDataFileObj.centroidedDataMS1 = {[]};
                app.MassData{n}.RawDataFileObj.timeDataMS1 = {[]};
                app.MassData{n}.RawDataFileObj.polarityMS1 = {[]};

                app.MassData{n}.RawDataFileObj.profileDataMS2 = {[]};
                app.MassData{n}.RawDataFileObj.centroidedDataMS2 = {[]};
                app.MassData{n}.RawDataFileObj.timeDataMS2 = {[]};
                app.MassData{n}.RawDataFileObj.polarityMS2 = {[]};
                app.MassData{n}.RawDataFileObj.precursorMass = {[]};
                app.MassData{n}.RawDataFileObj.molecularPrecursorMass = {[]};
                app.MassData{n}.RawDataFileObj.fragmentationEnergy = {[]};
                app.MassData{n}.RawDataFileObj.fragmentationType = {[]};

            end
        end

        function saveData(app)
            dialog = uiprogressdlg(app.MainWindow,'Title','Exporting Data','Message','Please Wait','Indeterminate','on');
            exportData.RawData = app.MassData;
            %remove temporary file locations and objects
            for iGroups = 1:numel(app.MassData)
                exportData.RawData(iGroups).RawDataFile = [];
                exportData.RawData(iGroups).RawDataFileObj = 0;
                exportData.RawData(iGroups).ROIDataFile = [];
                exportData.RawData(iGroups).ROIDataFileObj = 0;
            end
            exportData.FeatureData = app.FeatureData;
            exportData.FullFeatureData = app.FullFeatureData;
            exportData.augmentationAssignment = app.augmentationAssignment;
            exportData.maxP = app.EvaluationObject.pValueMax;
            exportData.minFold =  app.EvaluationObject.foldChangeMin;
            exportData.mzMergeTol =  app.EvaluationObject.mergeMassTolerance;
            exportData.mzMergeTolUnit =  app.EvaluationObject.mergeMassUnit;
            exportData.rtMergeTol =  app.EvaluationObject.mergeTimeTolerance;
            exportData.hochbergFilter =  app.EvaluationObject.useHochbergFilter;
            exportData.dataBaseFile = app.dataBaseFile;
            previews = cell(3,numel(app.MassData));
            for iGroups = 1:numel(app.MassData)
                previews{1,iGroups} = app.MassData{iGroups}.RawDataFileObj.previewTICs;
                previews{2,iGroups} = app.MassData{iGroups}.RawDataFileObj.previewBPCs;
                previews{3,iGroups} = app.MassData{iGroups}.RawDataFileObj.previewTimes;
            end
            exportData.previews = previews;
            roiResults = cell(4,numel(app.MassData));
            for iGroups = 1:numel(app.MassData)
                if exist(app.MassData{iGroups}.ROIDataFile,"file")
                    roiResults{1,iGroups} = app.MassData{iGroups}.ROIDataFileObj.ROIMat;
                    roiResults{2,iGroups} = app.MassData{iGroups}.ROIDataFileObj.ROIMatBLK;
                    roiResults{3,iGroups} = app.MassData{iGroups}.ROIDataFileObj.ROImzVec;
                    roiResults{4,iGroups} = app.MassData{iGroups}.ROIDataFileObj.timeVec;
                end
            end
            exportData.roiResults = roiResults;
            save(app.saveLocation,'exportData');
            message = {'Poject saved at:', convertStringsToChars(app.saveLocation)};
            close(dialog)
            uialert(app.MainWindow,message,"File saved successfully","Icon","success");
        end
    end
    %% Public Callbacks Block
    methods (Access = public)
        %% Tab Options
        function app = groupNameChange(app,~,event,CurrentTab)
            app.Tab(CurrentTab).Title = event.Value;
            if width(app.MassData) ~= 0 && ~isempty(app.MassData{CurrentTab})
                app.MassData{CurrentTab}.groupName = event.Value;
            end
        end

        function app = selectFiles(app,~,~,CurrentTab)
            %Select Separation Type
            msg = "Select separation type.";
            title = "Confirm separation type";
            selection = uiconfirm(app.MainWindow,msg,title, ...
            "Options",["LC-MS","GC-MS","CE-MS","Cancel"], ...
            "DefaultOption",1,"CancelOption",4);
            
            currentName = app.GroupNameField(CurrentTab).Value;

            switch selection
                case "LC-MS"
                    app.MassData{CurrentTab} = LCData(currentName,app.MainWindow);
                    fileTypes = {'*.mzXML;*.mzML','MS Files (*.mzXML,*.mzML)'};
                case "GC-MS"
                    app.MassData{CurrentTab} = GCData(currentName,app.MainWindow);
                    fileTypes = {'*.mzXML;*.mzML;*.CDF','MS Files (*.mzXML,*.mzML,*.CDF)'};
                case "CE-MS"
                    app.MassData{CurrentTab} = CEData(currentName,app.MainWindow);
                    fileTypes = {'*.mzXML;*.mzML','MS Files (*.mzXML,*.mzML)'};

                otherwise
                    uialert(app.MainWindow,"Task aborted by user","No Data Selected","Icon","warning")
                return
            end

            title = "Select Files for " + app.MassData{CurrentTab}.groupName;
            [fileName,path] = uigetfile(fileTypes,title,app.selectedFolder,'MultiSelect','on');
            if ischar(path) %Check Cancel input
                figure(app.Tab(CurrentTab).Parent.Parent)
                %update default directory
                app.selectedFolder = path;
                app.MassData{CurrentTab}.fileName = fileName;
                app.MassData{CurrentTab}.dataFile = fullfile(path,fileName);
                if ~isempty(app.MassData{CurrentTab}.dataFile)
                    % delete previous data
                    delete(app.MassData{CurrentTab}.RawDataFile)
                    % rebuild file
                    app.MassData{CurrentTab}.RawDataFileObj = matfile(app.MassData{CurrentTab}.RawDataFile,Writable=true);
                    %predefine Variables in .mat file
                    app.MassData{CurrentTab}.RawDataFileObj.previewTICs = {[]};
                    app.MassData{CurrentTab}.RawDataFileObj.previewBPCs = {[]};
                    app.MassData{CurrentTab}.RawDataFileObj.previewTimes = {[]};

                    app.MassData{CurrentTab}.RawDataFileObj.profileDataMS1 = {[]};
                    app.MassData{CurrentTab}.RawDataFileObj.centroidedDataMS1 = {[]};
                    app.MassData{CurrentTab}.RawDataFileObj.timeDataMS1 = {[]};
                    app.MassData{CurrentTab}.RawDataFileObj.polarityMS1 = {[]};

                    app.MassData{CurrentTab}.RawDataFileObj.profileDataMS2 = {[]};
                    app.MassData{CurrentTab}.RawDataFileObj.centroidedDataMS2 = {[]};
                    app.MassData{CurrentTab}.RawDataFileObj.timeDataMS2 = {[]};
                    app.MassData{CurrentTab}.RawDataFileObj.polarityMS2 = {[]};
                    app.MassData{CurrentTab}.RawDataFileObj.precursorMass = {[]};
                    app.MassData{CurrentTab}.RawDataFileObj.molecularPrecursorMass = {[]};
                    app.MassData{CurrentTab}.RawDataFileObj.fragmentationEnergy = {[]};
                    app.MassData{CurrentTab}.RawDataFileObj.fragmentationType = {[]};

                    %remove FileInfo Labels
                    if isgraphics(app.InfoLabels(1,CurrentTab))
                        delete(app.InfoLabels(:,CurrentTab))
                        app.InfoLabels(:,CurrentTab) = gobjects(5,1);
                    end
                end
            else
                figure(app.Tab(CurrentTab).Parent.Parent)
                uialert(app.Tab(CurrentTab).Parent.Parent,"Task aborted by user","No Data Selected","Icon","warning")
                return
            end
            if app.MassData{CurrentTab}.useBlankSubtraction == true
                title = "Select Blank Files for " + app.MassData{CurrentTab}.groupName;
                [blkFileName,blkPath] = uigetfile(fileTypes,title,app.selectedFolder,'MultiSelect','on');
                if ischar(blkPath) == 0 %Check Cancel input
                    figure(app.Tab(CurrentTab).Parent.Parent)
                    uialert(app.Tab(CurrentTab).Parent.Parent,"Task aborted by user","No Data Selected","Icon","warning")
                    return
                else
                    figure(app.Tab(CurrentTab).Parent.Parent)
                    app.MassData{CurrentTab}.blankFile = fullfile(blkPath,blkFileName);
                end
            end
            dialog = uiprogressdlg(app.MainWindow,'Title','Checking Files','Message','Please Wait','Indeterminate','on');

            % delete old Plot
            if app.currentPlot(1,CurrentTab) == true
                delete(app.PreviewAxes(CurrentTab).Children)
                delete(app.PreviewAxes(CurrentTab))
                app.currentPlot(1,CurrentTab) = false;
            elseif app.currentPlot(2,CurrentTab) == true
                delete(app.PreviewTiled(CurrentTab).Children)
                delete(app.PreviewTiled(CurrentTab))
                app.currentPlot(2,CurrentTab) = false;
            end

            app.MassData{CurrentTab} = app.MassData{CurrentTab}.dataFileCheck;
            nScansMS1 = round(mean([app.MassData{CurrentTab}.DataInfo.numberOfScansMS1]));
            nScansMSn = sum([app.MassData{CurrentTab}.DataInfo.numberOfScansMSn]~=0);
            freq = round(mean([app.MassData{CurrentTab}.DataInfo.scanFrequencyMS1]),2);

            app.InfoLabels(1,CurrentTab) = uilabel(app.Tab(CurrentTab),"Text","File Infos:",...
                "Position",[130,640,200,20],...
                "HorizontalAlignment","left",...
                "VerticalAlignment","center",...
                "Tooltip",["Selected Files:";app.MassData{CurrentTab}.fileName;app.MassData{CurrentTab}.blankFile]);

            app.InfoLabels(2,CurrentTab) = uilabel(app.Tab(CurrentTab),"Text","Separation type: " + selection,...
                "Position",[130,620,200,20],...
                "HorizontalAlignment","left",...
                "VerticalAlignment","center",...
                "Tooltip","Change separation type by data reselection");

            app.InfoLabels(3,CurrentTab) = uilabel(app.Tab(CurrentTab),"Text","Scan Rate MS1: " + freq + " Hz",...
                "Position",[130,600,200,20],...
                "HorizontalAlignment","left",...
                "VerticalAlignment","center");

            app.InfoLabels(4,CurrentTab) = uilabel(app.Tab(CurrentTab),"Text","Number of MS1 Scans: " + nScansMS1,...
                "Position",[130,580,200,20],...
                "HorizontalAlignment","left",...
                "VerticalAlignment","center",...
                "Tooltip","Average number of MS1 scans");

            app.InfoLabels(5,CurrentTab) = uilabel(app.Tab(CurrentTab),"Text","Files with MS/MS Scans: " + nScansMSn +"/"+numel(app.MassData{CurrentTab}.fileName),...
                "Position",[130,560,200,20],...
                "HorizontalAlignment","left",...
                "VerticalAlignment","center",...
                "Tooltip","Number of files with MS/MS data");

            %throw warning when no ESI-MS files contain MS2 data
            if strcmp(selection,"LC-MS") | strcmp(selection,"CE-MS") && nScansMSn == 0 
                selection = uiconfirm(app.Tab(CurrentTab).Parent.Parent, ...
                    ["Selected files do not contain MS/MS data.","Fragmentation based feature matching will be disabled.","Continue?"], ...
                    "Confirm to continue", ...
                    "Options",["Continue","Cancel"], ...
                    "DefaultOption",2,"CancelOption",2,...
                    "Icon","warning");
                switch selection
                    case 'Cancel'
                        %remove loaded files
                        app.MassData{CurrentTab}.fileName = [];
                        app.MassData{CurrentTab}.dataFile = [];
                        % delete previous data
                        delete(app.MassData{CurrentTab}.RawDataFile)

                        %remove FileInfo Labels
                        delete(app.InfoLabels(:,CurrentTab))
                        app.InfoLabels(:,CurrentTab) = gobjects(5,1);
                        CheckReady(app)
                        return
                end
            end

            %% build panels
            app.addMainOptions(CurrentTab)
            app.addROIOptions(CurrentTab)
            app.addIntegrationOptions(CurrentTab);
            app.addPeakAlignPanel(CurrentTab);
            app.addMSAlignPanel(CurrentTab);
            app.addScalingPanel(CurrentTab);
            app.addAddNLossPanel(CurrentTab);
            app.addGolayPanel(CurrentTab);
            app.addBaselinePanel(CurrentTab);
            app.addISPanel(CurrentTab)

            %update field limits based om DataCheck
            app.ROIOptionsPanel(CurrentTab).StartTimeField.Limits = [app.MassData{CurrentTab}.measurementStartTime,app.MassData{CurrentTab}.measurementEndTime];
            app.ROIOptionsPanel(CurrentTab).EndTimeField.Limits = [app.MassData{CurrentTab}.measurementStartTime,app.MassData{CurrentTab}.measurementEndTime];
            app.ROIOptionsPanel(CurrentTab).StartTimeField.Value = app.MassData{CurrentTab}.measurementStartTime;
            app.ROIOptionsPanel(CurrentTab).EndTimeField.Value = app.MassData{CurrentTab}.measurementEndTime;
            %ask for File Preview
            app.PreviewButton(CurrentTab).Visible = "on";
            app.EICButton(CurrentTab).Visible = "on";
            close(dialog)

            previewFile(app,CurrentTab)
            % activate common panels
            app.MainOptionsPanel(CurrentTab).Container.Enable = true;
            app.ROIOptionsPanel(CurrentTab).Container.Enable = true;
            app.IntegrationOptionsPanel(CurrentTab).Container.Enable = true;
            app.PeakAlignPanel(CurrentTab).Container.Enable = app.MassData{CurrentTab}.usePeakAlign;
            app.MSAlignPanel(CurrentTab).Container.Enable = app.MassData{CurrentTab}.useMassAlign;
            app.BaselinePanel(CurrentTab).Container.Enable = app.MassData{CurrentTab}.useBaselineCorrection;
            app.GolayPanel(CurrentTab).Container.Enable = app.MassData{CurrentTab}.useSmoothing;
            app.ScalingPanel(CurrentTab).Container.Enable = app.MassData{CurrentTab}.useScaling;
            app.ISPanel(CurrentTab).Container.Enable = app.MassData{CurrentTab}.useInternalStandard;

            %activate panels based on SeparationType
            switch app.MassData{CurrentTab}.separationType
                case {"LC","CE"}
                    app.AddNLossPanel(CurrentTab).Container.Enable = app.MassData{CurrentTab}.useAdductFilter;
                case "GC"
                    app.AddNLossPanel(CurrentTab).Container.Visible = false;
                    app.MainOptionsPanel(CurrentTab).IsotopeFilterTickBox.Enable = false;
                    app.MainOptionsPanel(CurrentTab).AddNLossTickBox.Enable = false;
                    app.MainOptionsPanel(CurrentTab).ContaminantTickBox.Enable = false;
            end

            %expand SampleScaling Vector and remove old values
            app.MassData{CurrentTab}.sampleScale = ones(size(app.MassData{CurrentTab}.fileName,1),1);

            % Warn User by Switching Lamps to Red
            if isempty(app.ScalingPanel(CurrentTab).window) == false
                app.ScalingPanel(CurrentTab).ScalingLamp.Color = [1,0,0];
            end
            app.AddNLossPanel(CurrentTab).AddNLossLamp.Color = [1,0,0];
            CheckReady(app)
            close(dialog)
        end

        function app = testParameters(app,~,~,CurrentTab)
            %check if Preview already loaded
            dialog = uiprogressdlg(app.MainWindow,'Title','Preparing','Message','Please Wait','Indeterminate','on');
            check = app.MassData{CurrentTab}.RawDataFileObj.profileDataMS1;
            if height(check) ~= height(app.MassData{CurrentTab}.fileName)
                dialog.Title = 'Loading Data';
                app.MassData{CurrentTab} = app.MassData{CurrentTab}.readData(app.MassData{CurrentTab}.dataFile,app.MassData{CurrentTab}.separationType);
                % delete old Plot
                if app.currentPlot(1,CurrentTab) == true
                    delete(app.PreviewAxes(CurrentTab).Children)
                    delete(app.PreviewAxes(CurrentTab))
                    app.currentPlot(1,CurrentTab)=false;
                elseif app.currentPlot(2,CurrentTab) == true
                    delete(app.PreviewTiled(CurrentTab).Children)
                    delete(app.PreviewTiled(CurrentTab))
                    app.currentPlot(2,CurrentTab)=false;
                end
            end

            dialog.Title = 'Processing Data';
            %build TempDataFile
            app.MassData{CurrentTab}.TempDataFile = tempname +".mat";
            app.MassData{CurrentTab}.TempDataFileObj = matfile(app.MassData{CurrentTab}.TempDataFile,Writable=true);
            %predefine Variables in .mat file
            app.MassData{CurrentTab}.TempDataFileObj.ROICells  = {[]};
            app.MassData{CurrentTab}.TempDataFileObj.TimeCells  = {[]};
            app.MassData{CurrentTab}.TempDataFileObj.ROIMat = [];
            app.MassData{CurrentTab}.TempDataFileObj.ROIMatBLK  = [];
            app.MassData{CurrentTab}.TempDataFileObj.ROImzVec = [];
            app.MassData{CurrentTab}.TempDataFileObj.timeVec  = [];

            app.MassData{CurrentTab} = app.MassData{CurrentTab}.cutScansToSize;
            %Apply MSAlign
            if app.MassData{CurrentTab}.useMassAlign == true
                dialog.Message = 'Performing Spectral Alignment';
                app.MassData{CurrentTab} = app.MassData{CurrentTab}.alignMasses("preview");
            end
            dialog.Value = .2;
            %ROISearch
            dialog.Message = 'Searching for ROIs';
            app.MassData{CurrentTab} = app.MassData{CurrentTab}.findRegionOfInterest("preview");
            dialog.Value = .4;
            %Apply Baseline Correction
            if app.MassData{CurrentTab}.useBaselineCorrection == true
                dialog.Message = 'Performing Baseline Correction';
                app.MassData{CurrentTab} = app.MassData{CurrentTab}.correctBaseline("preview");
                dialog.Value = .5;
            end
            %Apply Smoothing
            if app.MassData{CurrentTab}.useSmoothing == true
                dialog.Message = 'Performing Golay Smoothing';
                app.MassData{CurrentTab} = app.MassData{CurrentTab}.smoothPeaks("preview");
                dialog.Value = .6;
            end
            %Apply Contaminant Filter
            if app.MassData{CurrentTab}.useContaminantFilter == true
                dialog.Message = 'Filtering Contaminants';
                pause(0.5)
                app.MassData{CurrentTab} = app.MassData{CurrentTab}.removeContaminants;
            end
            app.MassData{CurrentTab} = app.MassData{CurrentTab}.finalizeROI;
            dialog.Value = .9;
            dialog.Message = 'Plotting Graphs';
            %Graphs
            roiData = app.MassData{CurrentTab}.TempDataFileObj.ROIMat;
            times = app.MassData{CurrentTab}.TempDataFileObj.timeVec;
            roiMass = flip(app.MassData{CurrentTab}.TempDataFileObj.ROImzVec);
            roiDataScaled = (sum(roiData)./max(sum(roiData)))*100;

            app.PreviewTiled(CurrentTab) = tiledlayout(app.Tab(CurrentTab),2,1, ...
                "TileSpacing","compact", ...
                Padding="compact", ...
                Units="pixels", ...
                OuterPosition=[470 200 680 420], ...
                PositionConstraint="outerposition", ...
                Visible="off");

            app.PreviewChrom(CurrentTab) = nexttile(app.PreviewTiled(CurrentTab));
            colororder(app.PreviewChrom(CurrentTab),"gem12")
            % Plot ROIs
            titleString = "ROIs for " + app.MassData{CurrentTab}.groupName;
            plot(app.PreviewChrom(CurrentTab),times,roiData)
            %add datatips
            for iPlot = size(app.PreviewChrom(CurrentTab).Children,1):-1:1
                app.PreviewChrom(CurrentTab).Children(iPlot).DataTipTemplate.DataTipRows(1).Label = "RT:";
                app.PreviewChrom(CurrentTab).Children(iPlot).DataTipTemplate.DataTipRows(2).Label = "Intensity:";
                app.PreviewChrom(CurrentTab).Children(iPlot).DataTipTemplate.DataTipRows(3) = dataTipTextRow("ROI m/z:",repmat(roiMass(iPlot),size(times,1),1),'%.4g');
            end
            xlabel(app.PreviewChrom(CurrentTab),"Time [sec]")
            ylabel(app.PreviewChrom(CurrentTab),"Intensity [arb]")
            title(app.PreviewChrom(CurrentTab), titleString)

            %Plot MZ values
            app.PreviewMZ(CurrentTab)=nexttile(app.PreviewTiled(CurrentTab));
            titleString = "ROI mz values for " + app.MassData{CurrentTab}.groupName;
            roiMass = flip(roiMass);
            stem(app.PreviewMZ(CurrentTab),roiMass,roiDataScaled,'Marker','none','Color','k')
            app.PreviewMZ(CurrentTab).Children(1).DataTipTemplate.DataTipRows(1).Label="m/z:";
            app.PreviewMZ(CurrentTab).Children(1).DataTipTemplate.DataTipRows(2).Label="Relative intensity:";
            ylim(app.PreviewMZ(CurrentTab),[0,110])
            xlabel(app.PreviewMZ(CurrentTab),"m/z [Da]")
            ylabel(app.PreviewMZ(CurrentTab),"Relative Intensity [%]")
            title(app.PreviewMZ(CurrentTab), titleString)
            delete(app.PreviewAxes(CurrentTab))
            app.PreviewTiled(CurrentTab).Visible = "on";
            app.currentPlot(2,CurrentTab) = true;
            dialog.Message = 'Done';
            dialog.Value = 1;
            delete(app.MassData{CurrentTab}.TempDataFile)
            app.MassData{CurrentTab}.TempDataFile = "";
            pause(0.5)
            close(dialog) %Close loading box
        end

        function app = plotEIC(app,~,~,CurrentTab)
            dialog = uiprogressdlg(app.MainWindow,'Title','Please Wait','Message','Select EICs','Indeterminate','on');
            % construct input window and get values
            [massArray,tolerance,tolUnit] = app.EICSelectionGUI(CurrentTab);
            %check for user abort
            if isempty(massArray)
                uialert(app.Tab(CurrentTab).Parent.Parent,"Task aborted by user","No masses selected","Icon","warning")
                return
            end

            %Full load Data
            %check if files already loaded
            check = app.MassData{CurrentTab}.RawDataFileObj.profileDataMS1;
            if height(check) ~= height(app.MassData{CurrentTab}.Files) || isempty(check{1,1})
                dialog.Message = "Loading Files";
                app.MassData{CurrentTab} = app.MassData{CurrentTab}.readData(app.MassData{CurrentTab}.fileName,app.MassData{CurrentTab}.separationType);
            end

            %extract EICs
            dialog.Message = 'Building EICs';
            fileData = app.MassData{CurrentTab}.RawDataFileObj.centroidedDataMS1;
            times = app.MassData{CurrentTab}.RawDataFileObj.timeDataMS1;
            eicStorage = cell(size(app.MassData{CurrentTab}.Files,1),numel(massArray));
            for iMass = 1:numel(massArray)
                mass = massArray(iMass);
                parfor jFile = 1:size(fileData,1)
                    eicStorage{jFile,iMass} = buildEIC(fileData{jFile,1},mass,tolerance,tolUnit);
                end
            end
            %plot
            fig = figure("Name","EICs for " + app.MassData{CurrentTab}.groupName,"NumberTitle","off");
            Tiles = tiledlayout("vertical");
            colororder(fig,"gem12")
            for iMass = 1:size(eicStorage,2)
                nexttile(Tiles)
                hold on
                for jFile = 1:size(eicStorage,1)
                    plot(times{jFile,1},eicStorage{jFile,iMass})
                    Tiles.Children(1).Children(1).DataTipTemplate.DataTipRows(1).Label="RT:";
                    Tiles.Children(1).Children(1).DataTipTemplate.DataTipRows(2).Label="Intensity:";
                end
                hold off
                title("EIC of m/z " + massArray(iMass) + " Da " + "\pm " + tolerance + " " + tolUnit)
                xlabel("time [s]")
                ylabel("Intensity [arb]")
                legend(app.MassData{CurrentTab}.fileName,Interpreter="none")
            end
            drawnow
            close(dialog)
        end

        %% Main Options

        function app = useBlankChange(app,~,event,CurrentTab)
            %stores changed Blank Subtraction value in calling Tab
            app.MassData{CurrentTab}.useBlankSubtraction = event.Value;
            if app.MassData{CurrentTab}.separationType == "GC"
                filetypes = {'*.mzXML;*.mzML;*.CDF','MS Files (*.mzXML,*.mzML,*.CDF)'};
            else
                filetypes = {'*.mzXML;*.mzML','MS Files (*.mzXML,*.mzML)'};
            end
            if event.Value == true && isempty(app.MassData{CurrentTab}.blankFile) == true
                text = "Select Blank Files for " + app.MassData{CurrentTab}.groupName;
                [fileName,path] = uigetfile(filetypes,text,app.selectedFolder,'MultiSelect','on');
                figure(app.Tab(CurrentTab).Parent.Parent)

                if ischar(path) == 0 %Check Cancel input
                    uialert(app.Tab(CurrentTab).Parent.Parent,"Task aborted by user","No Data Selected","Icon","warning")
                    app.MassData{CurrentTab}.useBlankSubtraction = false;
                    app.MainOptionsPanel(CurrentTab).BlankTickBox.Value = false;
                    return
                else
                    app.MassData{CurrentTab}.blankFile = fullfile(path,fileName);
                end
            end
            if app.MassData{CurrentTab}.useInternalStandard == true
                app.ISPanel(CurrentTab).ApplyDrop.Enable = event.Value;
                app.ISPanel(CurrentTab).ApplyLabel.Enable = event.Value;
                app.ISPanel(CurrentTab).OrderLabel.Enable = event.Value;
                app.ISPanel(CurrentTab).OrderDrop.Enable = event.Value;
            end
            CheckReady(app)
        end

        function app = usePeakAlignmentChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.usePeakAlign = event.Value;
            %toggle Panel Visibility
            app.PeakAlignPanel(CurrentTab).Container.Enable = event.Value;

        end
        function app = useMassAlignmentChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.useMassAlign = event.Value;
            %toggle Panel Visibility
            app.MSAlignPanel(CurrentTab).Container.Enable = event.Value;

        end
        function app = useBaselineCorrectionChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.useBaselineCorrection = event.Value;
            %toggle Panel Visibility
            app.BaselinePanel(CurrentTab).Container.Enable = event.Value;
        end
        function app = useSmoothingChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.useSmoothing = event.Value;
            %toggle Panel Visibility
            app.GolayPanel(CurrentTab).Container.Enable = event.Value;

        end
        function app = useInternalStandardChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.useInternalStandard = event.Value;
            %toggle Panel Visibility
            app.ISPanel(CurrentTab).Container.Enable = event.Value;
            if app.MassData{CurrentTab}.useBlankSubtraction == true
                app.ISPanel(CurrentTab).ApplyDrop.Enable = event.Value;
                app.ISPanel(CurrentTab).ApplyLabel.Enable = event.Value;
                app.ISPanel(CurrentTab).OrderLabel.Enable = event.Value;
                app.ISPanel(CurrentTab).OrderDrop.Enable = event.Value;
            end
            CheckReady(app)
        end

        function app = useIsotopeFilterChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.useIsotopeFilter = event.Value;
        end

        function app = useScalingChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.useScaling = event.Value;
            %toggle Panel Visibility
            app.ScalingPanel(CurrentTab).Container.Enable = event.Value;
            CheckReady(app)
        end
        function app = useAddNLossChange(app,~,event,CurrentTab)
            %stores changed AddNLoss value in calling Tab and calls AddNlossPanel
            app.MassData{CurrentTab}.useAdductFilter = event.Value;
            %toggle Panel Visibility
            app.AddNLossPanel(CurrentTab).Container.Enable = event.Value;
            CheckReady(app)
        end
        function app = useContaminantFilterChange(app,~,event,CurrentTab)
            %stores changed Contaminant value in calling Tab and calls ContaminantPanel
            app.MassData{CurrentTab}.useContaminantFilter = event.Value;
        end
        
        %% ROI Panel
        function app = ThreshChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.roiThreshold = event.Value;
        end
        function app = mzerrorChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.withinFileMassTolerance = event.Value;
        end
        function app = mzerrorUnitChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.withinFileMassUnit = event.Value;
        end
        function app = minROIChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.roiMinOccurrence = event.Value;
        end
        function app = STimeChange(app,~,event,CurrentTab)
            app.ROIOptionsPanel(CurrentTab).EndTimeField.Limits = [event.Value+0.1,Inf];
            app.MassData{CurrentTab}.measurementStartTime = event.Value;
        end
        function app = ETimeChange(app,~,event,CurrentTab)
            app.ROIOptionsPanel(CurrentTab).StartTimeField.Limits = [0,event.Value-0.1];
            app.MassData{CurrentTab}.measurementEndTime = event.Value;
        end
        %% Integration Panel
        function app = betweenFileMassToleranceChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.betweenFileMassTolerance = event.Value;
        end
        function app = betweenFileMassUnitChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.betweenFileMassUnit = event.Value;
        end
        function app = peakTimeToleranceChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.peakTimeTolerance = event.Value;
        end
        function app = useEntropyChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.useEntropyFilter = event.Value;
            app.IntegrationOptionsPanel(CurrentTab).EntropyDrop.Enable = event.Value;
        end
        function app = entropyStrengthChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.entropyFilterStrength = event.Value;
        end
        function app = minOccurrenceChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.minOccurence = event.Value/100;
        end
        function app = minSNChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.minSignal2Noise = event.Value;
        end
        function app = maxWidthChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.peakMaxWidth = event.Value;
        end
        function app = minWidthChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.peakMinWidth = event.Value;
        end

        function app = evalParameterChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.evaluationParameter = event.Value;
        end
        %% SubPanel Callbacks
        %% Golay
        function app = smoothingFrameSizeChanged(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.smoothingFrameSize = event.Value;
            app.GolayPanel(CurrentTab).Degree.Limits = [1,event.Value - 1];
        end
        function app = smoothingDegreeChanged(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.smoothingDegree = event.Value;
            app.GolayPanel(CurrentTab).FrameSize.Limits = [event.Value + 1,Inf];
        end
        %% MSAlign
        function app = massAlignmentQuantilChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.massAlignmentQuantil = event.Value;
        end
        function app = massAlignmentEstimMethodChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.massAlignmentEstimMethod = event.Value;
        end
        function app = massAlignmentCorrectionMethodChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.massAlignmentCorrectionMethod = event.Value;
        end
        %% PeakAlign
        function app = peakAlignmentMaxShiftPositiveChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.peakAlignmentMaxShiftPositive = event.Value;
        end
        function app = peakAlignmentMaxShiftNegativeChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.peakAlignmentMaxShiftNegative = event.Value*-1;
        end
        function app = peakAlignmentPulseWidthChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.peakAlignmentPulseWidth = event.Value;
        end
        function app = peakAlignmentWindowSizeRatioChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.peakAlignmentWindowSizeRatio = event.Value;
        end
        function app = peakAlignmentSearchSpaceChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.peakAlignmentSearchSpace = event.Value;
        end
        function app = peakAlignmentIterationChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.peakAlignmentIteration = event.Value;
        end
        function app = peakAlignmentGridStepsChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.peakAlignmentGridSteps = event.Value;
        end
        %% BaseCorr
        function app = baselineWindowSizeChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.baselineWindowSize = event.Value;
        end
        function app = baselineStepSizeChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.baselineStepSize = event.Value;
        end
        function app = baselineRegressionMethodChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.baselineRegressionMethod = event.Value;
        end
        function app = baselineEstimationMethodChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.baselineEstimationMethod = event.Value;
        end
        function app = baselineSmoothMethodChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.baselineSmoothMethod = event.Value;
        end
        function app = baselineQuantilChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.baselineQuantil = event.Value;
        end
        %% ISTD
        function app = constructISFigure(app,~,~,CurrentTab)
            app.ISPanel(CurrentTab).window = uifigure("WindowStyle","normal",...
                "Name","Define ISTDs",...
                "Position",[100,100,640,480],...
                "NumberTitle","off");

            app.ISPanel(CurrentTab).InputTable = uitable(app.ISPanel(CurrentTab).window,...
                "ColumnEditable",[true, true, true],...
                ColumnName=["Exact mass [Da]","Expected RT [sec]","Accepted Drift [sec]"],...
                ColumnWidth="auto",...
                ColumnFormat={'numeric','numeric','numeric'},...
                Position=[20,22,600,380]);

            app.ISPanel(CurrentTab).Labels(4) = uilabel(app.ISPanel(CurrentTab).window,...
                "Text","Define Internal Standards",...
                FontSize=24,...
                FontWeight="bold",...
                Position=[20,420,300,50]);

            if isempty(app.ISPanel(CurrentTab).oldDat) == false
                rowsOld = height(app.ISPanel(CurrentTab).oldDat);
                if rowsOld < app.ISPanel(CurrentTab).NumberSelect.Value %is added -> append new rows
                    newVals = app.ISPanel(CurrentTab).oldDat;
                    newVals(app.ISPanel(CurrentTab).NumberSelect.Value,3) = 0;
                else
                    newVals = app.ISPanel(CurrentTab).oldDat(1:app.ISPanel(CurrentTab).NumberSelect.Value,:);
                end
                app.ISPanel(CurrentTab).InputTable.Data = newVals;
            else
                dat = zeros(app.ISPanel(CurrentTab).NumberSelect.Value,3,'double');
                app.ISPanel(CurrentTab).InputTable.Data = dat;
            end
            app.ISPanel(CurrentTab).OKButton = uibutton(app.ISPanel(CurrentTab).window,"push",...
                "Position",[475,420,155,40],...
                Text="OK");
            app.ISPanel(CurrentTab).OKButton.ButtonPushedFcn = @(src,event){outputIS(app,src,event,CurrentTab)};
        end

        function app = outputIS(app,~,~,CurrentTab)
            ISData = app.ISPanel(CurrentTab).InputTable.Data;
            %%Check Correct Inputs
            %Check missing MZ
            if any(ISData(:,1)==0) == true || any(isnan(ISData(:,1))) == true
                uialert(app.ISPanel(CurrentTab).window,'Missing Internal Standard M/Z','Invalid Input');
                %Check missing Expected RT
            elseif any(ISData(:,2)==0) == true || any(isnan(ISData(:,2))) == true
                uialert(app.ISPanel(CurrentTab).window,'Missing Expected Retention Time','Invalid Input');
                %Check missing Accepted Drift
            elseif any(ISData(:,3)==0) == true || any(isnan(ISData(:,3))) == true
                uialert(app.ISPanel(CurrentTab).window,'Missing Accepted Drift','Invalid Input');
            else
                app = isPanelCloseRequest(app,CurrentTab,ISData);
            end
        end

        function app = isPanelCloseRequest(app,CurrentTab,ISData)
            app.MassData{CurrentTab}.internalStandardData = ISData;
            app.ISPanel(CurrentTab).oldDat = ISData;
            app.ISPanel(CurrentTab).StatusLamp.Color = [0.39,0.83,0.07];
            app.MassData{CurrentTab}.nInternalStandard = app.ISPanel(CurrentTab).NumberSelect.Value;
            delete(app.ISPanel(CurrentTab).window)
            CheckReady(app)
        end

        function app = isLampAction(app,~,event,CurrentTab)
            oldVal = app.MassData{CurrentTab}.internalStandardData;
            if event.Value == oldVal
                app.ISPanel(CurrentTab).StatusLamp.Color = [0.39,0.83,0.07];
                CheckReady(app)
            else
                app.ISPanel(CurrentTab).StatusLamp.Color = [1.0,0.0,0.0];
                app.ProcessFilesButton.Enable = "off";
            end
        end

        function app = useISMassCorrectionChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.useISMassCorrection = event.Value;
        end
        function app = applyIStoChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.applyISto = event.Value;
        end
        function app = internalStandardOrderChange(app,~,event,CurrentTab)
            app.MassData{CurrentTab}.internalStandardOrder = event.Value;
        end
        %% Scaling
        function app = constructScalingFigure(app,~,~,CurrentTab)
            % check if Files are selected
            if ~isempty(app.MassData{CurrentTab}.fileName)
                app.ScalingPanel(CurrentTab).window = uifigure("WindowStyle","normal",...
                    "Name","Define Scaling Factors",...
                    "Position",[100,100,640,480],...
                    "NumberTitle","off");

                app.ScalingPanel(CurrentTab).InputTable = uitable(app.ScalingPanel(CurrentTab).window,...
                    "ColumnEditable",[false, true],...
                    ColumnName=["Sample File","Scaing Factor"],...
                    ColumnWidth="auto",...
                    ColumnFormat={'char','numeric'},...
                    Position=[20,20,600,360]);

                app.ScalingPanel(CurrentTab).Labels(1) = uilabel(app.ScalingPanel(CurrentTab).window,...
                    "Text","Define Scaling Factors",...
                    FontSize=24,...
                    FontWeight="bold",...
                    Position=[20,420,300,50]);

                app.ScalingPanel(CurrentTab).GroupValField = uieditfield(app.ScalingPanel(CurrentTab).window,"numeric",...
                    "Position",[150,400,90,20],...
                    "Limits",[0,Inf], ...
                    "ValueDisplayFormat","%11.4g",...
                    "Value",app.MassData{CurrentTab}.groupScale, ...
                    "RoundFractionalValues","off");

                app.ScalingPanel(CurrentTab).Labels(2) = uilabel(app.ScalingPanel(CurrentTab).window,...
                    "Text","Group wide Factor",...
                    FontSize=12,...
                    Position=[20,400,110,20]);

                app.ScalingPanel(CurrentTab).InputTable.Data = [app.MassData{CurrentTab}.fileName,app.MassData{CurrentTab}.sampleScale];

                app.ScalingPanel(CurrentTab).OKButton = uibutton(app.ScalingPanel(CurrentTab).window,"push",...
                    "Position",[475,420,155,40],...
                    Text="OK");

                % add Callbacks
                app.ScalingPanel(CurrentTab).OKButton.ButtonPushedFcn = @(src,event){outputScaling(app,src,event,CurrentTab)};
                app.ScalingPanel(CurrentTab).GroupValField.ValueChangedFcn = @(src,event) {groupScaleChange(app,src,event,CurrentTab)};
                % Show Window after construction
                app.ScalingPanel(CurrentTab).window.Visible = true;
            else
                uialert(app.MainWindow,"Select Files to Continue","No Files Selected","Icon","error")
                return
            end
        end

        function app = outputScaling(app,~,~,CurrentTab)
            scalingData = app.ScalingPanel(CurrentTab).InputTable.Data(:,2);
            scalingData = str2double(scalingData);
            %Check Correct Inputs
            if all(scalingData > 0)
                app = scalingCloseRequest(app,CurrentTab,scalingData);
            else
                uialert(app.ScalingPanel(CurrentTab).window,'Scaling factors must not be negative or 0','Invalid Input');
            end
        end
        function app = scalingCloseRequest(app,CurrentTab,scalingData)
            app.MassData{CurrentTab}.sampleScale = scalingData;
            app.ScalingPanel(CurrentTab).ScalingLamp.Color = [0.39,0.83,0.07];
            delete(app.ScalingPanel(CurrentTab).window)
            CheckReady(app)
        end
        function app = groupScaleChange(app,~,event,CurrentTab)
            %   Detailed explanation goes here
            app.MassData{CurrentTab}.groupScale = event.Value;
        end
        %% AddNLoss
        function app = constructAddNLossFigure(app,~,~,CurrentTab)

            polarity = app.MassData{CurrentTab}.scanPolarity;
            
            switch polarity
                case "positive"
                    app.AddNLossPanel(CurrentTab).window = PosAdducts(app.MassData{CurrentTab});
                case "negative"
                    app.AddNLossPanel(CurrentTab).window = NegAdducts(app.MassData{CurrentTab});
                case "both"
                    app.AddNLossPanel(CurrentTab).window = AllAdducts(app.MassData{CurrentTab});
            end
            app.AddNLossPanel(CurrentTab).window.AcceptButton.ButtonPushedFcn = @(src,event){outputAddDat(app,src,event,CurrentTab,polarity)};
            app.AddNLossPanel(CurrentTab).window.UIFigure.Visible = true;
        end

        function app = outputAddDat(app,~,~,CurrentTab,polarity)
            %gather Cosine Similarity
            app.MassData{CurrentTab}.minCosineSimilarity = app.AddNLossPanel(CurrentTab).window.CosSimField.Value/100;
            %gather logical array
            singleCharged = [app.AddNLossPanel(CurrentTab).window.SingleArray(:).Value];
            doubleCharged = [app.AddNLossPanel(CurrentTab).window.DoubleArray(:).Value];
            tripleCharged = [app.AddNLossPanel(CurrentTab).window.TripleArray(:).Value];
            dimer = [app.AddNLossPanel(CurrentTab).window.DimerArray(:).Value];
            switch polarity
                case "positive"
                    app.MassData{CurrentTab}.adductSelectedPos = [singleCharged';dimer';doubleCharged';tripleCharged'];
                case "negative"
                    app.MassData{CurrentTab}.adductSelectedNeg = [singleCharged';dimer';doubleCharged';tripleCharged'];
                case "both"
                    app.MassData{CurrentTab}.adductSelectedPos = [singleCharged(1:12)';dimer(1:6)';doubleCharged(1:8)';tripleCharged(1:4)'];
                    app.MassData{CurrentTab}.adductSelectedNeg = [singleCharged(13:end)';dimer(7:end)';doubleCharged(9);tripleCharged(5)];
            end
            app.MassData{CurrentTab}.neutralSelectedSmol = [app.AddNLossPanel(CurrentTab).window.SmolArray(:).Value]';
            app.MassData{CurrentTab}.neutralSelectedCon = [app.AddNLossPanel(CurrentTab).window.ConjugatesArray(:).Value]';
            app.AddNLossPanel(CurrentTab).AddNLossLamp.Color = [0.39,0.83,0.07];
            delete(app.AddNLossPanel(CurrentTab).window.UIFigure);
        end

        %% Augmentation And Reference
        function constructAugmentationFigure(app)
            % check if Files are selected
            app.AugmentOptions.window = uifigure("WindowStyle","normal",...
                "Name","Augmentation and Referencing",...
                "Position",[100,100,700,480],...
                "NumberTitle","off");

            % build input Table
            colName = {'Groups to Combine:';'Reference Group:'};
            nGroup = width(app.MassData);
            rowName = strings(1,nGroup);
            for iGroup = 1:nGroup
                rowName(iGroup) = app.MassData{iGroup}.GroupName;
            end
            
            %check if AsignmentArray already exists
            if size(app.augmentationAssignment,1) ~= size(app.MassData,2)
                catsB = "none"; % Categories for Reference Column
                catsB = repmat(catsB,[nGroup,1]);
                %generate Assignment String
                allCats = [string(mat2cell('A':'Z',1,ones(1,26))),string(mat2cell('a':'z',1,ones(1,26)))];
                allCats(nGroup+1:end) = []; % remove Rows > number of Groups
                catsB = categorical(catsB,["none",allCats]);
                % Categories for Combine Column
                allCats = categorical(allCats,allCats);
                rowName(nGroup+1:end) = [];
                tableData = table(allCats',catsB,'VariableNames',colName,'RowNames',rowName);
            else
                tableData = table(removecats(app.augmentationAssignment(:,1)),app.augmentationAssignment(:,2),'VariableNames',colName,'RowNames',rowName);
            end
            toolTip = {'Define the relationship of the groups for data processing. Groups with the same label are processed together. Combined groups are treated as one group and compared with the others. By specifying a reference group, groups with the same label are normalized to the reference group.'};
            app.AugmentOptions.InputTable = uitable(app.AugmentOptions.window,'Data',tableData, ...
                "Position",[5,5,690,400], ...
                "ColumnEditable",true, ...
                "SelectionType","cell", ...
                "Tooltip",toolTip);

            app.AugmentOptions.Labels(1) = uilabel(app.AugmentOptions.window,...
                "Text",["Define Group Augmentation";"and Reference Groups"],...
                FontSize=18,...
                FontWeight="bold",...
                Position=[20,420,620,50]);

            app.AugmentOptions.OKButton = uibutton(app.AugmentOptions.window,"push",...
                "Position",[535,420,155,40],...
                Text="OK");

            app.AugmentOptions.mzerrorField = uieditfield(app.AugmentOptions.window,"numeric", ...
                "Position",[435,440,40,20], ...
                "Limits",[0,Inf], ...
                "LowerLimitInclusive","off",...
                "Value",app.mergeMassTolerance,...
                Tooltip="Lowest mass tolerance to identify features between different groups as the same feature.");

            app.AugmentOptions.Labels(2) = uilabel(app.AugmentOptions.window,"Text","Merging Mass tolerance", ...
                "Position",[280,440,150,20], ...
                HorizontalAlignment="right");

            app.AugmentOptions.mzerrorUnitDrop = uidropdown(app.AugmentOptions.window, ...
                "Items",["ppm","Da"],...
                ItemsData=["ppm","Da"], ...
                Value=app.mergeMassUnit,...
                Position=[475,440,50,20],...
                Tooltip="Specify whether a fixed mz error in Da or a relative error in ppm is used.");

            app.AugmentOptions.RTTolField = uieditfield(app.AugmentOptions.window,"numeric", ...
                "Position",[435,415,90,20], ...
                "Limits",[0,Inf], ...
                "ValueDisplayFormat","%.1f s",...
                "Value",app.mergeTimeTolerance, ...
                "RoundFractionalValues","off",...
                ToolTip="Retention time tolerance whether peaks from different groups belong to the same feature.");

            app.AugmentOptions.Labels(3) = uilabel(app.AugmentOptions.window,"Text","Merging Time Tolerance", ...
                "Position",[280,415,150,20], ...
                HorizontalAlignment="right");
            % Callbacks
            app.AugmentOptions.OKButton.ButtonPushedFcn = @(src,event){outputAugmentationData(app,src,event)};

        end
        function app = outputAugmentationData(app,~,~)
            augmentationData = app.AugmentOptions.InputTable.Data;
            app.augmentationAssignment = augmentationData{:,:};
            app.mergeTimeTolerance = app.AugmentOptions.RTTolField.Value;
            app.mergeMassTolerance = app.AugmentOptions.mzerrorField.Value;
            app.mergeMassUnit = app.AugmentOptions.mzerrorUnitDrop.Value;
            app = augmentationWindowCloseRequest(app);
        end
        function app = augmentationWindowCloseRequest(app)
            close(app.AugmentOptions.window)
            CheckReady(app)
        end
        %% DataBaseCallbacks
        function app = addDataBaseWindow(app)
            app.DataBase = MSDataBase(app);
            app.DataBase.SelectDBFile.ButtonPushedFcn = @(src,event) {selectDatabaseCall(app,src,event)};
            app.DataBase.GenerateDB.ButtonPushedFcn = @(src,event) {generateDatabaseCall(app,src,event)};
            app.DataBase.MS1SearchButton.ButtonPushedFcn = @(src,event) {queryMS1Call(app,src,event)};
            app.DataBase.MS2SearchButton.ButtonPushedFcn = @(src,event) {queryMS2Call(app,src,event)};
            app.DataBase.Window.CloseRequestFcn = @(src,event) {closeDataBaseSearcher(app,src,event)};
            %disable main window
            app.DataProcessingPanel.Enable = "off";
            app.FeatureScalingPanel.Enable = "off";
            app.GeneralOptionsPanel.Enable = "off";
            app.EvaluationObject.Container.Enable = "off";
            app.SwitchView.Enable = "off";
        end
        function app = selectDatabaseCall(app,~,~)
            app.DataBase = app.DataBase.selectDataBaseFile;
        end
        function app = generateDatabaseCall(app,~,~)
            app.DataBase = app.DataBase.generateDatabase;
        end

        function app = closeDataBaseSearcher(app,~,~)
            app.dataBaseFile  = app.DataBase.dataBaseFile;
            %re-enable mainWindow
            app.DataProcessingPanel.Enable = "on";
            app.FeatureScalingPanel.Enable = "on";
            app.GeneralOptionsPanel.Enable = "on";
            app.EvaluationObject.Container.Enable = "on";
            app.SwitchView.Enable = "on";
            delete(app.DataBase.Window);
        end

        function app = queryMS1Call(app,~,~)
            d = uiprogressdlg(app.DataBase.Window,'Title','Searching Database', ...
                'Message','Please Wait', ...
                'Indeterminate','on');
            app.FeatureData.dataBaseResultsMS1 = app.DataBase.dataBaseMS1Query;
            app.FeatureData = app.FeatureData.BuildIdentificaltionLevelStrings;
            %add results to Result table
            if ~isempty(app.ReportTable)
                if isvalid(app.ReportTable)
                    close(app.ReportTable.Parent)
                end
            end
            app.generateReport;
            close(d)
            uialert(app.DataBase.Window,'Search Complete','Success','Icon','success');
        end
        function app = queryMS2Call(app,~,~)

            % search Database
            if isempty(app.FeatureData.MSnSpectra)
                %update DataBase object
                app.DataBase.Features = app.FeatureData.IdentifierArray;
                if size(app.FeatureData.GroupName,2) < 2
                    app.DataBase.Significant = true(size(app.FeatureData.AverageIntensities));
                    app.DataBase.HighFoldChange = true(size(app.FeatureData.AverageIntensities));
                else
                    IsSignificant = horzcat(app.FeatureData.SignificantFeature{:});
                    IsSignificant = any(IsSignificant <= app.maxP,2);
                    app.DataBase.Significant = IsSignificant;
                    Fold = max(horzcat(app.FeatureData.FullFoldChanges{:}),[],2);
                    app.DataBase.HighFoldChange = Fold >= app.minFold;
                end
                app.DataBase.FoundInGroup = app.FeatureData.FeatureData.InGroup;
            end
            d = uiprogressdlg(app.DataBase.Window,'Title','Searching Database','Message','Please Wait','Indeterminate','on');
            [app.FeatureData.dataBaseResultsMS2,app.FeatureData.DataBaseSpectra,app.FeatureData.DBScoreSpectraID] = app.DataBase.DataBaseMS2Query(app.FeatureData.FeatureMSnSpectra);
            app.FeatureData = app.FeatureData.BuildIdentificaltionLevelStrings;
            % add results to Result table
            if isvalid(app.ReportTable)
                close(app.ReportTable.Parent)
            end
            app.generateReport;
            close(d)
            uialert(app.DataBase.Window,'Search Complete','Success','Icon','success');
        end
        %% Evaluation Callbacks
        % function app = PlotTICs(app,~,~)
        %     d = uiprogressdlg(app.MainWindow,'Title','Please Wait','Message','Please Wait','Indeterminate','on');
        %     delete(app.EvaluationPanel.PlotSpace.Children)
        %     app.EvaluationPanel.PlotSpace.Title.String = " ";
        %     app.EvaluationPanel.PlotSpace.XLabel.String = " ";
        %     app.EvaluationPanel.PlotSpace.YLabel.String = " ";
        %     % plot all TICs for every Group
        %     for g=1:numel(app.MassData)
        %         FileNames = app.MassData{g}.FileNames;
        %         GroupNames = app.MassData{g}.GroupName;
        %         data = app.MassData{g}.RawDataFileObj.previewTICs;
        %         times = app.MassData{g}.RawDataFileObj.previewTimes;
        %         numData = size(data,1);
        %         app.EvaluationPanel.PlotTiles(g) = nexttile(app.EvaluationPanel.PlotSpace);
        %         for n = 1:numData
        %             TIC=data{n,1};
        %             plot(app.EvaluationPanel.PlotTiles(g),times{n},TIC)
        %             colororder(app.EvaluationPanel.PlotTiles(g),"gem12")
        %             app.EvaluationPanel.PlotTiles(g).NextPlot = 'add';
        %         end
        %         title(app.EvaluationPanel.PlotTiles(g),"Sample TICs for " + GroupNames)
        %         legend(app.EvaluationPanel.PlotTiles(g),FileNames,Interpreter='none')
        %         drawnow limitrate
        %     end
        %     xlabel(app.EvaluationPanel.PlotSpace,"RT [sec]")
        %     ylabel(app.EvaluationPanel.PlotSpace,"Intensity [arb]")
        %     close(d)
        % end

        function app = PlotROIs(app,~,~)
            d = uiprogressdlg(app.MainWindow,'Title','Please Wait','Message','Please Wait','Indeterminate','on');
            delete(app.EvaluationObject.PlotSpace.Children)
            app.EvaluationObject.PlotSpace.Title.String = " ";
            app.EvaluationObject.PlotSpace.XLabel.String = " ";
            app.EvaluationObject.PlotSpace.YLabel.String = " ";
            % plot Found ROI Profiles for every Group
            for g=1:size(app.MassData,2)
                data = app.MassData{g}.ROIDataFileObj.ROIMat;
                time = app.MassData{g}.ROIDataFileObj.timeVec;
                mzroi = app.MassData{g}.ROIDataFileObj.ROImzVec;
                %set first and last row to 0, to prevent plot artefacts
                data = mat2cell(data,app.MassData{g}.nScansPadded,size(data,2));
                time = mat2cell(time,app.MassData{g}.nScansPadded,size(time,2));
                for j=1:size(data,1)
                    idx = time{j,1} == 0;
                    time{j,1}(idx) = [];
                    data{j,1}(idx,:) = [];
                    data{j,1}(1,:) = 0;
                    data{j,1}(end,:) = 0;
                end
                data = vertcat(data{:});
                time = vertcat(time{:});
                app.EvaluationObject.PlotTiles(g) = nexttile(app.EvaluationObject.PlotSpace);
                plot(app.EvaluationObject.PlotTiles(g),time,data)
                colororder(app.EvaluationObject.PlotTiles(g),"gem12")
                mzroi=flip(mzroi);
                for n=size(app.EvaluationObject.PlotTiles(g).Children,1):-1:1
                    app.EvaluationObject.PlotTiles(g).Children(n).DataTipTemplate.DataTipRows(1).Label="RT:";
                    app.EvaluationObject.PlotTiles(g).Children(n).DataTipTemplate.DataTipRows(2).Label="Intensity:";
                    app.EvaluationObject.PlotTiles(g).Children(n).DataTipTemplate.DataTipRows(3) = dataTipTextRow("ROI m/z:",repmat(mzroi(n),size(time,1),1),'%.6g');
                end
                title(app.EvaluationObject.PlotTiles(g),"ROIs of " + app.MassData{g}.GroupName);
            end
            xlabel(app.EvaluationObject.PlotSpace,"RT [sec]")
            ylabel(app.EvaluationObject.PlotSpace,"Intensity [arb]")
            drawnow
            close(d)
        end

        function app = PlotIntResults(app,~,~)
            delete(app.EvaluationObject.PlotSpace.Children)
            app.EvaluationObject.PlotSpace.Title.String = " ";
            app.EvaluationObject.PlotSpace.XLabel.String = " ";
            app.EvaluationObject.PlotSpace.YLabel.String = " ";
            % ask for specific features
            pCrit = app.maxP;
            [masses,SpecificIndex] = IntResultsSelectionGUI(length(app.FeatureData.NameStringArray));
            if isempty(masses)
                return
            end
            SelectedFeat = CompileSelection(app.FeatureData,masses,SpecificIndex,pCrit);

            %check resulting number of plots; throw warning if a LOT
            numPlots = sum(SelectedFeat);
            if numPlots > 100
                selection = uiconfirm(app.MainWindow,"A total of "+numPlots+" plots will be generated.",'Confirm Continue',...
                    'Options',{'Continue','Reselect Features','Cancel'}, ...
                    'DefaultOption',2,'CancelOption',3,'Icon','warning');
                switch selection
                    case 'Reselect Features'
                        [masses,SpecificIndex] = IntResultsSelectionGUI(length(app.FeatureData.NameStringArray));
                        if isempty(masses)
                            return
                        end
                        SelectedFeat = CompileSelection(app.FeatureData,masses,SpecificIndex,pCrit);
                    case 'Cancel'
                        return
                end
            end
            FeatNumber = 1:1:length(app.FeatureData.NameStringArray);
            FeatNumber(~SelectedFeat) = [];
            FeatName = app.FeatureData.NameStringArray(SelectedFeat) + ", "+app.FeatureData.InGroup(SelectedFeat);
            GroupNames = [app.FeatureData.GroupName]; %get group labels, when augmented groups replace combined name with abstract name
            indices = contains(GroupNames,"+");
            GroupNames(indices) = app.FeatureData.AbstractGroupName(indices);
            XIC = app.FeatureData.XIC(SelectedFeat,:);

            %build indices to original Group and gather time data
            OriginalGroup = app.FeatureData.OriginalGroup;
            GroupNamesNonAbstract=[app.FeatureData.GroupName]; %get group labels
            timeData = cell(size(GroupNamesNonAbstract));
            OGnames = strings(size(app.MassData));
            for n = 1:width(app.MassData)
                OGnames(1,n) = app.MassData{n}.GroupName;
            end
            for z=1:size(OriginalGroup,2)
                SplitName = strsplit(GroupNamesNonAbstract(z),"+");
                index = contains(OGnames,SplitName);
                timeData{z} = {app.MassData{index}.ROIDataFileObj.timeVec};
                OriginalGroup(OriginalGroup(:,z)~="",z)=z;
            end
            OriginalGroup = str2double(OriginalGroup);
            OriginalGroup = OriginalGroup(SelectedFeat,:);
            for q=1:numel(FeatNumber)
                ID = OriginalGroup(q,:);
                ID(isnan(ID)) = [];
                figure('Name',"Feature No. " +FeatNumber(q),'NumberTitle','off')
                hold on
                textstore = cell(1,numel(app.FeatureData.NumberOfFilesArray));
                currentXICs = XIC(q,:);
                indices = ~cellfun(@isempty,currentXICs);
                for g=ID
                    text = 1:1:size(currentXICs,2);
                    text(:,any(indices==0,1))=[];
                    text = GroupNames(g) + " Repetition " + text;
                    textstore{g} = text;
                    if isempty(indices)
                        continue
                    end
                    for w = 1:size(currentXICs,2)
                        if indices(w) == 1
                            area(currentXICs{1,w}(:,2),currentXICs{1,w}(:,1),'FaceAlpha',0.25)
                        end
                    end
                end
                text = horzcat(textstore{:});
                xlabel("RT [sec]")
                ylabel("Intensity [arb]")
                title(FeatName(q,1))
                legend(text)
                drawnow
            end
            function [check,indices] = IntResultsSelectionGUI(numFeat)
                % Create figure
                h.f = uifigure("Name","Select Features to plot", ...
                    'units','pixels', ...
                    'position',[400,400,300,150],...
                    'toolbar','none', ...
                    'menu','none');
                h.checked = [];
                % Create check boxes
                %only unique
                h.c(1) = uicheckbox(h.f, 'position',[25,55,130,20],Text='Unique Features');
                %only non unique
                h.c(2) = uicheckbox(h.f, 'position',[155,55,130,20],Text='non Uniqe Features');
                %only significant
                h.c(3) = uicheckbox(h.f, 'position',[25,80,130,20],Text='Significant Features');
                %specific Feature
                h.c(4) = uieditfield(h.f,"text",...
                    Position=[170,110,80,20],...
                    Value="1-"+numFeat, ...
                    Tooltip="Specify which Features to plot. Individual features can be separated with ,. To select a contiguous number use -. For example, '2,10-14, 20' will plot features 2,10,11,12,13,14 and 20.");
                h.l =  uilabel(h.f,"Text","Select Specific Features:",...
                    "Position",[25,110,155,20],...
                    "HorizontalAlignment","left");
                % Create OK push button
                h.p = uibutton(h.f,"Text","Plot Features", ...
                    "Position",[100,5,100,40],...
                    FontWeight="bold");
                h.p.ButtonPushedFcn = @p_call;
                uiwait(h.f);
                delete(h.f);
                if isempty(h.checked)
                    check = [];
                    indices = [];
                    return
                end
                check  = h.checked;
                indices = h.indices;
                % Push button callback
                function p_call(varargin)
                    vals = get(h.c,'Value');
                    % Split the string using ','
                    segments = strsplit(vals{4}, {',',' '});
                    vals(4) = [];
                    % Initialize an empty vector
                    output = [];
                    % Process each segment
                    for i = 1:length(segments)
                        segment = strtrim(segments{i}); % Remove leading/trailing spaces

                        % Check if the segment contains a '-'
                        if contains(segment, '-')
                            % Split the segment using '-'
                            range = strsplit(segment, '-');
                            % Extract the start and end values
                            start_val = str2double(range{1});
                            end_val = str2double(range{2});
                            % Generate the range vector and append it to the output
                            range_vector = (start_val:end_val)';
                            output = [output; range_vector];
                        else
                            % Single number segment, convert to double and append to the output
                            number = str2double(segment);
                            output = [output; number];
                        end
                    end
                    checked = vertcat(vals{:});
                    h.indices = output;
                    h.checked = checked;
                    close(h.f)
                end
            end

            function SelectedFeat = CompileSelection(FeatureData,check,SpecificIndex,pCrit)
                SelectedFeat = true(size(FeatureData.UniqueFeature,1),3);
                %only non Unique
                if check(1) == true
                    SelectedFeat(:,1) = any(FeatureData.UniqueFeature,2);
                end
                %only Unique
                if check(2) == true
                    SelectedFeat(:,2) = ~any(FeatureData.UniqueFeature,2);
                end
                %only significant
                if check(3) == true
                    SelectedFeat(:,3) = ~any(horzcat(FeatureData.SignificantFeature{:}) <= pCrit,2);
                end
                SelectedFeat = ~any(SelectedFeat==0,2);
                Specific = false(size(SelectedFeat));
                Specific(SpecificIndex) = true;
                SelectedFeat = SelectedFeat == true & Specific == true;
            end
        end

        function [masses,tolerance,tolUnit] = EICSelectionGUI(app,CurrentTab)
            % Create figure
            h.f = uifigure("Name","Select EICs to plot", ...
                'units','pixels', ...
                'position',[400,400,300,150],...
                'toolbar','none', ...
                'menu','none');

            %specify masses
            h.mField = uieditfield(h.f,"text",...
                Position=[100,110,180,20],...
                Value="194.08",...
                Tooltip="Specify which M to plot. Individual masses can be separated with , or ;");
            h.label(1) =  uilabel(h.f,"Text","Specifiy mass :",...
                "Position",[10,110,100,20],...
                "HorizontalAlignment","left");
            % tolerance and unit
            h.tolField = uieditfield(h.f,"numeric", ...
                "Position",[145,80,40,20], ...
                "Limits",[0,Inf], ...
                "LowerLimitInclusive","off",...
                "Value",app.MassData{CurrentTab}.mzerror,...
                Tooltip="mass tolerance for EIC generation.");
            h.tolDrop = uidropdown(h.f, ...
                "Items",["ppm","Da"],...
                ItemsData=["ppm","Da"], ...
                Value=app.MassData{CurrentTab}.mzErrorUnit,...
                Position=[185,80,60,20],...
                Tooltip="Specify whether a fixed mz error in Da or a relative error in ppm is used.");
            h.label(2) =  uilabel(h.f,"Text","Specifiy mass tolerance:",...
                "Position",[10,80,135,20],...
                "HorizontalAlignment","left");

            % Create OK push button
            h.p = uibutton(h.f,"Text","Plot EICs", ...
                "Position",[100,5,100,40],...
                FontWeight="bold");
            h.masses = [];
            h.tolerance = [];
            h.tolUnit = "";
            h.p.ButtonPushedFcn = @p_exit;
            uiwait(h.f);
            masses  = h.masses;
            tolerance = h.tolerance;
            tolUnit = h.tolUnit;
            delete(h.f);
            % Push button callback
            function p_exit(varargin)
                vals = get(h.mField,'Value');
                % Split the string using ','
                segments = strsplit(vals, {',',', ',' ,',';','; ',' ;',' '});
                % Process each segment
                segments = strtrim(segments); % Remove leading/trailing spaces, convert to double
                output = str2double(segments);
                h.masses = output;
                h.tolerance = h.tolField.Value;
                h.tolUnit = h.tolDrop.Value;
                close(h.f)
            end
        end

        function app = PlotEntropy(app,~,~)
            delete(app.EvaluationObject.PlotSpace.Children)
            app.EvaluationObject.PlotSpace.Title.String = " ";
            app.EvaluationObject.PlotSpace.XLabel.String = " ";
            app.EvaluationObject.PlotSpace.YLabel.String = " ";
            preMedianEntropy = zeros(size(app.MassData));
            GroupNames = strings(size(app.MassData));
            for n = 1:width(app.MassData)
                preMedianEntropy(n) = app.MassData{n}.MedianEntropy;
                GroupNames(n) = app.MassData{n}.GroupName;
            end
            GroupEntropy = mat2cell(app.FeatureData.EntropyArray,size(app.FeatureData.EntropyArray,1),app.FeatureData.NumberOfFilesArray);
            for g=1:size(GroupEntropy,2)
                allEntropy = GroupEntropy{1,g};
                postMedianEntropy = median(allEntropy,"all");
                app.EvaluationObject.PlotTiles(g) = nexttile(app.EvaluationObject.PlotSpace);
                histogram(app.EvaluationObject.PlotTiles(g),allEntropy,BinWidth=0.025)
                title(app.EvaluationObject.PlotTiles(g),"Entropy Distribution for " + GroupNames(g))
                xL=xlim(app.EvaluationObject.PlotTiles(g));
                yL=ylim(app.EvaluationObject.PlotTiles(g));
                text(app.EvaluationObject.PlotTiles(g),0.95*xL(2),0.95*yL(2),["Pre Filter Median Entropy: "+ preMedianEntropy(g);"Post Filter Median Entropy: "+ postMedianEntropy],'HorizontalAlignment','right','VerticalAlignment','top','FontSize',8);
            end
            xlabel(app.EvaluationObject.PlotSpace,"Peak Entropy")
            ylabel(app.EvaluationObject.PlotSpace,"Counts per bin")
        end

        function app = PlotSN(app,~,~)
            delete(app.EvaluationObject.PlotSpace.Children)
            app.EvaluationObject.PlotSpace.Title.String = " ";
            app.EvaluationObject.PlotSpace.XLabel.String = " ";
            app.EvaluationObject.PlotSpace.YLabel.String = " ";
            GroupNames = strings(size(app.MassData));
            for n = 1:width(app.MassData)
                GroupNames(n) = app.MassData{n}.GroupName;
            end
            GroupSN = mat2cell(app.FeatureData.Signal2NoiseArray,size(app.FeatureData.Signal2NoiseArray,1),app.FeatureData.NumberOfFilesArray);
            for g=1:size(GroupNames,2)
                allSN=GroupSN{1,g};
                allSN(allSN==0)=NaN;
                app.EvaluationObject.PlotTiles(g) = nexttile(app.EvaluationObject.PlotSpace);
                histogram(app.EvaluationObject.PlotTiles(g),allSN,BinWidth=5)
                title(app.EvaluationObject.PlotTiles(g),"S/N Distribution for " + GroupNames(g))
            end
            xlabel(app.EvaluationObject.PlotSpace,"Signal to Noise")
            ylabel(app.EvaluationObject.PlotSpace,"Counts per bin")
        end

        function app = PlotRejects(app,~,~)
            delete(app.EvaluationObject.PlotSpace.Children)
            app.EvaluationObject.PlotSpace.Title.String = " ";
            app.EvaluationObject.PlotSpace.XLabel.String = " ";
            app.EvaluationObject.PlotSpace.YLabel.String = " ";
            GroupNames = strings(size(app.MassData));
            RejectedFeat = zeros(6,width(app.MassData));
            for n = 1:width(app.MassData)
                GroupNames(1,n) = app.MassData{n}.GroupName;
                RejectedFeat(1,n) = app.MassData{n}.MinWidthFiltered;
                RejectedFeat(2,n) = app.MassData{n}.MaxWidthFiltered;
                RejectedFeat(3,n) = app.MassData{n}.SNFiltered;
                RejectedFeat(4,n) = app.MassData{n}.EntropyFiltered;
                RejectedFeat(5,n) = app.MassData{n}.OccurenceFiltered;
                RejectedFeat(6,n) = app.MassData{n}.AdductFiltered;
            end
            app.EvaluationObject.PlotTiles(1) = nexttile(app.EvaluationObject.PlotSpace,app.EvaluationObject.PlotSpace.GridSize);
            cause = categorical(["Min.Peak Width"; "Max.Peak Width"; "Min.Signal to Noise"; "Entropy";"Occurence";"Filtered Adducts"]);
            cause = reordercats(cause,{'Min.Peak Width','Max.Peak Width','Min.Signal to Noise','Entropy','Occurence','Filtered Adducts'});
            bar(app.EvaluationObject.PlotTiles(1),cause,RejectedFeat);
            xtickangle(app.EvaluationObject.PlotTiles(1),0);
            ylim(app.EvaluationObject.PlotTiles(1),[0,max(RejectedFeat,[],'all')+max(RejectedFeat,[],'all')*0.1])
            title(app.EvaluationObject.PlotTiles(1),"Number of rejected Peaks by filter")
            ylabel(app.EvaluationObject.PlotTiles(1),"Number of Peaks Rejected")
            legend(app.EvaluationObject.PlotTiles(1),GroupNames)
            xtips1 = [app.EvaluationObject.PlotTiles(1).Children.XEndPoints];
            ytips1 = [app.EvaluationObject.PlotTiles(1).Children.YEndPoints];
            labels1 = string([app.EvaluationObject.PlotTiles(1).Children.YData]);
            text(app.EvaluationObject.PlotTiles(1),xtips1,ytips1,labels1,'HorizontalAlignment','center','VerticalAlignment','bottom','FontSize',8);
        end

        function app = PlotScatter(app,~,~)
            delete(app.EvaluationObject.PlotSpace.Children)
            app.EvaluationObject.PlotSpace.Title.String = " ";
            app.EvaluationObject.PlotSpace.XLabel.String = " ";
            app.EvaluationObject.PlotSpace.YLabel.String = " ";
            %scatter retention times vs mz, size: height or area
            GroupNames = [app.FeatureData.GroupName]; %get group labels, when augmented groups replace combined name with abstract name
            idx = contains(GroupNames,"+");
            GroupNames(idx) = app.FeatureData.AbstractGroupName(idx);

            unscaledData = app.FeatureData.CleanFullIntensityArray;
            % calculate unscaled averages:
            unscaledData(unscaledData==0)=NaN;
            unscaledData = mat2cell(unscaledData,size(unscaledData,1),app.FeatureData.NumberOfFilesArray);
            unscaledData = cellfun(@(x) mean(x,2,'omitnan'),unscaledData,'UniformOutput',false);
            unscaledData = horzcat(unscaledData{:});
            % all TICs
            TIC = cell(1,size(app.MassData,2));
            times = cell(1,size(app.MassData,2));
            for w = 1:size(app.MassData,2)
                times(1,w) = app.MassData{w}.RawDataFileObj.previewTimes(1,1);
                TIC(1,w) = app.MassData{w}.RawDataFileObj.previewTICs(1,1);
            end

            scaleddata=app.FeatureData.AverageIntensities;
            %get mz values and Time values from Feature Label
            mz = app.FeatureData.IdentifierArray(:,1);
            rt = app.FeatureData.IdentifierArray(:,2);
            % unscaled data
            figure("Name","Scatter Plot, unscaled");
            maxMZ = {max(mz,[],"all")*0.6};
            maxMZ = repmat(maxMZ,1,numel(TIC));
            TIC = cellfun(@(x,y) rescale(x,0,y),TIC,maxMZ,'UniformOutput',false);
            app.ScatterPlot(1) = gca;
            hold on
            for n=1:width(TIC)
                plot(times{1,n},TIC{1,n},'Color',[0.9 0.9 0.9],'HandleVisibility',"off",'HitTest',"off")
            end
            rdata=rescale(unscaledData,0.1,1500);
            for g=1:size(GroupNames,2)
                scatter(app.ScatterPlot(1),rt,mz,rdata(:,g),'filled');
                app.ScatterPlot(1).Children(1).CData = repmat(app.colorPalette(g,:),size(mz,1),1);
                app.ScatterPlot(1).Children(1).MarkerFaceAlpha = "flat";
                app.ScatterPlot(1).Children(1).AlphaDataMapping = "none";
                app.ScatterPlot(1).Children(1).AlphaData = repmat(0.2,size(mz,1),1);
                app.ScatterPlot(1).Children(1).DataTipTemplate.DataTipRows(1).Label="RT:";
                app.ScatterPlot(1).Children(1).DataTipTemplate.DataTipRows(2).Label="mz:";
                app.ScatterPlot(1).Children(1).DataTipTemplate.DataTipRows(3) = dataTipTextRow('Feature',app.FeatureData.NameStringArray);
                app.ScatterPlot(1).Children(1).DataTipTemplate.DataTipRows(4) = dataTipTextRow('unscaled Average Peak Value',unscaledData(:,g),'%.4e');
                app.ScatterPlot(1).Children(1).DataTipTemplate.DataTipRows(5) = [];
            end

            title("Scatter Plot mz vs RT circle size corresponds to unscaled average intensity")
            legend(GroupNames,Interpreter='none',AutoUpdate='off')
            xlabel("RT [sec]")
            ylabel("mz [Da]")

            % plot scaled data if scaling was applied
            if strcmp(app.EvaluationObject.transformationType,app.EvaluationObject.dataScalingType) == false
                % scaled data
                figure("Name","Scatter Plot, scaled");
                app.ScatterPlot(2) = gca;
                hold on
                for n=1:width(TIC)
                    plot(times{1,n},TIC{1,n},'Color',[0.9 0.9 0.9],'HandleVisibility',"off",'HitTest',"off")
                end
                set(gca,'ColorOrderIndex',1)
                rdata=rescale(scaleddata,0.1,100);
                for g=1:size(GroupNames,2)
                    scatter(rt,mz,rdata(:,g),'filled')
                    app.ScatterPlot(2).Children(1).CData = repmat(app.colorPalette(g,:),size(mz,1),1);
                    app.ScatterPlot(2).Children(1).AlphaData = repmat(0.2,size(mz,1),1);
                    app.ScatterPlot(2).Children(1).MarkerFaceAlpha = "flat";
                    app.ScatterPlot(2).Children(1).AlphaDataMapping = "none";
                    app.ScatterPlot(2).Children(1).DataTipTemplate.DataTipRows(1).Label = "RT:";
                    app.ScatterPlot(2).Children(1).DataTipTemplate.DataTipRows(2).Label = "mz:";
                    app.ScatterPlot(2).Children(1).DataTipTemplate.DataTipRows(3) = dataTipTextRow('Feature',app.FeatureData.NameStringArray);
                    app.ScatterPlot(2).Children(1).DataTipTemplate.DataTipRows(4) = dataTipTextRow('unscaled Average Peak Value',unscaledData(:,g),'%.4e');
                    app.ScatterPlot(2).Children(1).DataTipTemplate.DataTipRows(5) = [];
                end
                title("Scatter Plot mz vs RT, circle size corresponds to scaled average intensity")
                legend(GroupNames,Interpreter='none',AutoUpdate='off')
                xlabel("RT [sec]")
                ylabel("mz [Da]")
            end


        end

        function app = PlotCloud(app,~,~)
            delete(app.EvaluationObject.PlotSpace.Children)
            app.EvaluationObject.PlotSpace.Title.String = " ";
            app.EvaluationObject.PlotSpace.XLabel.String = " ";
            app.EvaluationObject.PlotSpace.YLabel.String = " ";
            %get data
            foldCell=app.FeatureData.FullFoldChanges;
            pCell=app.FeatureData.SignificantFeature;
            VSNames = app.FeatureData.VSNaming;
            %get mz values and Time values from Feature Label
            mz=app.FeatureData.IdentifierArray(:,1);
            rt=app.FeatureData.IdentifierArray(:,2);
            % all TICs
            TIC = cell(2,size(app.MassData,2));
            for w = 1:size(app.MassData,2)
                TIC(1,w) = app.MassData{w}.RawDataFileObj.previewTICs(1,1);
                TIC(2,w) = app.MassData{w}.RawDataFileObj.previewTimes(1,1);
            end
            %pad to same size
            nScans = max(cellfun(@numel,TIC(:,2)));
            TIC = cellfun(@(X) padarray(X,size(X,1)-nScans,0,"post"),TIC,UniformOutput=false);
            Times=vertcat(TIC{2,:});
            TIC=vertcat(TIC{1,:});
            for n=1:size(VSNames,2)
                fold = foldCell{n};
                fold = log2(fold);
                fold = real(fold);
                fold(fold==Inf)=0;
                fold(fold==-Inf)=0;
                p = pCell{n};
                PlotNames=VSNames{n};
                for g = 1:size(PlotNames,2)
                    figure("Name","Cloud Plot","NumberTitle","off");
                    hold on
                    mzDat = mz;
                    rtDat = rt;
                    %change mz sign when foldchange < 0
                    mzDat(fold(:,g)<0) = mzDat(fold(:,g)<0)*-1;
                    SizeDat = abs(fold(:,g));
                    ColorDat = p(:,g);
                    idx = SizeDat~=0;
                    if ~all(idx)
                        TIC = rescale(TIC,0,100);
                    else
                        TIC = rescale(TIC,0,max(mz(idx),[],"all")*0.7);
                    end
                    plot(Times,TIC,'Color',[0.9 0.9 0.9],'HandleVisibility',"off",'HitTest',"off")
                    plot(Times,-TIC,'Color',[0.9 0.9 0.9],'HandleVisibility',"off",'HitTest',"off")
                    set(gca,'ColorOrderIndex',1)
                    bubblechart(rtDat(idx),mzDat(idx),SizeDat(idx),ColorDat(idx))
                    colormap(gca,"copper")
                    app.CloudPlot(n+g-1) = gca;
                    bubblesize(app.CloudPlot(n+g-1),[1 25])
                    bubblelegend(app.CloudPlot(n+g-1),'Fold Change','Location','westoutside')
                    app.CloudPlot(n+g-1).Children(1).DataTipTemplate.DataTipRows(1).Label="Time:";
                    app.CloudPlot(n+g-1).Children(1).DataTipTemplate.DataTipRows(2).Label="mz:";
                    app.CloudPlot(n+g-1).Children(1).DataTipTemplate.DataTipRows(3)=dataTipTextRow("Fold Change:",fold(idx,g));
                    app.CloudPlot(n+g-1).Children(1).DataTipTemplate.DataTipRows(4)=dataTipTextRow("p-Value:",ColorDat(idx));
                    lebar = colorbar;
                    lebar.Label.String = 'p-Value';
                    title("Fold Changes of "+PlotNames(g),Interpreter="none")
                    ylabel("mz [Da]")
                    xlabel("Time [sec]")
                end

            end
        end

        function app = PlotProbability(app,~,~)
            delete(app.EvaluationObject.PlotSpace.Children)
            app.EvaluationObject.PlotSpace.Title.String = " ";
            app.EvaluationObject.PlotSpace.XLabel.String = " ";
            app.EvaluationObject.PlotSpace.YLabel.String = " ";
            %scatter retention times vs mz, size: height or area
            GroupNames=[app.FeatureData.GroupName]; %get group labels, when augmented groups replace combined name with abstract name
            idx = contains(GroupNames,"+");
            GroupNames(idx) = app.FeatureData.AbstractGroupName(idx);
            app.EvaluationObject.PlotTiles(1) = nexttile(app.EvaluationObject.PlotSpace,app.EvaluationObject.PlotSpace.GridSize);
            data=app.FeatureData.AverageIntensities; % Feature average Height or Area data
            data(data==0)=NaN;
            normplot(app.EvaluationObject.PlotTiles(1),data)
            legend(app.EvaluationObject.PlotTiles(1),GroupNames)
        end

        function app = PlotFeatureDistribution(app,~,~)
            delete(app.EvaluationObject.PlotSpace.Children)
            app.EvaluationObject.PlotSpace.Title.String = " ";
            app.EvaluationObject.PlotSpace.XLabel.String = " ";
            app.EvaluationObject.PlotSpace.YLabel.String = " ";
            %plot distribution of original data and transformed data
            GroupNames=[app.FeatureData.GroupName]; %get group labels, when augmented groups replace combined name with abstract name
            idx = contains(GroupNames,"+");
            GroupNames(idx) = app.FeatureData.AbstractGroupName(idx);
            %scaled data
            scaled = app.FeatureData.IntensityArray;
            scaled(scaled==0) = NaN;
            scaled = mat2cell(scaled, size(scaled,1),app.FeatureData.NumberOfFilesArray);
            for g=1:size(GroupNames,2)
                app.EvaluationObject.PlotTiles(g) = nexttile(app.EvaluationObject.PlotSpace);
                histogram(app.EvaluationObject.PlotTiles(g),scaled{g},'BinMethod','sqrt')
                title(app.EvaluationObject.PlotTiles(g),"Feature Intensities for " + GroupNames(g))
                %test for normal distribution
                zscores = scaled{g};
                zscores(any(isnan(zscores),2),:)=[];
                zscores = zscore(zscores);
                [h, p] = kstest(reshape(zscores,[],1), 'Alpha', 0.05);
                p = round(p,3);
                Significance = "Kolmogorov-Smirnov test, alpha: 0.05";
                if h
                    Significance(2,1) = "The values are not normally distributed.";
                else
                    Significance(2,1) =  "The values are normally distributed.";
                end
                Significance(3,1) = "Test p-value: " + p;
                xL=xlim(app.EvaluationObject.PlotTiles(g));
                yL=ylim(app.EvaluationObject.PlotTiles(g));
                text(app.EvaluationObject.PlotTiles(g),0.95*xL(2),0.95*yL(2),Significance,'HorizontalAlignment','right','VerticalAlignment','top','FontSize',8);
                xlh = xlabel(app.EvaluationObject.PlotTiles(g),"Intensity bin",'VerticalAlignment','top','HorizontalAlignment','center');
                xlh.Position(2) = xlh.Position(2) - abs(xlh.Position(2) * 0.1);
                ylabel(app.EvaluationObject.PlotTiles(g),"Counts per bin")
            end

        end

        function app = PlotVolcano(app,~,~)
            delete(app.EvaluationObject.PlotSpace.Children)
            app.EvaluationObject.PlotSpace.Title.String = " ";
            app.EvaluationObject.PlotSpace.XLabel.String = " ";
            app.EvaluationObject.PlotSpace.YLabel.String = " ";
            %get data
            foldCell = app.FeatureData.FullFoldChanges;
            pCell = app.FeatureData.SignificantFeature;
            GroupLabels = [app.FeatureData.GroupName]; %get group labels, when augmented groups replace combined name with abstract name
            idx = contains(GroupLabels,"+");
            GroupLabels(idx) = app.FeatureData.AbstractGroupName(idx);
            VSNames = app.FeatureData.VSNaming;
            FeatLabel = app.FeatureData.NameStringArray;
            ISUnique = any(app.FeatureData.UniqueFeature,2);
            %% transform min fold change
            FoldBorder = app.minFold;
            switch app.transformationType
                case 'logn'
                    FoldBorder = log(FoldBorder);
                case 'log10'
                    FoldBorder = log10(FoldBorder);
                case 'power'
                    FoldBorder = sqrt(FoldBorder);
                case 'cube'
                    FoldBorder = FoldBorder.^(1/3);
                case 'reciprocal'
                    FoldBorder = FoldBorder.^-1;
            end
            for n=1:size(GroupLabels,2)
                figure("Name","Volcano Plot for "+ GroupLabels(n),"NumberTitle","off");
                app.VulcanoPlot(n) = gca;
                fold = foldCell{n};
                fold = log2(fold);
                fold = real(fold);
                fold(fold==Inf) = 0;
                fold(fold==-Inf) = 0;
                fold(isnan(fold)) = 0;
                p = -log10(pCell{n});
                PlotNames=VSNames{n};
                hold on
                for g = 1:size(PlotNames,2)
                    scatter(fold(~ISUnique,g),p(~ISUnique,g),'filled','o')
                    app.VulcanoPlot(n).Children(1).CData = repmat(app.colorPalette(g,:),size(fold(~ISUnique,g),1),1);
                    app.VulcanoPlot(n).Children(1).MarkerFaceAlpha = "flat";
                    app.VulcanoPlot(n).Children(1).AlphaDataMapping = "none";
                    app.VulcanoPlot(n).Children(1).AlphaData = repmat(0.2,size(fold(~ISUnique,g),1),1);
                    app.VulcanoPlot(n).Children(1).DataTipTemplate.DataTipRows(1).Label="log2 Fold Change:";
                    app.VulcanoPlot(n).Children(1).DataTipTemplate.DataTipRows(2).Label="-log10 p:";
                    app.VulcanoPlot(n).Children(1).DataTipTemplate.DataTipRows(3)=dataTipTextRow("Feature:",FeatLabel);
                    app.VulcanoPlot(n).Children(1).DataTipTemplate.DataTipRows(4) = [];
                end
                title("Fold Changes of " + GroupLabels(n),Interpreter="none")
                xlabel("log2 FoldChange")
                ylabel("-log10 p")
                legend(PlotNames,Interpreter="none",AutoUpdate="off")
                xlim([min(fold(~ISUnique))-0.1,max(fold(~ISUnique))+0.1])
                xline(log2(FoldBorder),'-.','HandleVisibility','off')
                xline(-log2(FoldBorder),'-.','HandleVisibility','off')
                yline(-log10(app.maxP),'--r',"p Critical",'HandleVisibility','off')
                hold off
            end
        end

        function app = PlotpDistribution(app,~,~)
            delete(app.EvaluationObject.PlotSpace.Children)
            app.EvaluationObject.PlotSpace.Title.String = " ";
            app.EvaluationObject.PlotSpace.XLabel.String = " ";
            app.EvaluationObject.PlotSpace.YLabel.String = " ";
            %plot distribution of original data and transformed data
            GroupLabels=[app.FeatureData.GroupName]; %get group labels, when augmented groups replace combined name with abstract name
            idx = contains(GroupLabels,"+");
            GroupLabels(idx) = app.FeatureData.AbstractGroupName(idx);
            %define size of plot for each group
            Fullsize = app.EvaluationObject.PlotSpace.GridSize;
            numGroups = numel(GroupLabels);
            ColumnSize = Fullsize(2)/numGroups;
            RowSize = Fullsize(1);
            %scaled data
            pCell = app.FeatureData.SignificantFeature;
            for g=1:size(GroupLabels,2)
                app.EvaluationObject.PlotTiles(g) = nexttile(app.EvaluationObject.PlotSpace,[RowSize,ColumnSize]);
                histogram(app.EvaluationObject.PlotTiles(g),pCell{g},'BinMethod','sqrt')
                title(app.EvaluationObject.PlotTiles(g),"p-Values for " + GroupLabels(g))
                xlh = xlabel(app.EvaluationObject.PlotTiles(g),"p-Value bin",'VerticalAlignment','top','HorizontalAlignment','center');
                xlh.Position(2) = xlh.Position(2) - abs(xlh.Position(2) * 0.1);
                ylabel(app.EvaluationObject.PlotTiles(g),"Counts per bin")
            end
        end

        function app = PlotHeatmap(app,~,~)
            delete(app.EvaluationObject.PlotSpace.Children)
            app.EvaluationObject.PlotSpace.Title.String = " ";
            app.EvaluationObject.PlotSpace.XLabel.String = " ";
            app.EvaluationObject.PlotSpace.YLabel.String = " ";
            SampleData = app.FeatureData.IntensityArray;
            GroupData = vertcat(app.FeatureData.AverageIntensities);
            GroupData(isnan(GroupData))=0;
            GroupLabels = [app.FeatureData.GroupName]; %get group labels, when augmented groups replace combined name with abstract name
            idx = contains(GroupLabels,"+");
            GroupLabels(idx) = app.FeatureData.AbstractGroupName(idx);
            %correlation between Samples
            corrSamp=corrcoef(SampleData);
            %Correlation between Groups
            corrGroup=corrcoef(GroupData);
            %plots
            %GroupCorrelation
            %only plot if multiple groups
            if width(app.MassData) > 1
                figure("Name","Correlation Heatmap");
                h1=heatmap(GroupLabels,GroupLabels,corrGroup);
                h1.NodeChildren(3).XAxis.TickLabelInterpreter = 'none';
                h1.NodeChildren(3).YAxis.TickLabelInterpreter = 'none';
                title('Correlation between Groups')
            end
            %SampleCorrelation
            SampleLabels = vertcat(app.FeatureData.CombinedSampleNames{:});
            %check sample name size and replace when bigger than 20
            val = max(arrayfun(@(x) strlength(x), SampleLabels));
            if val >= 20
                SampleLabels = [];
                for n=1:numel(app.FeatureData.NumberOfFilesArray)
                    tempLabel = repmat(GroupLabels(n), app.FeatureData.NumberOfFilesArray(n),1);
                    tempLabel = tempLabel + " Repetition " + (1:1:app.FeatureData.NumberOfFilesArray(n))';
                    SampleLabels = [SampleLabels;tempLabel];
                end
            end
            figure("Name","Correlation Heatmap");
            h2=heatmap(SampleLabels,SampleLabels,corrSamp);
            h2.NodeChildren(3).XAxis.TickLabelInterpreter = 'none';
            h2.NodeChildren(3).YAxis.TickLabelInterpreter = 'none';
            title('Correlation between Samples')
        end

        function app = PlotPCA(app,~,~)
            d = uiprogressdlg(app.MainWindow,'Title','Performing Principal Component Analysis','Message','Please Wait','Indeterminate','on');
            delete(app.EvaluationObject.PlotSpace.Children)
            app.EvaluationObject.PlotSpace.Title.String = " ";
            app.EvaluationObject.PlotSpace.XLabel.String = " ";
            app.EvaluationObject.PlotSpace.YLabel.String = " ";
            PerformPCA(app)
            close(d)
        end

        function app = PlotISdeviation(app,~,~)
            delete(app.EvaluationObject.PlotSpace.Children)
            app.EvaluationObject.PlotSpace.Title.String = "Internal Standard mass deviation";
            app.EvaluationObject.PlotSpace.XLabel.String = " ";
            app.EvaluationObject.PlotSpace.YLabel.String = " ";

            for n = 1:numel(app.MassData)
                app.EvaluationObject.PlotTiles(n) = nexttile(app.EvaluationObject.PlotSpace);
                app.EvaluationObject.PlotTiles(n).NextPlot = 'add';
                colororder(app.EvaluationObject.PlotTiles(1),"gem12")
                roiMasses = app.MassData{n}.ROIDataFileObj.ROImzVec;
                
                deltas = app.MassData{n}.ISdelta*1000;
                if isscalar(deltas)
                    correctionVector = repmat(deltas,1,length(roiMasses));
                else
                    correctionVector = app.MassData{n}.mzCorrectionFcn(roiMasses)*1000;
                end
                plot(app.EvaluationObject.PlotTiles(1),roiMasses,correctionVector,"LineStyle","--")
                expectedMass = app.MassData{n}.ISMass;
                stem(app.EvaluationObject.PlotTiles(n),expectedMass,deltas,"filled",LineStyle=":",Marker="x",MarkerSize=10)
                app.EvaluationObject.PlotTiles(n).Children(1).DataTipTemplate.DataTipRows(1).Label = "m/z:";
                app.EvaluationObject.PlotTiles(n).Children(1).DataTipTemplate.DataTipRows(2).Label = "\Delta m/z:";
                app.EvaluationObject.PlotTiles(n).Children(2).DataTipTemplate.DataTipRows(1).Label = "m/z:";
                app.EvaluationObject.PlotTiles(n).Children(2).DataTipTemplate.DataTipRows(2).Label = "\Delta m/z:";
                title(app.EvaluationObject.PlotTiles(n),app.MassData{n}.GroupName)
                legend(app.EvaluationObject.PlotTiles(n),["m/z correction function";"found mass delta"])
                xlabel(app.EvaluationObject.PlotTiles(n),"m/z [Da]")
                ylabel(app.EvaluationObject.PlotTiles(n),"\Deltam/z [mDa]")
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ~isdeployed % check missing toolboxes for .mlapp Version
                addOnList = matlab.addons.installedAddons;
                isInstalled = find(ismember(addOnList.Identifier, {'BI','DM','SG','ST','WA','IP','DB'}));
                isEnabeled = addOnList.Enabled(isInstalled);
                if numel(isInstalled) ~= 7 || any(isEnabeled) == false
                    text = "Please install missing Toolboxes:";
                    toolboxes = addOnList{:,1};
                    text = vertcat(text,toolboxes(isInstalled(~isEnabeled)));
                    uialert(app.MainWindow,text,'Missing Toolbox detected','Icon','error','CloseFcn',@alertCallback);
                    uiwait(app.MainWindow)
                    delete(app)
                end
            end

            %construct TabGroup and First Tab
            app.TabContainer = uitabgroup(app.MainWindow,"Position",[10,10,1155,685]);
            app.TabContainer.SelectionChangedFcn = @(src,event){TabSelection(app,src,event)};
            %Construct Group name Field and Select File Button
            app.groupCounter = 0;
            constructNewTab(app);
            uitab(app.TabContainer,'Title','+',Tooltip="Add additional group");
            % Default Save Location and FileName
            folder = pwd;
            app.selectedFolder = convertCharsToStrings(folder);
            app.saveLocation = folder+"\"+"NewProject"+".ams";
            app.colorPalette = orderedcolors("gem12");
            app.ReportTable = uifigure(Visible="off");
            delete(app.ReportTable)

            function alertCallback(~,~)
                uiresume(app.MainWindow)
            end

        end

        % Button pushed function: ImportData
        function ImportDataPushed(app, event)
            [file,path] = uigetfile({'*.ams','AriumMS File (*.ams)'},'Select a File',app.selectedFolder,MultiSelect='off');
            figure(app.MainWindow);
            dialog = uiprogressdlg(app.MainWindow,'Title','Loading Project','Message','Please Wait','Indeterminate','on');
            if ischar(file) == true
                file = fullfile(path,file);
                app.saveLocation = file;
                %delete temporaries of all open tabs
                file = [[app.MassData.RawDataFile],[app.MassData.ROIDataFile]];
                id = isfile(file);
                for iFile = 1:numel(id)
                    if id(iFile) == true
                        delete(file(iFile))
                    end
                end
                %load data
                importData = load(file,'-mat');
                importData = importData.exportData;
                %store data
                app.MassData = importData.RawData;
                if isfield(importData,"roiResults")
                    app.rebuildTempFiles(importData.previews,importData.roiResults);
                else
                    app.rebuildTempFiles(importData.previews);
                end
                app.FeatureData = importData.FeatureData;
                app.augmentationAssignment = removecats(importData.augmentationAssignment);
                app.FullFeatureData = importData.FullFeatureData;

                %update Parameter settings
                app.EvaluationObject.pValueMax = importData.maxP;
                app.EvaluationObject.foldChangeMin = importData.minFold;
                app.EvaluationObject.transformationType = importData.transformationType;
                app.EvaluationObject.dataScalingType = importData.dataScalingType;
                app.mergeMassTolerance = importData.mzMergeTol;
                app.mergeMassUnit = importData.mzMergeTolUnit;
                app.mergeTimeTolerance = importData.rtMergeTol;
                app.dataBaseFile = importData.dataBaseFile;

                app.activateParameterPanels;
                drawnow nocallbacks limitrate
            else
                uialert(app.MainWindow,"Task aborted by user","No Data Selected","Icon","warning")
                return
            end

            %select first Group
            app.TabContainer.SelectedTab = app.Tab(1);
            app.selectedGroup = 1;
            %activate Evaluation panel if FeatureData is available
            if isempty(app.EvaluationObject)
                app.addEvaluationPanel;
            end

            for iFile=1:size(app.MassData,2)
                if isempty(app.MassData{iFile}.Files)
                    app.PreviewButton(iFile).Visible = "off";
                    app.EICButton(iFile).Visible = "off";
                    app.PreviewButton(iFile).Visible = "off";
                    app.EICButton(iFile).Visible = "off";
                    app.MainOptionsPanel(iFile).Container.Enable = "off";
                    app.ROIOptionsPanel(iFile).Container.Enable = "off";
                    app.IntegrationOptionsPanel(iFile).Container.Enable = "off";
                    app.ScalingPanel(iFile).Container.Enable = "off";
                    app.MSAlignPanel(iFile).Container.Enable = "off";
                    app.PeakAlignPanel(iFile).Container.Enable = "off";
                    app.BaselinePanel(iFile).Container.Enable = "off";
                    app.ISPanel(iFile).Container.Enable = "off";
                    app.GolayPanel(iFile).Container.Enable = "off";
                    app.AddNLossPanel(iFile).Container.Enable = "off";
                else    %check files
                    %check if File is still at specified location
                    if all(isfile([app.MassData{iFile}.Files;app.MassData{iFile}.BlankFiles]))
                        nScansMS1 = round(mean([app.MassData{iFile}.DataInfo.NumberOfScansMS1]));
                        nScansMSn = sum([app.MassData{iFile}.DataInfo.NumberOfScansMSn]~=0);
                        freq = round(mean([app.MassData{iFile}.DataInfo.ScanFrequenceMS1]),1);
                        app.InfoLabels(1,iFile) = uilabel(app.Tab(iFile),"Text","File Infos:",...
                            "Position",[130,620,200,20],...
                            "HorizontalAlignment","left",...
                            "VerticalAlignment","center",...
                            "Tooltip",["Selected Files:";app.MassData{iFile}.Files;app.MassData{iFile}.BlankFiles]);
                        app.InfoLabels(2,iFile) = uilabel(app.Tab(iFile),"Text","Scan Rate MS1: " + freq + " Hz",...
                            "Position",[130,600,200,20],...
                            "HorizontalAlignment","left",...
                            "VerticalAlignment","center");
                        app.InfoLabels(3,iFile) = uilabel(app.Tab(iFile),"Text","Number of MS1 Scans: " + nScansMS1,...
                            "Position",[130,580,200,20],...
                            "HorizontalAlignment","left",...
                            "VerticalAlignment","center",...
                            "Tooltip","Average number of MS1 scans");
                        app.InfoLabels(4,iFile) = uilabel(app.Tab(iFile),"Text","Files with MS/MS Scans: " + nScansMSn +"/"+numel(app.MassData{iFile}.Files),...
                            "Position",[130,560,200,20],...
                            "HorizontalAlignment","left",...
                            "VerticalAlignment","center",...
                            "Tooltip","Number of files with MS/MS data");
                        %ask for File Preview
                        app.PreviewButton(iFile).Visible = "on";
                        app.EICButton(iFile).Visible = "on";
                    else
                        app.MassData{iFile}.Files = "";
                        app.PreviewButton(iFile).Visible = "off";
                        app.EICButton(iFile).Visible = "off";
                        app.MainOptionsPanel(iFile).Container.Enable = "off";
                        app.ROIOptionsPanel(iFile).Container.Enable = "off";
                        app.IntegrationOptionsPanel(iFile).Container.Enable = "off";
                        app.ScalingPanel(iFile).Container.Enable = "off";
                        app.MSAlignPanel(iFile).Container.Enable = "off";
                        app.PeakAlignPanel(iFile).Container.Enable = "off";
                        app.BaselinePanel(iFile).Container.Enable = "off";
                        app.ISPanel(iFile).Container.Enable = "off";
                        app.GolayPanel(iFile).Container.Enable = "off";
                        app.AddNLossPanel(iFile).Container.Enable = "off";
                        uialert(app.MainWindow,"Files for "+ app.MassData{iFile}.GroupName +" are missing. Reselect MS-Files .","File not found");
                    end
                end
            end
            app.CheckDataState;

            close(dialog)
        end

        % Button pushed function: SelectSaveLoc
        function SelectSaveLocButtonPushed(app, event)
            initFile = app.saveLocation;
            [file,path] = uiputfile('*.ams','Select Save Location and Filename',initFile);
            figure(app.MainWindow);
            if ischar(file) 
                path = convertCharsToStrings(path);
                file = convertCharsToStrings(file);
                app.saveLocation = path+file;
            else % when user cancels input set safe location to current folder and filename to workspace Variable name
                return
            end
            app.saveData;
        end

        % Button pushed function: CopySettingstoallGroupsButton
        function CopySettingstoallGroupsButtonPushed(app, event)
            selection = uiconfirm(app.MainWindow,'Copy settings of current Group to all Groups? (Previous Inputs will be overwritten)','Proceed?',...
                'Options',{'All Parameters','Select Parameters','Cancel'}, ...
                'DefaultOption',1,'CancelOption',3,'Icon','question');

            switch selection
                case 'Cancel'
                    return
                case 'All Parameters'
                    selectedOptions = true(1,9);
                case 'Select Parameters'
                    selectedOptions = false(1,9);
                    prompt = {"Choose Settings to be copied","Selected settings are applied to all groups"};
                    list = {'ROI Parameters','Peak Picking and Filter Options','Spectral Alignment Parameters','Peak Alignment Parameters','Baseline Correction Parameters','Internal Standard Options','Golay Smoothing Parameters','Scaling Options','Adduct & Neutral Loss Options'};
                    [selectedIdx,calcelTest] = listdlg('PromptString',prompt,'SelectionMode','multiple','ListString',list,'ListSize',[220,150]);
                    figure(app.MainWindow);
                    if calcelTest == 0
                        return
                    end
                    selectedOptions(selectedIdx) = true;
            end
            nGroups = app.groupCounter;
            selected = app.selectedGroup;
            copyGroup = app.MassData{selected};
            for iGroup = 1:nGroups
                if iGroup ~= selected
                    app.MassData{iGroup}.useBlankSubtraction = false;
                    % ROI Block
                    if selectedOptions(1) == true
                        app.MassData{iGroup}.measurementEndTime = copyGroup.measurementEndTime;
                        app.MassData{iGroup}.measurementStartTime = copyGroup.measurementStartTime;
                        app.MassData{iGroup}.roiMinOccurrence = copyGroup.roiMinOccurrence;
                        app.MassData{iGroup}.withinFileMassTolerance = copyGroup.withinFileMassTolerance;
                        app.MassData{iGroup}.withinFileMassUnit = copyGroup.withinFileMassUnit;
                        app.MassData{iGroup}.roiThreshold = copyGroup.roiThreshold;
                    end
                    % Peak Picking Block
                    if selectedOptions(2) == true
                        app.MassData{iGroup}.betweenFileMassTolerance = copyGroup.betweenFileMassTolerance;
                        app.MassData{iGroup}.betweenFileMassUnit = copyGroup.betweenFileMassUnit;
                        app.MassData{iGroup}.peakTimeTolerance = copyGroup.peakTimeTolerance;
                        app.MassData{iGroup}.useEntropyFilter = copyGroup.useEntropyFilter;
                        app.MassData{iGroup}.entropyFilterStrength = copyGroup.entropyFilterStrength;
                        app.MassData{iGroup}.minOccurence = copyGroup.minOccurence;
                        app.MassData{iGroup}.minSignal2Noise = copyGroup.minSignal2Noise;
                        app.MassData{iGroup}.peakMinWidth = copyGroup.peakMinWidth;
                        app.MassData{iGroup}.peakMaxWidth = copyGroup.peakMaxWidth;
                        app.MassData{iGroup}.evaluationParameter = copyGroup.evaluationParameter;
                    end
                    %mz Align Block
                    if selectedOptions(3) == true
                        app.MassData{iGroup}.massAlignmentQuantil = copyGroup.massAlignmentQuantil;
                        app.MassData{iGroup}.massAlignmentCorrectionMethod = copyGroup.massAlignmentCorrectionMethod;
                        app.MassData{iGroup}.massAlignmentEstimMethod = copyGroup.massAlignmentEstimMethod;
                        app.MassData{iGroup}.useMassAlign = copyGroup.useMassAlign;
                    end
                    %Peak Align Block
                    if selectedOptions(4) == true
                        app.MassData{iGroup}.peakAlignmentGridSteps = copyGroup.peakAlignmentGridSteps;
                        app.MassData{iGroup}.peakAlignmentIteration = copyGroup.peakAlignmentIteration;
                        app.MassData{iGroup}.peakAlignmentSearchSpace = copyGroup.peakAlignmentSearchSpace;
                        app.MassData{iGroup}.peakAlignmentWindowSizeRatio = copyGroup.peakAlignmentWindowSizeRatio;
                        app.MassData{iGroup}.peakAlignmentPulseWidth = copyGroup.peakAlignmentPulseWidth;
                        app.MassData{iGroup}.peakAlignmentMaxShiftPositive = copyGroup.peakAlignmentMaxShiftPositive;
                        app.MassData{iGroup}.peakAlignmentMaxShiftNegative = copyGroup.peakAlignmentMaxShiftNegative;
                        app.MassData{iGroup}.usePeakAlign = copyGroup.usePeakAlign;
                    end

                    %Baseline Block
                    if selectedOptions(5) == true
                        app.MassData{iGroup}.baselineQuantil = copyGroup.baselineQuantil;
                        app.MassData{iGroup}.baselineSmoothMethod = copyGroup.baselineSmoothMethod;
                        app.MassData{iGroup}.baselineEstimationMethod = copyGroup.baselineEstimationMethod;
                        app.MassData{iGroup}.baselineRegressionMethod = copyGroup.baselineRegressionMethod;
                        app.MassData{iGroup}.baselineStepSize = copyGroup.baselineStepSize;
                        app.MassData{iGroup}.baselineWindowSize = copyGroup.baselineWindowSize;
                        app.MassData{iGroup}.useBaselineCorrection = copyGroup.useBaselineCorrection;
                    end
                    %IS Block
                    if selectedOptions(6) == true
                        app.MassData{iGroup}.internalStandardOrder = copyGroup.internalStandardOrder;
                        app.MassData{iGroup}.applyISto = copyGroup.applyISto;
                        app.MassData{iGroup}.useISMassCorrection = copyGroup.useISMassCorrection;
                        app.MassData{iGroup}.internalStandardData = copyGroup.internalStandardData;
                        app.MassData{iGroup}.nInternalStandard = copyGroup.nInternalStandard;
                        app.MassData{iGroup}.useInternalStandard = copyGroup.useInternalStandard;
                    end
                    % Golay Block
                    if selectedOptions(7) == true
                        app.MassData{iGroup}.smoothingDegree = copyGroup.smoothingDegree;
                        app.MassData{iGroup}.smoothingFrameSize = copyGroup.smoothingFrameSize;
                        app.MassData{iGroup}.useSmoothing = copyGroup.useSmoothing;
                    end
                    %Scaling Block
                    if selectedOptions(8) == true
                        app.MassData{iGroup}.sampleScale = copyGroup.sampleScale;
                        %check size of SampScale
                        if app.MassData{iGroup}.sampleScale < size(app.MassData{iGroup}.fileName,1)
                            app.MassData{iGroup}.sampleScale(size(app.MassData{iGroup}.fileName,1),1) = 1;
                        elseif app.MassData{iGroup}.sampleScale > size(app.MassData{iGroup}.fileName,1)
                            app.MassData{iGroup}.sampleScale(size(app.MassData{iGroup}.fileName,1)+1:end,1) = [];
                        end
                        app.MassData{iGroup}.groupScale = copyGroup.groupScale;
                        app.MassData{iGroup}.useScaling = copyGroup.useScaling;
                    end
                    % Adduct and Neutral loss block
                    if selectedOptions(9) == true
                        app.MassData{iGroup}.neutralSelectedCon = copyGroup.neutralSelectedCon;
                        app.MassData{iGroup}.neutralSelectedSmol = copyGroup.neutralSelectedSmol;
                        app.MassData{iGroup}.adductSelectedNeg = copyGroup.adductSelectedNeg;
                        app.MassData{iGroup}.adductSelectedPos = copyGroup.adductSelectedPos;
                        app.MassData{iGroup}.minCosineSimilarity = copyGroup.minCosineSimilarity;
                        app.MassData{iGroup}.useAdductFilter = copyGroup.useAdductFilter;
                        app.MassData{iGroup}.useIsotopeFilter = copyGroup.useIsotopeFilter;
                        app.MassData{iGroup}.useContaminantFilter = copyGroup.useContaminantFilter;
                    end

                    %Add Panels
                    app.addMainOptions(iGroup);
                    app.addROIOptions(iGroup);
                    app.addIntegrationOptions(iGroup);
                    app.addPeakAlignPanel(iGroup);
                    app.addMSAlignPanel(iGroup);
                    app.addBaselinePanel(iGroup);
                    app.addGolayPanel(iGroup);
                    app.addScalingPanel(iGroup);
                    app.addISPanel(iGroup);
                    app.addAddNLossPanel(iGroup);
                    %enable panels
                    app.MainOptionsPanel(iGroup).Container.Enable = true;
                    app.ROIOptionsPanel(iGroup).Container.Enable = true;
                    app.IntegrationOptionsPanel(iGroup).Container.Enable = true;                    
                    app.PeakAlignPanel(iGroup).Container.Enable = copyGroup.usePeakAlign;                    
                    app.MSAlignPanel(iGroup).Container.Enable = copyGroup.useMassAlign;                    
                    app.BaselinePanel(iGroup).Container.Enable = copyGroup.useBaselineCorrection;                    
                    app.GolayPanel(iGroup).Container.Enable = copyGroup.useSmoothing;                    
                    app.ScalingPanel(iGroup).Container.Enable = copyGroup.useScaling;                    
                    app.ISPanel(iGroup).Container.Enable = copyGroup.useInternalStandard;                    
                    app.AddNLossPanel(iGroup).Container.Enable = copyGroup.useAdductFilter;
                else
                    continue
                end
            end
            drawnow
            app.CheckReady
        end

        % Value changed function: TransformationDropDown
        function TransformationDropDownValueChanged(app, event)
            app.EvaluationObject.transformationType = app.TransformationDropDown.Value;
        end

        % Value changed function: ScalingDropDown
        function ScalingDropDownValueChanged(app, event)
            app.EvaluationObject.dataScalingType = app.ScalingDropDown.Value;
        end

        % Value changed function: maximumpValueEditField
        function maximumpValueEditFieldValueChanged(app, event)
            app.EvaluationObject.maxP = app.maximumpValueEditField.Value;
        end

        % Value changed function: minimumFoldChangeEditField
        function minimumFoldChangeEditFieldValueChanged(app, event)
            app.EvaluationObject.minFold = app.minimumFoldChangeEditField.Value;
        end

        % Button pushed function: ProcessFilesButton
        function ProcessFilesButtonPushed(app, event)
            if size(app.MassData,2) > 1 && isempty(app.augmentationAssignment) || app.DefineAugmentationandReferencingButton.Enable==true && size(app.augmentationAssignment,1) ~= size(app.MassData,2)
                % Augmentation Assignment not set,
                msg = {'Group augmentation or reference groups are not defined or not defined correctly.'; 'Continue?'};
                title = 'Continue?';
                selection = uiconfirm(app.MainWindow,msg,title, ...
                    'Options',{'Continue','Define Augmentation and Referencing','Cancel'}, ...
                    'DefaultOption',1,'CancelOption',3);
                switch selection
                    case 'Continue'

                    case 'Define Augmentation and Referencing'
                        constructAugmentationFigure(app)
                        uiwait(app.AugmentOptions.window)
                    case 'Cancel'
                        return
                end
            end

            pool = gcp('nocreate');
            if isempty(pool)
                % create parallel pool when no pool present
                parpool("Processes");
            end
            clearvars pool

            timeStart = tic;

            RawOut = struct(...
                "feature",[],...
                "minWidthFiltered",[],...
                "maxWidthFiltered",[],...
                "entropyFiltered",[],...
                "signal2NoiseFiltered",[],...
                "occurenceFiltered",[],...
                "groupName",string,...
                "fileNames",string,...
                "dataSize",[],...
                "separationType",string);

            for nGroup = 1:app.groupCounter
                [RawOut(nGroup),app.MassData{nGroup}] = app.MassData{nGroup}.extractFeaturesFromMassData;
            end
            assignin("base","RawOut_new",RawOut);

            % d.Message = "Augmenting Groups";
            % 
            % %Split Output into Augmentation Assignment
            % AugAssign = app.augmentationAssignment(:,1);
            % AugAssign = removecats(AugAssign);
            % Categories = categories(AugAssign);
            % idx = AugAssign == Categories';
            % %update Assignment of merged Groups
            % 
            % RefAssign = app.augmentationAssignment(:,2);
            % RefAssign = removecats(RefAssign,'none');
            % AugAssign = strings(numel(Categories),2);
            % % Build FeatureData objects
            % if isempty(Categories)
            %     mergedRaw = RawOut;
            %     app.mergedAssignment = app.augmentationAssignment;
            % end
            % for n= 1:length(Categories)
            %     if sum(idx(:,n)) > 1
            %         mergedRaw(n) = mergeGroups(app,RawOut(idx(:,n)));
            %         Names = mergedRaw(n).GroupName;
            %         mergedRaw(n).GroupName = strjoin(Names,"+");
            %         mergedRaw(n).GroupName(2,1) = string(Categories(n));
            %         Names = mergedRaw(n).SampleNames;
            %         mergedRaw(n).SampleNames = join(Names,"+");
            %         AugAssign(n,1) = convertCharsToStrings(Categories{n});
            %         if ~any(isundefined(RefAssign(idx(:,n))))
            %             ref = RefAssign(idx(:,n));
            %             id = strcmp(ref,'none');
            %             AugAssign(n,2) = ref(~id);
            %         else
            %             AugAssign(n,2) = "none";
            %         end
            %     else
            %         mergedRaw(n) = RawOut(idx(:,n));
            %         mergedRaw(n).GroupName(2,1) = string(Categories(n));
            %         app.mergedAssignment = app.augmentationAssignment;
            %     end
            % end
            % 
            % if size(mergedRaw,2) > 1 %Build fully Merged Struct
            %     app.FullFeatureData = BuildFullArray(app,mergedRaw);
            % else
            %     mergedRaw.SampleNumbers = size(mergedRaw.IntensityStorage,2); %add Number of files field
            %     app.FullFeatureData = mergedRaw;
            % end
            % %check for empty output
            % if app.FullFeatureData.DataSize == 0
            %     uialert(app.MainWindow,["No features found";"Use less restrictive settings to reprocess files"],"Warning","Icon","warning");
            %     return
            % end
            % %Build FeatureData objects
            % app.FeatureData = FeatData(app.FullFeatureData);
            % app.FullFeatureData = struct();
            % % calculate statistics
            % app.FeatureData = app.FeatureData.RefGroupScaling(app.mergedAssignment);
            % app.FeatureData = app.FeatureData.applyFeatureScaling(app.transformationType,app.dataScalingType);
            % app.FeatureData = app.FeatureData.CalculateSignificance(app.hochbergFilterFlag,app.falseDiscoveryRate);
            % 
            % 
            % 
            % % build new UI Elements
            % app.addEvaluationPanel;
            % app.EvaluationPanel.Container.Visible = "on";
            % app.FeatureScalingPanel.Visible = "on";
            % app.TabContainer.Visible = "off";
            % app.DataProcessingPanel.Visible = "off";
            % app.SwitchView.Value = "Evaluation";
            % d.Value = 1;
            % app.CheckDataState;
            % GenerateReport(app)

            tEnd = toc(timeStart);
            text = ["Data Processing succsessful","Time to Process was "+ tEnd/60 + " Minutes"];
            uialert(app.MainWindow,text,"Success","Icon","success");
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isfile(app.saveLocation)
                % File exists. Ask to overwrite or as new
                msg = 'Saving will overwrite previous file.';
                title = 'Confirm Save';
                selection = uiconfirm(app.MainWindow,msg,title, ...
                    'Options',{'Overwrite','Save as new','Cancel'}, ...
                    'DefaultOption',2,'CancelOption',3);
                switch selection
                    case 'Overwrite'
                        saveData(app)
                    case 'Save as new'
                        SelectSaveLocButtonPushed(app, event)
                        saveData(app)
                    case 'Cancel'
                        return
                end
            else
                % File does not exist. Save File as is
                saveData(app)
            end
        end

        % Close request function: MainWindow
        function MainWindowCloseRequest(app, event)
            msg = {'Do you want to save your changes to',convertStringsToChars(app.saveLocation)};
            title = 'Confirm Close';
            selection = uiconfirm(app.MainWindow,msg,title, ...
                'Options',{'Save Project','Close without saving','Cancel'}, ...
                'DefaultOption',1,'CancelOption',3);

            switch selection
                case 'Close without saving'
                    %delete(files)
                    deleteAll(app)
                    delete(app)
                case 'Save Project'
                    SaveButtonPushed(app, event)
                    deleteAll
                    delete(app)
                case 'Cancel'
                    return
            end

            function deleteAll(app)
                uiprogressdlg(app.MainWindow,"Title","Please Wait",...
                    "Message","Closing the application","Indeterminate","on");
                %gather and delete temporary files
                if ~isempty(app.MassData)
                    app.MassData(cellfun(@isempty,app.MassData)) = [];
                    for iGroup = 1:numel(app.MassData)
                        fileArray = [[app.MassData{1,iGroup}.RawDataFile],[app.MassData{1,iGroup}.ROIDataFile],[app.MassData{1,iGroup}.TempDataFile]];
                        id = isfile(fileArray);
                        fileArray(~id) = [];
                        for jFile = 1:numel(fileArray)
                            delete(fileArray(jFile))
                        end
                    end
                end

                for iGroup = 1:width(app.MassData)
                    try %close AddNloss window
                        close(app.AddNLossPanel(iGroup).window.UIFigure)
                    catch
                        continue
                    end
                    try %close IS window
                        close(app.ISPanel(iGroup).window)
                    catch
                        continue
                    end
                    try %close SampScale window
                        close(app.ScalingPanel(iGroup).window)
                    catch
                        continue
                    end
                end
                
                %close ResultSubDisplay
                if isgraphics(app.ResultSubDisplay)
                    close(app.ResultSubDisplay)
                end
                %close ReportTable
                if isgraphics(app.ReportTable)
                    close(app.ReportTable.Parent)
                end
                if ~isempty(app.AugmentOptions) & isgraphics(app.AugmentOptions.window)
                    close(app.AugmentOptions.window)
                end
                %close Database window
                if ~isempty(app.DataBase) & isgraphics(app.DataBase.Window)
                    close(app.DataBase.Window)
                end
            end
        end

        % Button pushed function: RemoveSelectedGroupButton
        function RemoveSelectedGroupButtonPushed(app, event)
            columnID = app.selectedGroup;
            app.groupCounter = app.groupCounter-1;
            % check if last group
            if app.groupCounter == 1
                app.RemoveSelectedGroupButton.Enable = 'off';
                app.DefineAugmentationandReferencingButton.Enable = 'off';
                app.CopySettingstoallGroupsButton.Enable = "off";
            end
            % remove row of Augmentation assignment if assignment
            % already exists
            if ~isempty(app.augmentationAssignment)
                app.augmentationAssignment(columnID,:) = [];
            end
            %delete tab objects
            app.currentPlot(:,columnID) = [];
            app.MassData(columnID) = [];
            delete(app.Tab(columnID).Children)
            delete(app.Tab(columnID))
            app.Tab(columnID) = [];
            app.GroupLabel(columnID) = [];
            app.GroupNameField(columnID) = [];
            app.InfoLabels(:,columnID) = [];
            app.SelectFilesButton(columnID) = [];
            app.PreviewButton(columnID) = [];
            app.EICButton(columnID) = [];
            app.PreviewAxes(columnID) = [];
            app.PreviewTiled(columnID) = [];
            app.MainOptionsPanel(columnID) = [];
            app.ROIOptionsPanel(columnID) = [];
            app.IntegrationOptionsPanel(columnID) = [];
            app.ISPanel(columnID) = [];
            app.PeakAlignPanel(columnID) = [];
            app.MSAlignPanel(columnID) = [];
            app.BaselinePanel(columnID) = [];
            app.GolayPanel(columnID) = [];
            app.AddNLossPanel(columnID) = [];
            app.ScalingPanel(columnID) = [];

            % update tab identifier and callbacks of all tabs
            for iTab=1:size(app.Tab,2)
                % Tab Identifier
                app.Tab(iTab).UserData = iTab;
                UpdateCallbacks(app,iTab);
            end
            % update selected Group
            newSelection = columnID-1;
            if newSelection < 1
                newSelection = 1;
            end
            app.TabContainer.SelectedTab = app.Tab(newSelection);
            app.selectedGroup = app.TabContainer.SelectedTab.UserData;
            %check ready
            app.CheckReady;
        end

        % Button pushed function: DefineAugmentationandReferencingButton
        function DefineAugmentationandReferencingButtonPushed(app, event)
            constructAugmentationFigure(app);
        end

        % Value changed function: SwitchView
        function SwitchViewValueChanged(app, event)
            value = app.SwitchView.Value;
            switch value
                case "Processing"
                    if size(app.EvaluationObject,1) == 1
                        app.EvaluationObject.Container.Visible = "off";
                    end
                    app.FeatureScalingPanel.Visible = "off";
                    app.TabContainer.Visible = "on";
                    app.DataProcessingPanel.Visible = "on";

                case "Evaluation"
                    if size(app.EvaluationObject,1) == 1
                        app.EvaluationObject.Container.Visible = "on";
                    else
                        app.EvaluationObject = EvaluationOptions(app);
                    end
                    app.TabContainer.Visible = "off";
                    app.FeatureScalingPanel.Visible = "on";
                    app.DataProcessingPanel.Visible = "off";
            end
        end

        % Value changed function: Hochberg
        function HochbergValueChanged(app, event)
            app.EvaluationObject.useHochbergFilter = app.Hochberg.Value;
            app.FalseDiscoveryRateLabel.Visible = app.Hochberg.Value;
            app.FDR.Visible = app.Hochberg.Value;
        end

        % Value changed function: FDR
        function FDRValueChanged(app, event)
            app.EvaluationObject.falseDiscoveryRate = app.FDR.Value/100;
        end

        % Button pushed function: ApplyChangesButton
        function ApplyChangesButtonPushed(app, event)
            % reapply scaling
            app.FeatureData = app.FeatureData.RefGroupScaling(app.mergedAssignment);
            app.FeatureData = app.FeatureData.applyFeatureScaling(app.EvaluationObject.transformationType,app.EvaluationObject.dataScalingType);
            % calculate new Statistics
            app.FeatureData = app.FeatureData.CalculateSignificance(app.hochbergFilterFlag,app.EvaluationObject.falseDiscoveryRate);
            if isvalid(app.ReportTable)
                close(app.ReportTable.Parent)
                app.generateReport
            else
                app.generateReport
            end

        end

        % Button pushed function: DEBUGButton
        function DEBUGButtonPushed(app, event)
            assignin("base","app",app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'AriumMS';
            app.MainWindow.CloseRequestFcn = createCallbackFcn(app, @MainWindowCloseRequest, true);
            app.AriumMSLabel = uilabel(app.MainWindow);
            app.AriumMSLabel.Text = 'AriumMS';
            app.alpha60Label = uilabel(app.MainWindow);
            app.alpha60Label.Text = '1.2.alpha-6.0';
            app.GeneralOptionsPanel = uipanel(app.MainWindow);
            app.GeneralOptionsPanel.Title = 'General Options';
            app.ImportData = uibutton(app.GeneralOptionsPanel, 'push');
            app.ImportData.ButtonPushedFcn = createCallbackFcn(app, @ImportDataPushed, true);
            app.ImportData.Text = 'Import Project';
            app.SelectSaveLoc = uibutton(app.GeneralOptionsPanel, 'push');
            app.SelectSaveLoc.ButtonPushedFcn = createCallbackFcn(app, @SelectSaveLocButtonPushed, true);
            app.SelectSaveLoc.Text = 'Select Save Location';
            app.SaveButton = uibutton(app.GeneralOptionsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save Project';
            app.FeatureScalingPanel = uipanel(app.MainWindow);
            app.FeatureScalingPanel.Title = 'Feature Scaling';
            app.FeatureScalingPanel.Visible = 'off';
            app.ScalingDropDownLabel = uilabel(app.FeatureScalingPanel);
            app.ScalingDropDownLabel.Text = 'Scaling';
            app.ScalingDropDown = uidropdown(app.FeatureScalingPanel);
            app.ScalingDropDown.Items = {'none', 'Centering', 'Auto', 'Pareto', 'Vast', 'Range', 'Level'};
            app.ScalingDropDown.ItemsData = {'none', 'Centering', 'Auto', 'Pareto', 'Vast', 'Range', 'Level'};
            app.ScalingDropDown.ValueChangedFcn = createCallbackFcn(app, @ScalingDropDownValueChanged, true);
            app.ScalingDropDown.Value = 'none';
            app.TransformationDropDownLabel = uilabel(app.FeatureScalingPanel);
            app.TransformationDropDownLabel.Text = 'Transformation';
            app.TransformationDropDown = uidropdown(app.FeatureScalingPanel);
            app.TransformationDropDown.Items = {'none', 'Natural log', 'log 10', 'Power', 'Cube Root', 'Reciprocal'};
            app.TransformationDropDown.ItemsData = {'none', 'logn', 'log10', 'power', 'cube', 'reciprocal'};
            app.TransformationDropDown.ValueChangedFcn = createCallbackFcn(app, @TransformationDropDownValueChanged, true);
            app.TransformationDropDown.Value = 'none';
            app.maximumpValueEditFieldLabel = uilabel(app.FeatureScalingPanel);
            app.maximumpValueEditFieldLabel.Text = {'maximum'; 'p-Value'};
            app.maximumpValueEditField = uieditfield(app.FeatureScalingPanel, 'numeric');
            app.maximumpValueEditField.Limits = [0 1];
            app.maximumpValueEditField.ValueChangedFcn = createCallbackFcn(app, @maximumpValueEditFieldValueChanged, true);
            app.maximumpValueEditField.Value = 0.05;
            app.minimumFoldChangeEditFieldLabel = uilabel(app.FeatureScalingPanel);
            app.minimumFoldChangeEditFieldLabel.Text = {'minimum'; 'Fold Change'};
            app.minimumFoldChangeEditField = uieditfield(app.FeatureScalingPanel, 'numeric');
            app.minimumFoldChangeEditField.Limits = [0 Inf];
            app.minimumFoldChangeEditField.ValueChangedFcn = createCallbackFcn(app, @minimumFoldChangeEditFieldValueChanged, true);
            app.minimumFoldChangeEditField.Value = 2;
            app.ApplyChangesButton = uibutton(app.FeatureScalingPanel, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButtonPushed, true);
            app.ApplyChangesButton.Enable = 'off';
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.Hochberg = uicheckbox(app.FeatureScalingPanel);
            app.Hochberg.ValueChangedFcn = createCallbackFcn(app, @HochbergValueChanged, true);
            app.Hochberg.Text = 'Benjamini Hochberg Filter';
            app.FalseDiscoveryRateLabel = uilabel(app.FeatureScalingPanel);
            app.FalseDiscoveryRateLabel.Visible = 'off';
            app.FalseDiscoveryRateLabel.Text = {'False Discovery '; 'Rate '};
            app.FDR = uieditfield(app.FeatureScalingPanel, 'numeric');
            app.FDR.Limits = [0 100];
            app.FDR.ValueDisplayFormat = '%.1f %%';
            app.FDR.ValueChangedFcn = createCallbackFcn(app, @FDRValueChanged, true);
            app.FDR.Visible = 'off';
            app.FDR.Value = 10;
            app.SwitchViewLabel = uilabel(app.MainWindow);
            app.SwitchViewLabel.Text = 'Switch View';
            app.SwitchView = uiswitch(app.MainWindow, 'slider');
            app.SwitchView.Items = {'Processing', 'Evaluation'};
            app.SwitchView.ValueChangedFcn = createCallbackFcn(app, @SwitchViewValueChanged, true);
            app.SwitchView.Value = 'Processing';
            app.DataProcessingPanel = uipanel(app.MainWindow);
            app.DataProcessingPanel.Title = 'Data Processing';
            app.DefineAugmentationandReferencingButton = uibutton(app.DataProcessingPanel, 'push');
            app.DefineAugmentationandReferencingButton.ButtonPushedFcn = createCallbackFcn(app, @DefineAugmentationandReferencingButtonPushed, true);
            app.DefineAugmentationandReferencingButton.Enable = 'off';
            app.DefineAugmentationandReferencingButton.Text = {'Define Augmentation '; 'and Referencing'};
            app.CopySettingstoallGroupsButton = uibutton(app.DataProcessingPanel, 'push');
            app.CopySettingstoallGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @CopySettingstoallGroupsButtonPushed, true);
            app.CopySettingstoallGroupsButton.Text = {'Copy Settings '; 'to all Groups'};
            app.RemoveSelectedGroupButton = uibutton(app.DataProcessingPanel, 'push');
            app.RemoveSelectedGroupButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSelectedGroupButtonPushed, true);
            app.RemoveSelectedGroupButton.Text = {'Remove '; 'Selected Group'};
            app.ProcessFilesButton = uibutton(app.DataProcessingPanel, 'push');
            app.ProcessFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessFilesButtonPushed, true);
            app.ProcessFilesButton.Enable = 'off';
            app.ProcessFilesButton.Text = {'Process'; 'Files'};
            app.DEBUGButton = uibutton(app.MainWindow, 'push');
            app.DEBUGButton.ButtonPushedFcn = createCallbackFcn(app, @DEBUGButtonPushed, true);
            app.DEBUGButton.Text = 'DEBUG';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AriumMS

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MainWindow)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.MainWindow)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end