classdef AriumMSEval < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Main                            matlab.ui.Figure
        Version10Label                  matlab.ui.control.Label
        ExtractFeaturesButton           matlab.ui.control.Button
        FeatureExtractionPanel          matlab.ui.container.Panel
        FeatureFilterPanel              matlab.ui.container.Panel
        EntroFilt                       matlab.ui.control.CheckBox
        minSN                           matlab.ui.control.NumericEditField
        minimumSignalNoiseLabel         matlab.ui.control.Label
        MaxPeakW                        matlab.ui.control.NumericEditField
        MaximumPeakWidthsecEditFieldLabel  matlab.ui.control.Label
        MinPeakW                        matlab.ui.control.NumericEditField
        MinimumPeakWidthsecEditFieldLabel  matlab.ui.control.Label
        MinPeakH                        matlab.ui.control.NumericEditField
        MinimumPeakHeightEditFieldLabel  matlab.ui.control.Label
        FDR                             matlab.ui.control.NumericEditField
        FalseDiscoveryRateEditFieldLabel  matlab.ui.control.Label
        Hochberg                        matlab.ui.control.CheckBox
        FoldChangeEditField             matlab.ui.control.NumericEditField
        FoldChangeEditFieldLabel        matlab.ui.control.Label
        pVal                            matlab.ui.control.NumericEditField
        pValueEditFieldLabel            matlab.ui.control.Label
        MinOcc                          matlab.ui.control.NumericEditField
        MinOccuranceperGroupLabel       matlab.ui.control.Label
        SelectEvaluationParameterButtonGroup  matlab.ui.container.ButtonGroup
        PeakHeightButton                matlab.ui.control.RadioButton
        PeakAreaButton                  matlab.ui.control.RadioButton
        FeatureSortingParametersPanel   matlab.ui.container.Panel
        RTTol                           matlab.ui.control.NumericEditField
        RTTolerancesecEditFieldLabel    matlab.ui.control.Label
        mzTol                           matlab.ui.control.NumericEditField
        mzToleranceDaEditFieldLabel     matlab.ui.control.Label
        DefineSampleSpecificScalingFactorsButton  matlab.ui.control.Button
        ExportResultsButton             matlab.ui.control.Button
        DataPreTreatmentPanel           matlab.ui.container.Panel
        ScalingOptionsButtonGroup       matlab.ui.container.ButtonGroup
        Level                           matlab.ui.control.RadioButton
        Center                          matlab.ui.control.RadioButton
        Range                           matlab.ui.control.RadioButton
        Vast                            matlab.ui.control.RadioButton
        STD                             matlab.ui.control.RadioButton
        Pareto                          matlab.ui.control.RadioButton
        noScale                         matlab.ui.control.RadioButton
        TransformationsButtonGroup      matlab.ui.container.ButtonGroup
        powerTransf                     matlab.ui.control.RadioButton
        logTransf                       matlab.ui.control.RadioButton
        noTransf                        matlab.ui.control.RadioButton
        ISTDProcessingoptionsPanel      matlab.ui.container.Panel
        MZCal                           matlab.ui.control.CheckBox
        ISTDnormalizationDropDown       matlab.ui.control.DropDown
        ISTDnormalizationDropDownLabel  matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        BLK_then_ISTD                   matlab.ui.control.RadioButton
        ISTD_then_BLK                   matlab.ui.control.RadioButton
        DataVisualizationPanel          matlab.ui.container.Panel
        Reject                          matlab.ui.control.Button
        Chrom                           matlab.ui.control.Button
        Cloud                           matlab.ui.control.Button
        Volcano                         matlab.ui.control.Button
        EntroHist                       matlab.ui.control.Button
        ScatterPlot                     matlab.ui.control.Button
        MultivariateDataAnalysisPanel   matlab.ui.container.Panel
        HeatmapButton                   matlab.ui.control.Button
        PCAButton                       matlab.ui.control.Button
        AriumMSEvalLabel                matlab.ui.control.Label
    end


    properties (Access = private)
        Features      % Extracted Feature Data
        InputTable    % User input for Group relations and Group scaling factors
        ISTDval       % Extracted IS Feature Data
        EvalParam     % Eval App Parameter Storage
        NewResults    % Processed RawResults
        CallingApp    % ROIAnalyst Main App
        SampScaleApp  % SubApp for Sample scaling definition
        uniqueMZ      % list of all feature MZ values
        uniqueRT      % list of all feature retention times
        ScaleType     % selected Normalization
        TransType     % selected Transformation
        Stats         % Statistic results
        Grouping      % Group Data assignment
        RejectedFeat  % Number of rejected Features per Filter
        iEntropy    % Average Entropy for Entropy plot
        IntData      % integration results
        NumericFeatDat % storage for numeric mz and RT values for all found features
    end

    properties (Access = public)
        RawResults %     Data handed over from ROIAnalyst MainApp
        SampScaleVal    %matrix containing Scaling factors for each sample
        AddPosRules     % Calculation Rules nositive Adducts
        AddNegRules     % Calculation Rules negative Adducts
        NLossRules      % Calculation Rules Neutral Loss Fragments
        IsotopeRules    % Masses and natural abundance of Isotopes
    end


    methods (Access = private)
        %% Blank Subtraction function
        function Data = subBLK(~,Data)
            Ints=Data{1,1}(1:end-1,1);
            BLK=Data{1,1}{end,1};
            mz=Data{1,1}{1,2};
            parfor r=1:length(Ints)
                DataTemp=Ints{r,1}-BLK;
                DataTemp = max(DataTemp,0);
                idx=max(DataTemp)==0;
                DataTemp(:,idx)=[];
                temp{r,1}=DataTemp;
                tempmz{r,1}=mz(:,~idx);
            end
            Data{1,1}(end,:)=[];
            Data{1,1}(:,1) = temp;
            Data{1,1}(:,2) = tempmz;
        end

        %% Integrate function
        function [Out,allEntropy] = CWTIntegrate(~,Input,PeakHeight,MinPeakwidth,MaxPeakwidth,minSignalnoise,EntropyFilter)
            Out=cell(size(Input,1),2);
            allEntropy=zeros(size(Input,1),1);
            for d=1:size(Input,1)
                Sample=Input{d,1};
                mz=Input{d,2};
                time=Input{d,3};
                res=cell(11,length(mz));
                scfrq=Input{d,4};
                MinPW=1/round(MinPeakwidth/scfrq);
                MaxPW=1/round(MaxPeakwidth/scfrq);
                fb = cwtfilterbank("SignalLength",numel(time),"WaveletParameters",[3 4],"VoicesPerOctave",32,"FrequencyLimits",[MaxPW MinPW]);
                for m=1:length(mz)
                    res{1,m}=mz(1,m); %Store searched mz
                    EIC=Sample(:,m); %extract EIC
                    EICtemp=EIC;
                    res{6,m}=EIC; %store ROI profile
                    sEIC=smoothdata(EIC,"gaussian","omitnan","SmoothingFactor",0.075);
                    H=EIC-sEIC; %Highpass Filter
                    D2=diff(sEIC,2);
                    D2(numel(time))=0;
                    %% wavelet transform
                    [cfs2,~,~,~] = wt(fb,-D2);
                    scaleCWT=rescale(real(cfs2),0,1);
                    %find initial rt
                    imMax=imextendedmax(scaleCWT,0.2);
                    RTID=any(imMax,1);
                    isolatedPeak=sEIC;
                    isolatedPeak(~RTID)=0;
                    [~,locs,~,~] = findpeaks(isolatedPeak,'WidthReference','halfheight');
                    %find initial border locations
                    imMin=imextendedmin(scaleCWT,0.1);
                    imMin=sum(imMin);
                    [~,borders,~,~] = findpeaks(imMin);
                    peaks=zeros(numel(locs),3);
                    peaks(:,1)=locs';
                    %sort borders to RT
                    for n=1:size(peaks,1)
                        [~,idx]=mink(borders-locs(n),2,'ComparisonMethod','abs');
                        if isempty(borders(idx))==true
                            peaks(n,2:3)=[0,0];
                        else
                            peaks(n,2:3)=sort(borders(idx),'ascend');
                        end
                    end
                    idx=peaks(:,2)==0;
                    peaks(idx,:)=[];
                    %friction border correction
                    sEIC=rescale(sEIC,0,1);
                    thresh=0.001;
                    %normalize thresh
                    thresh=(max(sEIC)-min(sEIC))*thresh;
                    for n=1:size(peaks,1)
                        u=peaks(n,3);
                        while u<numel(sEIC)-1 %upper bond
                            if sEIC(u)-sEIC(u+1)>thresh
                                u=u+1;
                            else
                                peaks(n,3)=u;
                                break
                            end
                        end
                        l=peaks(n,2);
                        while l>1 %lower bond
                            if sEIC(l)-sEIC(l-1)>thresh
                                l=l-1;
                            else
                                peaks(n,2)=l;
                                break
                            end
                        end
                    end
                    %moving STD correction to remove errors from smoothed peaks
                    for n=1:size(peaks,1)
                        u=peaks(n,3);
                        while u>1 && u<numel(EIC)-8 %upper bond
                            if EIC(u-1)-mean(EIC(u-1:u+7)) <= std(EIC(u-1:u+7)) || round(EIC(u-1)-EIC(u))==0
                                u=u-1;
                            else
                                peaks(n,3)=u;
                                break
                            end
                        end
                        l=peaks(n,2);
                        while l<=numel(EIC)-1 && l>8 %lower bond
                            if EIC(l+1)-mean(EIC(l-7:l+1)) <= std(EIC(l-7:l+1)) || round(EIC(l)-EIC(l+1))==0
                                l=l+1;
                            else
                                peaks(n,2)=l;
                                break
                            end
                        end
                    end
                    %get final peak location and height
                    for n=1:size(peaks,1)
                        EICtemp(1:peaks(n,2)-1)=0;
                        EICtemp(peaks(n,3)+1:end)=0;
                        [peaks(n,4),peaks(n,1)]=max(EICtemp);
                    end
                    %% Peak filter
                    %remove peaks with wrong boundaries
                    if isempty(peaks)==false
                        idx=peaks(:,2)>=peaks(:,3);
                        peaks(idx,:)=[];
                    end
                    %remove duplicate peaks
                    if isempty(peaks)==false
                        peaks=unique(peaks,'rows');
                    end
                    %remove peaks with height = 0
                    if isempty(peaks)==false
                        idx=peaks(:,4)==0;
                        peaks(idx,:)=[];
                    end
                    %less than minimum peak width
                    if isempty(peaks)==false
                        idx=peaks(:,3)-peaks(:,2)<round(MinPeakwidth/scfrq);
                        peaks(idx,:)=[];
                        res{7,m}=sum(idx);
                    end
                    %more than maximum peak width
                    if isempty(peaks)==false
                        idx=peaks(:,3)-peaks(:,2)>round(MaxPeakwidth/scfrq);
                        peaks(idx,:)=[];
                        res{8,m}=sum(idx);
                    end
                    % less than minimum peak height
                    if isempty(peaks)==false
                        idx=peaks(:,4)/max(peaks(:,4)) < PeakHeight;
                        peaks(idx,:)=[];
                        res{9,m}=sum(idx);
                    end
                    %S/N peak rejection
                    SN=zeros(size(peaks,1),1);
                    if isempty(peaks)==false
                        SN=peaks(:,4)./std(H);
                        idx=SN<minSignalnoise;
                        peaks(idx,:)=[];
                        SN(idx,:)=[];
                        res{10,m}=sum(idx);
                    end
                    %% entropy calculation
                    Entropy=zeros(size(peaks,1),1);
                    if isempty(peaks)==false
                        D=diff(EIC);
                        p=zeros(size(peaks,1),1);
                        for n=1:size(peaks,1)
                            maxidx=peaks(n,1);
                            % check normal or variant point , variant point = 1
                            premax=D(1:maxidx-1)<0;
                            postmax=D(maxidx+1:end)>0;
                            VarPoints=[premax; false; postmax];
                            %cut to size
                            VarPoints=VarPoints(peaks(n,2):peaks(n,3));
                            p(n,1)=sum(VarPoints)/numel(VarPoints);
                        end
                        Entropy=-p.*log2(p)-(1-p).*log2(1-p);
                        Entropy(isnan(Entropy))=0;
                    end
                    %% integrate and store results
                    if isempty(peaks)==false
                        Areas=zeros(size(peaks,1),1);
                        for n=1:size(peaks,1)
                            Areas(n,1)=trapz(EIC(peaks(n,2):peaks(n,3)));
                        end
                        res{2,m}=peaks(:,4);
                        res{3,m}=[Areas,peaks(:,2),peaks(:,3)];
                        res{4,m}=[peaks(:,1),time(peaks(:,1))];
                        res{5,m}=[Entropy,SN];
                    end
                end
                empt=cellfun(@isempty,res(2,:));
                res(:,empt)=[];
                %entropy rejection
                mEntropy=vertcat(res{5,:});
                mEntropy(:,2)=[];
                mEntropy=median(mEntropy,'omitnan');
                if EntropyFilter==true
                    for n=1:size(res,2)
                        idx=res{5,n}(:,1)>mEntropy;
                        res{2,n}(idx,:)=[];
                        res{3,n}(idx,:)=[];
                        res{4,n}(idx,:)=[];
                        res{11,n}=sum(idx);
                    end
                else
                    for n=1:size(res,2)
                        res{11,n}=0;
                    end
                end
                % remove empty features and store
                empt=cellfun(@isempty,res(2,:));
                res(:,empt)=[];
                res={res};
                res{1,2}=time;
                Out(d,:)=res; %store Sample data
                allEntropy(d,1)=mEntropy;
            end
        end
        %% find ISTD values
        function SampleCells = getISTDvals(app,SampleCells)
            %search sample data for ISTD and store ISTD data
            nSamp=size(SampleCells{25,1},1);
            if app.ISTDnormalizationDropDown.Value == 1
                nSamp=nSamp-1;
            end
            ISData=SampleCells{24,1};
            nIS=size(ISData,1);
            mzerror=SampleCells{11,1};
            %repetition loop
            for N=1:nSamp
                %get relevent data
                Ints=SampleCells{25,1}{N,1};
                scfrq=SampleCells{25,1}{N,4};
                MinPWS=1/round(app.EvalParam{1,2}/scfrq);
                MaxPWS=1/round(app.EvalParam{1,3}/scfrq);
                ROImz=SampleCells{25,1}{N,2};
                %preallocate IS dataStor for samples
                SampleIS=zeros(nIS,7);
                ZeroVec=[ones(1,7) 0];
                %remove possible empty columns
                idx=max(Ints)<=0;
                Ints(:,idx)=[];
                ROImz(idx)=[];
                %num internalStandardLoop
                for i=1:nIS
                    IS=ISData(i,:);
                    ISmz=repmat(IS(1,1),size(ROImz));
                    [mindelta,idx]=min(abs(ISmz-ROImz));
                    %check if ISmass found
                    if (abs(mindelta) > mzerror) == true
                        header="Skipping ISTD No. "+ i;
                        switch N == nSamp & SampleCells{4,1} == true
                            case true
                                message = "Reason: ISTD mass not found in Blank File";
                            otherwise
                                message = "Reason: ISTD mass not found in Sample " + N;
                        end
                        uialert(app.Main,message,header,'Icon','warning');
                        SampleIS(i,:)=ZeroVec;
                        continue
                    else
                        mindelta=IS(1,1)-ROImz(idx);
                        EIC=Ints(:,idx);
                        sEIC=smoothdata(EIC,"gaussian","omitnan","SmoothingFactor",0.075);
                        D2=diff(sEIC,2);
                        D2(numel(EIC))=0;
                        fb = cwtfilterbank("SignalLength",numel(D2),"WaveletParameters",[3 4],"VoicesPerOctave",32,"FrequencyLimits",[MaxPWS MinPWS]);
                        [cfs2,~,~,~] = wt(fb,-D2);
                        scaleCWT=rescale(real(cfs2),0,1);
                        %borders in CWT matrix
                        imMax=imextendedmax(scaleCWT,0.2);
                        imMin=imextendedmin(scaleCWT,0.1);
                        %find peak location and height
                        RTID=any(imMax,1);
                        isolatedPeak=sEIC;
                        isolatedPeak(~RTID)=0;
                        [~,locs,~,~] = findpeaks(isolatedPeak,'WidthReference','halfheight');
                        %find initial border locations
                        imMin=sum(imMin);
                        [~,borders,~,~] = findpeaks(imMin);
                        %sort bounderies to peaks
                        peaks=zeros(numel(locs),3);
                        peaks(:,1)=locs';
                        for n=1:size(peaks,1)
                            [~,idx]=mink(borders-locs(n),2,'ComparisonMethod','abs');
                            peaks(n,2:3)=sort(borders(idx),'ascend');
                        end
                        dataMatscale=rescale(sEIC);
                        %friction boundary correction
                        thresh=0.001;
                        %normalize thresh
                        thresh=(max(dataMatscale)-min(dataMatscale))*thresh;
                        for n=1:size(peaks,1)
                            u=peaks(n,3);
                            while u<numel(dataMatscale)-1 %upper bond
                                if dataMatscale(u)-dataMatscale(u+1)>thresh
                                    u=u+1;
                                else
                                    peaks(n,3)=u;
                                    break
                                end
                            end
                            l=peaks(n,2);
                            while l>0 %lower bond
                                if dataMatscale(l)-dataMatscale(l-1)>thresh
                                    l=l-1;
                                else
                                    peaks(n,2)=l;
                                    break
                                end
                            end
                        end
                        %moving STD correction to remove errors from smoothed peaks
                        for n=1:size(peaks,1)
                            u=peaks(n,3);
                            while u>1 && u<numel(EIC)-8 %upper bond
                                if EIC(u-1)-mean(EIC(u-1:u+7)) <= std(EIC(u-1:u+7)) || round(EIC(u-1)-EIC(u))==0
                                    u=u-1;
                                else
                                    peaks(n,3)=u;
                                    break
                                end
                            end
                            l=peaks(n,2);
                            while l<=numel(EIC)-1 && l>8 %lower bond
                                if EIC(l+1)-mean(EIC(l-7:l+1)) <= std(EIC(l-7:l+1)) || round(EIC(l)-EIC(l+1))==0
                                    l=l+1;
                                else
                                    peaks(n,2)=l;
                                    break
                                end
                            end
                        end
                        %remove peaks with wrong boundaries
                        idx=peaks(:,2)>=peaks(:,3);
                        peaks(idx,:)=[];
                        %get final peak location and height
                        for n=1:size(peaks,1)
                            p=EIC;
                            p(1:peaks(n,2)-1)=0;
                            p(peaks(n,3)+1:end)=0;
                            [peaks(n,4),peaks(n,1)]=max(p);
                        end
                        %filter peaks outside RT range
                        rt=SampleCells{25,1}{N,3}(peaks(n,1));
                        RangeStart = rt-IS(1,3)/2;
                        RangeEnd = rt+IS(1,3)/2;
                        if isempty(rt) == true
                            peaks=[];
                        else
                            [idx,~] = ismembertol(rt,RangeStart,RangeEnd,'DataScale',1);
                            peaks(~idx,:)=[];
                        end
                    end
                    if isempty(peaks)==true
                        header="Skipping ISTD No. "+ i;
                        switch N == nSamp & SampleCells{4,1} == true
                            case true
                                message = "Reason: ISTD outside acceptable RT range in Blank File";
                            otherwise
                                message = "Reason: ISTD outside acceptable RT range in Sample " + N;
                        end
                        uialert(app.Main,message,header,'Icon','warning');
                        SampleIS(i,:)=ZeroVec;
                        continue
                    else
                        %filter smaller peaks peaks
                        [~,idx]=max(peaks(:,4));
                        peaks(~idx,:)=[];
                        %integrate Peak
                        peaks(:,5)=trapz(EIC(peaks(1,2):peaks(1,3)));
                        %replace peak prominence with mz of IS
                        found=[peaks(1,1),peaks(1,4),peaks(1,5),peaks(1,2:3),mindelta];
                        SampleIS(i,1:6)=found;
                        SampleIS(i,7)=IS(1,1);
                    end
                end

                SampleCells{25,1}{N,5}=SampleIS;
            end
            clearvars -except app SampleCells nSamp ZeroVec
            %check for missing IS
            for u=1:nSamp
                SampleIS=cell2mat(SampleCells{25,1}(u,5));
                Checksum=sum(SampleIS,2);
                idx(:,u)= Checksum == repmat(7,size(Checksum));
            end
            idx=any(idx,2);
            for u=1:nSamp %replace is vales if IS is not found in any sample
                if any(idx,2)== false
                    continue
                else
                    SampleCells{25,1}{u,5}(idx,:)=[];
                end
            end

        end
        %% Find ISTD ranges
        function ISTDranges(app)
            for g=1:size(app.NewResults,2) %group loop
                ISdat=app.NewResults{25,g}(:,5);
                for s=1:size(ISdat,1) % sample loop
                    nScan=numel(app.NewResults{25,g}{s,3});
                    nMZ=numel(app.NewResults{25,g}{s,2});
                    MZ=app.NewResults{25,g}{s,2};
                    IS=cell2mat(ISdat(s,1));
                    ISrt=IS(:,1);
                    if numel(ISrt) == 1 % use IS for everything if only one is selected/found
                        app.NewResults{25,g}{s,5}(9:10)=[1 nScan];
                        app.NewResults{25,g}{s,5}(11:12)=[1 nMZ];
                    else
                        %RTval
                        [~,idx] = sort(IS(:,1)); % sort rt column
                        RTsortedIS = IS(idx,:);   % sort the whole matrix using the sort indices
                        rtval=RTsortedIS(:,1);
                        rtborders = arrayfun(@(i) mean(rtval(i:i+1)),1:1:length(rtval)-1)';
                        rtborders = round(rtborders);
                        rtborderEnds=[rtborders;nScan];
                        rtborderStarts=[1;rtborders+1];
                        rtborders=[rtborderStarts rtborderEnds];
                        app.NewResults{25,g}{s,5}(:,9:10)=rtborders; %store ranges
                        %mzvalues
                        [~,idx] = sort(IS(:,7)); % sort mz column
                        mzsortedIS = IS(idx,:);   % sort the whole matrix using the sort indices
                        mzval=mzsortedIS(:,7);
                        mzborders = arrayfun(@(i) mean(mzval(i:i+1)),1:1:length(mzval)-1)'; %mean between two elements
                        mzborders=mzborders-MZ; %find indices of MZ values with minimum distance
                        [~,mzborders]=min(abs(mzborders),[],2);
                        mzborderEnds=[mzborders;nMZ]; % build ranges as 2 column matrix
                        mzborderStarts=[1;mzborders+1];
                        mzborders=[mzborderStarts mzborderEnds];
                        app.NewResults{25,g}{s,5}(:,11:12)=mzborders; %store ranges
                    end
                end
            end

        end
        %% Sort Group Features into Hypercube
        function buildFeatureHypercube(app)
            % Combine Featurelists and build splitvar
            IntResults=cellfun(@(x) x(:,1),app.Features(1,:),'UniformOutput',false); %get relevant data
            numSamps=cellfun('size',IntResults,1); %number of samples per group
            %Feature sorting preparation
            Master=cell(5,size(IntResults,2));
            newRT=cell(1,size(IntResults,2));
            newMZ=cell(1,size(IntResults,2));
            tolmz=app.mzTol.Value;
            tolRT=app.RTTol.Value;
            switch app.PeakAreaButton.Value %switch between area and Height
                case true
                    val=3; %use Peak Area
                otherwise
                    val=2; %use Peak Height
            end
            for k=1:size(IntResults,2)
                %extract all RTs
                G1=IntResults{1,k};
                rt=cell(size(IntResults{1,k},1),1);
                mz=cell(size(IntResults{1,k},1),1);
                splitRT=cell(size(IntResults{1,k},1),1);
                for n=1:size(IntResults{1,k},1)
                    rttemp=cellfun(@(X) X(:,2),G1{n,1}(4,:)','UniformOutput',false);
                    rt{n,1}=vertcat(rttemp{:});
                    mz{n,1}=cellfun(@(X) X(:,1),G1{n,1}(1,:)');
                    splitRT{n,1}=cellfun('size',G1{n,1}(2,:),1); %use number of peaks in per mz
                end
                Master{1,k}=vertcat(rt{:});
                Master{2,k}=vertcat(mz{:});
                splitsmz=cellfun(@(X) length(X),mz);
                [uniquesrt,~,idrt]=uniquetol(Master{1,k},tolRT,'DataScale',1);
                [uniquesmz,~,idmz]=uniquetol(Master{2,k},tolmz,'DataScale',1);
                RTmat=zeros(length(uniquesrt),n);
                MZmat=zeros(length(uniquesmz),n);
                SampSortrt=mat2cell(idrt,cellfun(@sum,splitRT));
                SampSortmz=mat2cell(idmz,splitsmz);
                Master{3,k}=SampSortrt;
                Master{4,k}=SampSortmz;
                Master{5,k}=splitRT;
                for h=1:length(G1)
                    RTmat(SampSortrt{h},h)=rt{h,1};
                    MZmat(SampSortmz{h},h)=mz{h,1};
                end
                %replace 0 with NaN and get new RT
                id0=RTmat==0;
                RTmat(id0)=NaN;
                newRT{1,k}=mean(RTmat,2,"omitnan");
                %replace 0 with NaN and get new RT
                id0=MZmat==0;
                MZmat(id0)=NaN;
                newMZ{1,k}=mean(MZmat,2,"omitnan");
            end
            %% over all sorting
            splitsAllrt=cellfun(@(X) length(X),newRT)';
            splitsAllmz=cellfun(@(X) length(X),newMZ)';
            [app.uniqueRT,~,idallrt]=uniquetol(vertcat(newRT{1,:}),tolRT,'DataScale',1);
            [app.uniqueMZ,~,idallmz]=uniquetol(vertcat(newMZ{1,:}),tolmz,'DataScale',1);
            GroupSortrt=mat2cell(idallrt,splitsAllrt);
            GroupSortmz=mat2cell(idallmz,splitsAllmz);
            %preallocate Cube
            Cubes=cell(size(numSamps,2),1);
            for k=1:size(numSamps,2)
                dat=IntResults{1,k};
                i2r=GroupSortrt{k,1};
                i2m=GroupSortmz{k,1};
                SampSortrt=Master{3,k};
                SampSortmz=Master{4,k};
                splitRT=Master{5,k};
                Cube=NaN(length(app.uniqueRT),length(app.uniqueMZ),numSamps(k));
                for n=1:numSamps(k)
                    y=1;
                    data=dat{n,1}(val,:); %get relevant data
                    i1r=SampSortrt{n,1};
                    i1m=SampSortmz{n,1};
                    r=i2r(i1r);
                    m=i2m(i1m);
                    rtsplit=splitRT{n,1};
                    for M=1:length(m)
                        Values=data{1,M};
                        for R=1:rtsplit(M)
                            Cube(r(y),m(M),n)=Values(R,1);
                            y=y+1;
                        end
                    end
                end
                Cubes{k,1}=Cube;
            end
            app.Features(2,:)=Cubes'; %store in Feature cell array
        end
        %% Scale Function
        function scaledata(app)
            for G=1:size(app.Features,2)
                Data=app.Features{2,G}; %get get cube
                %scale along 3rd dimension (sample), each mz/rt combo
                %scaled individually excluding NaN
                if strcmp(app.ScaleType.Text,'Centering') == 1 %only mean centering
                    Data=normalize(Data,3,'center','mean');

                elseif strcmp(app.ScaleType.Text,'Auto') == 1 %z score
                    Data=normalize(Data,3,"zscore","std");

                elseif strcmp(app.ScaleType.Text,'Pareto') == 1 %Pareto scaling
                    Data = (Data-mean(Data,3,'omitnan'))./sqrt(std(Data,0,3,'omitnan'));

                elseif strcmp(app.ScaleType.Text,'Vast') == 1 %Vast scaling
                    Data = ((Data-mean(Data,3,'omitnan'))./std(Data,0,3,'omitnan')).*(mean(Data,3,'omitnan')./std(Data,0,3,'omitnan'));

                elseif strcmp(app.ScaleType.Text,'Range') == 1 %range [min max] scaling
                    Data = (Data-mean(Data,3,'omitnan'))./(max(Data,[],3,'omitnan')-min(Data,[],3,'omitnan'));

                elseif strcmp(app.ScaleType.Text,'Level') == 1 %Level Scaling
                    Data = (Data-mean(Data,3,'omitnan'))./mean(Data,3,'omitnan');
                end
                app.Features{2,G}=Data; %store scaled data
            end
        end
        %% Combine Groups
        function BuildFeatureLists(app)
            FeatureCubes=app.Features(2,:)';
            % Gather Group Assignments
            assignments=app.Grouping{:,1:2}; %group assignment
            assignments=removecats(assignments);    %remove unused categories
            Uni=unique(assignments(:,1));
            AugFeat=cell(3,size(Uni,1));
            idx=assignments(:,1)==Uni';
            idxRef=assignments(:,2)==Uni';
            numSamps=cellfun('size', app.Features(2,:),3);
            %expand unique RT vector based on number of combined cubes
            rep=max(sum(idx));
            app.uniqueRT=repmat(app.uniqueRT,rep,1);
            %combine Datapoint Labels
            SampLabels=cell(1,length(Uni));
            CombGroups=cell(1,length(Uni));
            Augments=cell(1,length(Uni));
            newGrouping=repmat(string(missing),length(Uni),2);
            newReject=zeros(7,length(Uni));
            for n=1:size(Uni,1)
                %test if groups to combine have same number of samples
                test=numSamps(idx(:,n));
                if length(unique(test))==1
                    AugFeat{1,n}=cat(1,FeatureCubes{idx(:,n)});
                    SampLabels{1,n}=app.RawResults(1,idx(:,n));
                    CombGroups{1,n}=app.InputTable.RowName(idx(:,n));
                    CombAsign=assignments(idx(:,n));
                    newGrouping(n,1)=unique(CombAsign);
                    RefAsign=assignments(idxRef(:,n));
                    if isempty(RefAsign)==true
                        RefAsign="none";
                    end
                    newGrouping(n,2)=unique(RefAsign);
                    text(1:sum(idx(:,n)),1)=CombGroups{1,n};
                    Augments{1,n}=text;
                    newReject(:,n)=sum(app.RejectedFeat(:,idx(:,n)),2);
                else %skip combination if number of samples differ between groups
                    uialert(app.Main,'To combine groups, they must all contain the same number of samples','Skipping Augmentation','Icon','warning');
                    AugFeat(1,:)=FeatureCubes;
                    AugFeat(2,:)=app.RawResults(1,:);
                    Augments=[];
                end
            end
            app.RejectedFeat=newReject;
            if isempty(Augments)==false
                numSamps=cellfun('size', AugFeat(1,:),3);

            end
            newGrouping=categorical(newGrouping);
            app.Grouping=newGrouping;
            %combine Group Labeling Strings
            for n=1:size(CombGroups,2)
                dat=CombGroups{1,n};
                dattemp=dat{1,1};
                for g=2:size(dat,1)
                    dattemp=dattemp+"_"+dat{g,1};
                end
                CombGroups{1,n}=dattemp;
            end
            AugFeat(2,:)=SampLabels;
            AugFeat(3,:)=Augments;
            %% Preparing Feature data
            %remove not augmented Groups
            test=cellfun("size",AugFeat(3,:),1);
            idx=test==max(test);
            %Rebuild Cube
            Featdata=AugFeat(1,idx);
            Featdata=reshape(Featdata,1,1,length(Featdata));
            Featdata=cell2mat(Featdata);
            Featdata(isnan(Featdata))=0; %replace NaN with 0
            %construct row(rt) and col(mz) indices for Data Matrix
            [r,m,s]=size(Featdata);
            %reshape to 2D array where columns contain all cells from sample
            Featdata=reshape(Featdata,[],s);
            %build feature names
            Groups=AugFeat(3,:);
            nGroups=size(Groups,2);
            RTstring = repmat(app.uniqueRT,1,length(app.uniqueMZ));
            MZstring = repmat(app.uniqueMZ',size(RTstring,1),1);
            varname="";
            varname=repmat(varname,r,m,nGroups);
            for k=1:nGroups
                CGroups=Groups{1,k};
                temp=cell(length(CGroups),1);
                for j=1:length(CGroups)
                    G=convertCharsToStrings(CGroups{j,1});
                    temp{j,1}=repmat(G,length(app.uniqueRT)/rep,length(app.uniqueMZ));
                end
                G=vertcat(temp{:});
                varname(:,:,k)=MZstring + "Da, @" + RTstring + "sec, " +G;
            end
            varname=reshape(varname,size(Featdata,1),[]);

            %remove rows with no data
            zeroidx=all(Featdata==0,2);
            Featdata(zeroidx,:)=[];
            varname(zeroidx,:)=[];
            %% Filter Features with to few occurrences per group
            % split list in groups
            Featdata=mat2cell(Featdata,size(Featdata,1),numSamps);
            numRej=zeros(1,size(Featdata,1));
            for n=1:size(Featdata,2)
                idx=sum(Featdata{1,n}>0,2)<numSamps(n)*app.MinOcc.Value/100;
                numRej(1,n)=sum(idx);
                Featdata{1,n}(idx,:)=0;
            end
            app.RejectedFeat(6,:)=numRej;
            Featdata=cell2mat(Featdata);

            %remove filtered rows with no data
            zeroidx=all(Featdata==0,2);
            Featdata(zeroidx,:)=[];
            varname(zeroidx,:)=[];

            %combine sample names
            SampNames=strings(size(AugFeat{2,n}{1,1},1),size(AugFeat,2));
            for n=1:size(AugFeat,2)
                dotlabel=AugFeat{2,n};
                dotlabel=vertcat(dotlabel{:});
                dotlabel=cellfun(@(x) strsplit(x,'\'),dotlabel,'UniformOutput',false); %split string at \ to isolate Filename
                dotlabel=vertcat(dotlabel{:});
                dotlabel=dotlabel(:,end);
                dotlabel=reshape(dotlabel,[],size(AugFeat{2,n},2));
                dotlabel=string(dotlabel);
                if size(dotlabel,2)==1
                    SampNames(:,n)=dotlabel;
                else
                    for f=1:size(dotlabel,2)-1
                        dotlabel(:,1)=dotlabel(:,1)+" + "+dotlabel(:,f+1);
                    end
                    dotlabel(:,2:end)=[];
                    SampNames(:,n)=dotlabel;
                end
            end
            %store
            Results{1,1}=Featdata;
            Results{1,2}=varname;
            Results{1,3}=SampNames;
            Results{1,4}=CombGroups;
            app.Features=Results;
            %get numeric mz and RT for all features
            varname=varname(:,1);
            varname=varname';
            varname=cellfun(@(x) strsplit(x,{'D','@','s'}),varname,'UniformOutput',false); %split string at \ to isolate Filename
            varname=vertcat(varname{:});
            mz=varname(:,1);
            rt=varname(:,3);
            mz=convertCharsToStrings(mz);
            mz=str2double(mz);
            rt=convertCharsToStrings(rt);
            rt=str2double(rt);
            app.NumericFeatDat=[mz,rt];
        end
        %% PCA
        function PerformPCA(app)
            %preparation
            %get number of Samples of every group
            numSamps=cellfun("size", app.Features{1,3},1);
            numSamps=sum(numSamps);
            cumSamps=cumsum(numSamps);
            color=lines(length(numSamps)); %default color definition for gscatter
            labels=app.Features{1,4}; %get group labels
            labels=string(labels)'; %unpack cell array
            replabels=cell(numSamps(1),1); %preallocate then build cellarray for gscatter grouping
            repColor=cell(length(numSamps),1);
            for n=1:length(numSamps)
                replabels{1,n}=repmat(labels(n,:),numSamps(n),1);
                repColor{1,n}=repmat(color(n,:),numSamps(n),1);
            end
            replabels=vertcat(replabels{:}); %unpack cellarray
            repColor=vertcat(repColor{:}); %unpack cellarray
            dotlabel=app.Features{1,3};
            dotlabel=reshape(dotlabel,[],1);
            dx=0.1; %datalabel offset
            dy=0.1;
            %% perform pca
            PCAdata=app.Features{1,1}'; %transpose so observations = rows, variables = columns
            [coeff,score,~,~,explained]=pca(PCAdata);
            % chek for meaningfull result
            if size(score,2)==1
                uialert(app.Main,["Only one PC calculated"; "Add more Samples or use less restrictive Filters"],'Bad Result','Icon','warning');
                return
            end
            % plot
            % build figure
            PCAres=uifigure("Name","PCA Score Plots");
            t=tiledlayout(PCAres,2,2);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            s1=nexttile(t);
            % PC1 vs 2
            gscatter(s1,score(:,1),score(:,2),replabels,color);
            text(s1,score(:,1)+dx,score(:,2)+dy,dotlabel,'Fontsize',5,'Interpreter','none')
            s1.XAxisLocation='Origin';
            s1.YAxisLocation='Origin';
            xlabel(s1,'PC1')
            ylabel(s1,'PC2')
            title(s1,'Scoreplot PC1 vs PC2')
            grid(s1,'on')
            % PC1 vs 3
            if size(score,2)>=3
                s2=nexttile(t);
                gscatter(s2,score(:,1),score(:,3),replabels,color);
                text(s2,score(:,1)+dx,score(:,3)+dy,dotlabel,'Fontsize',5,'Interpreter','none')
                s2.XAxisLocation='Origin';
                s2.YAxisLocation='Origin';
                xlabel(s2,'PC1')
                ylabel(s2,'PC3')
                title(s2,'Scoreplot PC1 vs PC3')
                grid(s2,'on')
                % PC2 vs 3
                s3=nexttile(t);
                gscatter(s3,score(:,2),score(:,3),replabels,color);
                text(s3,score(:,2)+dx,score(:,3)+dy,dotlabel,'Fontsize',5,'Interpreter','none')
                s3.XAxisLocation='Origin';
                s3.YAxisLocation='Origin';
                xlabel(s3,'PC2')
                ylabel(s3,'PC3')
                title(s3,'Scoreplot PC2 vs PC3')
                grid(s3,'on')
            end
            %Explained Variance
            s4=nexttile(t);
            yyaxis(s4,"left")
            bar(s4,explained);
            ylabel(s4,'individual [%]')
            yyaxis(s4,"right")
            plot(s4,cumsum(explained),'LineWidth',2)
            title(s4,'Explained Variance')
            ylabel(s4,'Cumulative [%]')
            xlabel(s4,'Principle Component')
            %3D scatter
            if size(score,2)>=3
                s3D=uifigure("Name","3D Score Plot");
                s3ax=uiaxes(s3D,'Position',[10 10 550 400]);
                scatter3(s3ax,score(1:numSamps(1),1),score(1:numSamps(1),2),score(1:numSamps(1),3),50,repColor(1:numSamps(1),:),'filled')
                hold(s3ax,"on")
                for n=1:length(numSamps)-1
                    scatter3(s3ax,score(cumSamps(n)+1:cumSamps(n+1),1),score(cumSamps(n)+1:cumSamps(n+1),2),score(cumSamps(n)+1:cumSamps(n+1),3),50,repColor(cumSamps(n)+1:cumSamps(n+1),:),'filled')
                end
                hold(s3ax,"off")
                s3ax.XAxisLocation='Origin';
                s3ax.YAxisLocation='Origin';
                legend(s3ax,labels,'Interpreter','none')
                title(s3ax,"3D Score Plot")
                grid(s3ax,'on')
            end
            %biplots
            %extract 10 highest loadings
            bigload=sum(abs(coeff),2);
            [~,idx]=maxk(bigload,10);
            bigcoeff=coeff(idx,:);
            %get Varnames
            loadingNames=app.Features{1,2}(idx);
            %create plot
            PCAbi=uifigure("Name","PCA Biplots");
            t2=tiledlayout(PCAbi,2,2);
            t2.TileSpacing = 'compact';
            t2.Padding = 'compact';
            sb1=nexttile(t2);
            biplot(sb1,bigcoeff(:,1:2),'Scores',score(:,1:2),'Varlabels',loadingNames)
            title(sb1,'Biplot PC1 vs PC2')
            if size(score,2)>=3
                sb2=nexttile(t2);
                biplot(sb2,[bigcoeff(:,1),bigcoeff(:,3)],'Scores',[score(:,1),score(:,3)],'Varlabels',loadingNames)
                title(sb2,'Biplot PC1 vs PC3')
                sb3=nexttile(t2);
                biplot(sb3,bigcoeff(:,2:3),'Scores',score(:,2:3),'Varlabels',loadingNames)
                title(sb3,'Biplot PC2 vs PC3')
                sb4=nexttile(t2);
                biplot(sb4,bigcoeff(:,1:3),'Scores',score(:,1:3),'Varlabels',loadingNames)
                title(sb4,'Biplot PC1 vs PC2 vs PC3')
            end
        end
        %% Compare groups
        function Statistify(app)
            Featurelist=app.Features{1,1};
            SampleLabels=app.Features{1,3};
            Groups=app.Features{1,4};
            GroupFlags=app.Grouping;
            %preparations
            Uniques=cell(length(Groups),1);
            SplitFeat=cell(length(Groups),9);
            %Split Feature Matrix into Groups
            numSamps=cellfun("size", SampleLabels,1);
            nFeat=size(Featurelist,1);
            SplitFeat(:,1)=mat2cell(Featurelist',sum(numSamps),nFeat);
            %calculate Mean and Std for all groups
            SplitFeat(:,2)=cellfun(@(x) mean(x,1,'omitnan'),SplitFeat(:,1),'UniformOutput',false);
            SplitFeat(:,3)=cellfun(@(x) std(x,[],1,'omitnan'),SplitFeat(:,1),'UniformOutput',false);
            %get Group Flags
            assignments=GroupFlags; %group assignment
            assignments=removecats(assignments);    %remove unused categories
            Uni=unique(assignments(:,1));
            Ref=assignments(:,2)==Uni'; % row = refGroup, Col = SampleGroup
            [RefGr,SaGr]=find(Ref);

            %perform Reference scaling

            if any(any(Ref,2))==true
                notInRef=cell(length(Groups),1);
                for n=1:length(SaGr)
                    ref=SplitFeat{RefGr(n),3};
                    idx=ref~=0;
                    samp=SplitFeat{SaGr(n),3};
                    idx(2,:)=samp~=0;
                    samp(idx(1,:))=samp(idx(1,:))./ref(idx(1,:));
                    SplitFeat{SaGr(n),3}=samp;
                    notInRef{SaGr(n),1}=idx(1,:)==false & idx(2,:)==true;
                end
            else
                text="no Reference Group";
                notInRef{1,1}=repmat(text,1,size(Featurelist,1));
                notInRef=repmat(notInRef,length(Groups),1);
            end
            SplitFeat(:,8)=notInRef;
            %perform comperative statistics
            if size(Groups,2)==1
                return
            else
                data=SplitFeat(:,1);
                Combis=nchoosek(1:1:size(Groups,2),2);
                Combis2=nchoosek(size(Groups,2):-1:1,2);
                Combis=[Combis;Combis2];
                ps=cell(size(Combis,1),1);
                folds=cell(size(Combis,1),1);
                for n=1:size(Combis,1)
                    x=data{Combis(n,1)};
                    y=data{Combis(n,2)};
                    if size(x,1)==1
                        fold=x./y;
                    else
                        fold=(mean(x)./mean(y));
                    end
                    fold(isinf(fold))=0;
                    fold(isnan(fold))=0; %replace possible NaN and Inf with 0;
                    [~,p]=ttest2(x,y,"Dim",1);
                    if app.Hochberg.Value==true
                        [pnew,sortParam]=sort(p,'ascend');
                        sortParam(isnan(pnew))=[];
                        pnew(isnan(pnew))=[];
                        rank=1:1:length(pnew);
                        ptest=rank./length(rank)*app.FDR.Value/100;
                        idx=pnew<ptest;
                        sortParam(~idx)=[];
                        pnew=nan(1,length(p));
                        pnew(sortParam)=p(sortParam);
                        p=pnew;
                    end
                    ps{n,1}=p;
                    folds{n,1}=fold;
                end
            end

            %sort values back to corresponding groups
            for n=1:size(Groups,2)
                idx=Combis(:,1)==n;
                SplitFeat{n,9}=Combis(idx,:); %store index of compared groups
                SplitFeat{n,4}=vertcat(folds(idx));
                p=vertcat(ps(idx));
                SplitFeat{n,5}=p;
                p=cell2mat(p);
                p(isnan(p))=1;
                SplitFeat{n,6}=p<=app.pVal.Value;%get index for significant features
                x=data{n,1};
                y=data;
                y(n,:)=[];
                y=vertcat(y{:});
                Uniques{n,1}=mean(x,1)~=0 & mean(y,1)==0; %index of group specific features
            end
            SplitFeat(:,7)=Uniques;
            Sig=cellfun(@(X) sum(~any(X,1)),SplitFeat(:,6));
            app.RejectedFeat(7,:)=Sig';
            app.Stats=SplitFeat;
            app.Volcano.Enable = true;
            app.ScatterPlot.Enable = true;
            app.Cloud.Enable = true;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ROIResults)
            %Check if RAW data is present and remove it to save Memory
            ContainRawData = cellfun('size', ROIResults(1,:),2);
            ContainRawBLK = cellfun('size', ROIResults(2,:),2);
            for n=1:size(ROIResults,2)
                if ContainRawData(1,n)==2
                    ROIResults{1,n}(:,2)=[];
                end
                if ContainRawBLK(1,n)==2
                    ROIResults{2,n}(:,2)=[];
                end
            end
            app.RawResults=ROIResults;
            nG=size(app.RawResults,2);
            % get scan frequency for every sample
            for g=1:nG
                times=cell2mat(app.RawResults{25,g}(:,3)');
                times=diff(times);
                times=mean(times);
                times=num2cell(times);
                app.RawResults{25,g}(:,4)=times;
            end
            %build input table
            pos=[602,171,657,348];
            colName={'Groups to Combine:';'Reference Group:';'Group Scaling Factor'};
            RowName=app.RawResults(38,:);
            ToolTip={'Define the relationship of the groups for data processing. Groups with the same label are processed together. Combined groups are treated as one group and compared with the others. By specifying a reference group, groups with the same label are normalized to the reference group.'};
            catsB="none"; % Categories for Reference Column
            catsB=repmat(catsB,[nG,1]);
            catsB=categorical(catsB,["none","A","B","C","D","E","F","G","H","I","J"]);
            catsA=["A";"B";"C";"D";"E";"F";"G";"H";"I";"J"]; % Categories for Combine Column
            catsA(nG+1:end)=[]; % remove Rows > number of Groups
            catsB(nG+1:end)=[];
            catsA=categorical(catsA,["A","B","C","D","E","F","G","H","I","J"]);
            RowName(nG+1:end)=[];
            scale=ones(nG,1);
            tdata=table(catsA,catsB,scale,'VariableNames',colName,'RowNames',RowName);
            app.InputTable = uitable(app.Main,'Data',tdata,"Position",pos,"ColumnEditable",true,"SelectionType","cell","Tooltip",ToolTip);
            app.NewResults=cell(38,size(app.RawResults,2));
            app.Features=cell(1,size(app.RawResults,2));
            % Build Sample Scaling Factors array
            app.SampScaleVal=cell(1,nG);
            nSamp=cellfun('size', app.RawResults(1,:),1);
            for G=1:nG
                app.SampScaleVal{1,G}=ones(nSamp(G),2);
            end
            app.ScaleType = app.ScalingOptionsButtonGroup.SelectedObject;
            app.TransType = app.TransformationsButtonGroup.SelectedObject;
            app.RejectedFeat = zeros(7,nG);
            app.iEntropy=cell(1,2);
            %% Build Mass Filter calculation Rules
            % Calculation Rules (convert to M+H mass)
            %Adduct positve Mode
            app.AddPosRules = {@(x) (x - 18.033823 + 1.007276);         % M+NH4
                @(x) (x - 22.989218 + 1.007276);                       % M+Na
                @(x) (x - 38.963158 + 1.007276);                       % M+K
                @(x) (x - 42.033823);                                  % M+ACN+H
                @(x) (x - 33.033489);                                  % M+CH3OH+H
                @(x) (x - 61.065340);                                  % M+IPA+H
                @(x) (x - 79.021220);                                  % M+DMSO+H
                @(x) (x - 42.033823 - 22.989218 + 1.007276);           % M+ACN+Na
                @(x) (x - 42.033823*2);                                % M+2ACN+H
                @(x) (x - 61.065340 - 22.989218 + 1.007276);           % M+IPA+Na
                @(x) (x - 22.989218*2 + 1.007276*2);                   % M+2Na-H
                @(x) (x - 38.963158*2 + 1.007276*2);                   % M+2K-H
                @(x) ((x - 1.007276)/2 + 1.007276);                    % 2M+H
                @(x) ((x - 18.033823)/2 + 1.007276);                   % 2M+NH4
                @(x) ((x - 22.989218)/2 + 1.007276);                   % 2M+Na
                @(x) ((x - 38.963158)/2 + 1.007276);                   % 2M+K
                @(x) ((x - 42.033823-1.007276)/2 + 1.007276);          % 2M+ACN+H
                @(x) ((x - 42.033823-22.989218)/2 + 1.007276);         % 2M+ACN+Na
                @(x) ((x - 1.007276*2)*2 + 1.007276);                  % M+2H
                @(x) ((x - 1.007276-18.033823)*2 + 1.007276);          % M+H+NH4
                @(x) ((x - 1.007276-22.989218)*2 + 1.007276);          % M+H+Na
                @(x) ((x - 1.007276-38.963158)*2 + 1.007276);          % M+H+K
                @(x) ((x - 22.989218*2)*2 + 1.007276);                 % M+2Na
                @(x) ((x - 42.033823 - 1.007276*2)*2 + 1.007276);      % M+ACN+2H
                @(x) ((x - 42.033823*2 - 1.007276*2)*2 + 1.007276);    % M+2ACN+2H
                @(x) ((x - 42.033823*3 - 1.007276*2)*2 + 1.007276);    % M+3ACN+2H
                @(x) ((x - 1.007276*3)*3 + 1.007276);                  % M+3H
                @(x) ((x - 1.007276*2 - 22.989218)*3 + 1.007276);      % M+2H+Na
                @(x) ((x - 1.007276 - 22.989218*2)*3 + 1.007276);      % M+H+2Na
                @(x) ((x - 22.989218*3)*3 + 1.007276);                 % M+3Na
                };

            %Adduct negativ Mode
            app.AddNegRules = {@(x) (x - 34.968853 - 1.007276);         % M+Cl35
                @(x) (x - 36.965903 - 1.007276);                       % M+Cl37
                @(x) (x - 78.918336 - 1.007276);                       % M+Br79
                @(x) (x - 80.916290 - 1.007276);                       % M+Br81
                @(x) (x - 46.005480);                                  % M+Fa-H
                @(x) (x - 60.021130);                                  % M+HAc-H
                @(x) (x - 82.003075);                                  % M+NaAc-H
                @(x) (x - 77.047679);                                  % M+NH4Ac-H
                @(x) (x - 113.992864);                                 % M+TFA-H
                @(x) (x - 22.989218 + 1.007276);                       % M+Na-2H
                @(x) (x - 18.034374 + 1.007276);                       % M+NH4-2H
                @(x) (x - 38.963158 + 1.007276);                       % M+K-2H
                @(x) ((x + 1.007276)/2 - 1.007276);                    % 2M-H
                @(x) ((x - 46.005480 + 1.007276)/2 - 1.007276);        % 2M+Fa-H
                @(x) ((x - 60.021130 + 1.007276)/2 - 1.007276);        % 2M+HAc-H
                @(x) ((x + 1.007276)/3 - 1.007276);                    % 3M-H
                @(x) ((x + 1.007276*2)*2 - 1.007276);                  % M-2H
                @(x) ((x + 1.007276*2)*2 - 1.007276);                  % M-3H
                };

            % Neutral loss rules
            app.NLossRules = {@(x) (x - 17.026549);                     % -NH3
                @(x) (x + 18.010565);                                  % -H2O
                @(x) (x + 27.010899);                                  % -HCN
                @(x) (x + 27.994915);                                  % -CO
                @(x) (x + 28.018724);                                  % -H2CN
                @(x) (x + 29.997989);                                  % -NO
                @(x) (x + 30.010565);                                  % -CH2O
                @(x) (x + 32.026215);                                  % -CH4O
                @(x) (x + 33.987722);                                  % -H2S
                @(x) (x + 35.976678);                                  % -HCl
                @(x) (x + 43.989830);                                  % -CO2
                @(x) (x + 45.992904);                                  % -NO2
                @(x) (x + 46.005480);                                  % -CH2O2
                @(x) (x + 63.961902);                                  % -SO2
                @(x) (x + 79.966333);                                  % -HPO3
                @(x) (x + 81.972467);                                  % -H2SO3
                @(x) (x + 97.976898);                                  % -H2PO4
                @(x) (x + 127.912302);                                 % -HI
                @(x) (x + 81.045273);                                  % -Histidine
                @(x) (x + 121.019751);                                 % -cysteine
                @(x) (x + 130.062995);                                 % -dideoxyhexoside
                @(x) (x + 132.042260);                                 % -pentoside
                @(x) (x + 146.057910);                                 % -deoxyhexoside
                @(x) (x + 146.069143);                                 % -glutaminic acid
                @(x) (x + 162.052824);                                 % -hexoside
                @(x) (x + 163.030316);                                 % -N-acetylcysteine
                @(x) (x + 164.068475);                                 % -rhamonoside
                @(x) (x + 176.032090);                                 % -glucuronide
                @(x) (x + 194.042655);                                 % -glucuronide (benzylic)
                @(x) (x + 203.079374);                                 % -N-acetylglucosamine (benzylic)
                @(x) (x + 221.089939);                                 % -N-acetylglucosamine
                @(x) (x + 248.053220);                                 % -malonylglucuronide
                @(x) (x + 250.062345);                                 % -gamma-GluCys
                @(x) (x + 266.063785);                                 % -malonylglucuronide (benzylic)
                @(x) (x + 307.083809);                                 % -gluthatione
                };

            app.IsotopeRules=[0.997035 , 0.0036;
                0.999387 , 0.0075;
                1.003355 , 0.0107;
                1.994105 , 0.0425;
                2.004244 , 0.0021];
        end

        % Button pushed function: ExtractFeaturesButton
        function ExtractFeaturesButtonPushed(app, event)
            diag = uiprogressdlg(app.Main,'Title','Please Wait','Message','Extracting Feature Data','Indeterminate','on');
            %gather necessary data
            app.Features=[];
            app.Grouping=app.InputTable.Data;
            [~,c]=size(app.RawResults); % number of groups
            app.EvalParam = {app.MinPeakH.Value*0.01 app.MinPeakW.Value app.MaxPeakW.Value}; %Integration Parameters
            ZeroISVec=ones(1,5);
            ZeroISVec=[ZeroISVec 0]; %Values if no IS is selected or not found
            for G = 1:c
                %preparations
                [nSamp,~]=size(app.RawResults{25,G});
                Data=app.RawResults(:,G);
                %swith BLK ISTD order
                if app.BLK_then_ISTD.Value	== true && app.RawResults{4,G} == true
                    Data = subBLK(app,Data);
                end
                %ISTD case Switch
                I = app.ISTDnormalizationDropDown.Value;
                I=str2double(I);
                switch I
                    case 0 %no IS normalization -> set all IS parameters to 1
                        for S=1:nSamp
                            app.RawResults{25,G}{S,5}=ZeroISVec;
                            app.NewResults(:,G)=app.RawResults(:,G);
                        end %store ISTD values as 1
                    case 1 %only ISTD normalisation for Sample Files
                        IS=app.RawResults{24,G}; %if no IS specified set IS values to 1
                        if isempty(IS)==true
                            for S=1:nSamp
                                app.RawResults{25,G}{S,5}=ZeroISVec;
                                app.NewResults(:,G)=app.RawResults(:,G);
                            end
                        else
                            app.RawResults{:,G}=getISTDvals(app,Data);
                            app.RawResults{25,G}{end,5}=ZeroISVec; %set ISTD values for Blank files = 1
                            app.NewResults(:,G)=app.RawResults(:,G);
                        end
                    otherwise % ISTD normalization for Sample and Blank
                        IS=app.RawResults{24,G};
                        if isempty(IS)==true
                            for S=1:nSamp
                                app.RawResults{25,G}{S,5}=ZeroISVec;
                                app.NewResults(:,G)=app.RawResults(:,G);
                            end
                        else
                            Data=getISTDvals(app,Data);
                            app.NewResults(:,G)=Data;
                        end
                end

            end
            %% Get ISTD Ranges
            ISTDranges(app);
            %% IS Normalization Matrix Stage
            switch app.ISTD_then_BLK.Value
                case true
                    for G = 1:c %group loop
                        for s = 1:size(app.NewResults{25,G},1) %sample Loop
                            for t = 1:size(size(app.NewResults{25,G}{s,5},1)) %IS loop
                                ISheight = app.NewResults{25,G}{s,5}(t,2);
                                ISstart = app.NewResults{25,G}{s,5}(t,9);
                                ISend = app.NewResults{25,G}{s,5}(t,10);
                                app.NewResults{25,G}{s,1}(ISstart:ISend,:)=app.NewResults{25,G}{s,1}(ISstart:ISend,:) ./ ISheight;
                            end
                        end
                    end
            end
            %% Mass Calibration Loop
            switch app.MZCal.Value
                case true
                    for G = 1:c %group loop
                        for s = 1:size(app.NewResults{25,G},1) %sample Loop
                            for i = 1:size(size(app.NewResults{25,G}{s,5},1)) %IS loop
                                mzdelta = app.NewResults{25,G}{s,5}(i,6);
                                mzstart = app.NewResults{25,G}{s,5}(i,11);
                                mzend = app.NewResults{25,G}{s,5}(i,12);
                                app.NewResults{25,G}{s,2}(1,mzstart:mzend)=app.NewResults{25,G}{s,2}(1,mzstart:mzend) + mzdelta;
                            end
                        end
                    end
            end
            %% BlankSubtraction after ISTDNorm Loop
            Data = app.NewResults(25,:);
            isSub = app.RawResults(4,:);
            blk = app.ISTD_then_BLK.Value;
            for G = 1:c
                if blk	== true && isSub{1,G} == true
                    Data(1,G) = subBLK(app,Data(1,G));
                end
            end
            app.NewResults(25,:)=Data;
            %% Peak Finding and Integration
            Data=app.NewResults(25,:);
            FData=cell(1,c);
            AllEntropySN=cell(1,c);
            Ent=zeros(1,c);
            MinPH=app.EvalParam{1,1};
            MinPWS=app.EvalParam{1,2};
            MaxPWS=app.EvalParam{1,3};
            mSN=app.minSN.Value;
            Entro=app.EntroFilt.Value;
            for G = 1:c
                [Out,Ent] = CWTIntegrate(app,Data{1,G},MinPH,MinPWS,MaxPWS,mSN,Entro);
                sumReject=cellfun(@(X) sum(cell2mat(X(7:11,:)),2),Out(:,1),'UniformOutput',false);
                sumReject=cell2mat(sumReject');
                sumReject=sum(sumReject,2);
                app.RejectedFeat(1:5,G)=sumReject;
                allEntSN=cellfun(@(X) vertcat(X(5,:)'),Out,'UniformOutput',false);
                allEntSN(:,2)=[];
                allEntSN=allEntSN{:};
                allEntSN=vertcat(allEntSN{:});
                AllEntropySN{1,G}=allEntSN;
                FData{1,G}=Out;
                Ent(1,G)=mean(Ent);
            end
            app.iEntropy{1,1}=Ent;
            app.iEntropy{1,2}=AllEntropySN;
            app.Features=FData;
            %% Filter isotopes, adducts, Neutral loss Fragments
            for G = 1:c
                if app.RawResults{7,G}==true % Isotopes
                    Gdata=app.Features{:,G};
                    mztol=app.mzTol.Value;
                    Rules=app.IsotopeRules;
                    parfor n=1:size(Gdata,1)
                        Gdata{n,1} = IsotopeFilterAlgo(Gdata{n,1},Rules,mztol)
                    end
                    app.Features{:,G}=Gdata;
                end
                if app.RawResults{8,G}==true
                    Gdata=app.Features{:,G}; % Adducts + neutral loss
                    mztol=app.mzTol.Value;
                    Selection=app.RawResults{26,G};
                    CosSim=Selection(end);
                    Selection(end)=[];
                    Selection=logical(Selection);
                    if app.RawResults{3,G} == "positive"
                        Rules=[app.AddPosRules;app.NLossRules];
                    else
                        Rules=[app.AddNegRules;app.NLossRules];
                    end
                    Rules=Rules(Selection);
                    parfor n=1:size(Gdata,1)
                        Gdata{n,1} = AdductFilterAlgo(Gdata{n,1},Rules,mztol,CosSim)
                    end
                    app.Features{:,G}=Gdata;
                end
            end
            %% IS Normalization Feature Stage
            switch app.BLK_then_ISTD.Value
                case true
                    for G = 1:c %group loop
                        for s=1:size(app.Features{1,G}) %sample loop
                            for f=1:size(app.Features{1,G}{s,1},2) %feature loop
                                % height
                                app.Features{1,G}{s,1}{2,f}=app.Features{1,G}{s,1}{2,f} ./ app.NewResults{25,G}{s,5}(1,2);
                                % area
                                app.Features{1,G}{s,1}{3,f}(:,1)=app.Features{1,G}{s,1}{3,f}(:,1) ./ app.NewResults{25,G}{s,5}(1,3);
                            end
                        end
                    end
            end

            %gather Group and sample specific norm factors and multiply
            %into one factor
            for G = 1:c %group loop
                app.SampScaleVal{1,G}(:,1)=app.SampScaleVal{1,G}(:,1).*app.InputTable.Data{G,3};
            end

            %Group+SampleNorm
            for G = 1:c %group loop
                for s=1:size(app.Features{1,G}) %sample loop
                    for f=1:size(app.Features{1,G}{s,1},2) %feature loop
                        % height
                        app.Features{1,G}{s,1}{2,f}=app.Features{1,G}{s,1}{2,f} ./ app.SampScaleVal{1,G}(s,1);
                        % area
                        app.Features{1,G}{s,1}{3,f}(:,1)=app.Features{1,G}{s,1}{3,f}(:,1) ./ app.SampScaleVal{1,G}(s,1);
                    end
                end
            end
            % build Feature Cubes
            buildFeatureHypercube(app);
            %% Transform
            if strcmp(app.TransType.Text,'log') == 1
                app.Features(2,:)=cellfun(@(x) log(x),app.Features(2,:),'UniformOutput',false);
                for G=1:size(app.Features,2)
                    app.Features{2,G}=normalize(app.Features{2,G},3,'center','mean'); %centering
                end
            elseif strcmp(app.TransType.Text,'power') == 1
                app.Features(2,:)=cellfun(@(x) sqrt(x),app.Features(2,:),'UniformOutput',false);
                app.Features{2,G}=normalize(app.Features{2,G},3,'center','mean'); %centering
            end
            %% ScaleNorm
            if strcmp(app.ScaleType.Text,'none') ~= 1 %check if Scaling is enabled
                scaledata(app)
            end
            BuildFeatureLists(app)
            %check if all Features are empty
            if isempty(app.Features{1,1})==true
                uialert(app.Main,["No Features found";"Try raising the mz and time tolerance,"; "different Peak identification parameters,"; "or less restrictive Feature Filters"],'Error','Icon','error');
                return
            end

            %% Calculate Statistics
            Statistify(app)
            app.ExportResultsButton.Enable = true;
            app.PCAButton.Enable = true;
            app.HeatmapButton.Enable = true;
            app.Reject.Enable = true;
            app.EntroHist.Enable = true;
            close(diag)
            uialert(app.Main,'Task Complete','Feature Extraction','Icon','success');
        end

        % Button pushed function: ExportResultsButton
        function ExportResultsButtonPushed(app, event)

            if size(app.RawResults,2)~=1 %output when multiple groups present
                samplenames=app.Features{1,3}';
                tabledata=mat2cell(app.Features{1,2},size(app.Features{1,2},1),ones(size(app.Features{1,2},2),1));
                tabledata=tabledata';
                tabledata=cellfun(@(X) transpose(X),tabledata,'UniformOutput',false);
                tabledata=[tabledata, app.Stats(:,1:8)];
                tabledata=cellfun(@(X) transpose(X),tabledata,'UniformOutput',false);
                folddata=cell(size(app.Stats,1),3);
                for k=1:size(app.Stats,1)
                    tabledata{k,10}=sum(tabledata{k,2}~=0,2);
                    tabledata{k,11}=sum(horzcat(tabledata{:,2})~=0,2);
                    % convert folds and p into table
                    fold=tabledata{k,5};
                    fold=vertcat(fold{:});
                    fold=fold';
                    p=tabledata{k,6};
                    p=vertcat(p{:});
                    p=p';
                    p(isnan(p))=1;
                    signif=tabledata{k,7};
                    %build column names
                    asign=app.Stats{k,9};
                    colnames="";
                    for z=1:size(asign,1)
                        colnames(1,z)=app.Features{1,4}(asign(z,1)) + " vs. "+app.Features{1,4}(asign(z,2));
                    end
                    fold=array2table(fold,"VariableNames",colnames);
                    p=array2table(p,"VariableNames",colnames);
                    signif=array2table(signif,"VariableNames",colnames);
                    folddata{k,1}=fold;
                    folddata{k,2}=p;
                    folddata{k,3}=signif;
                end
                % get numeric mz and rt values
                feat=app.NumericFeatDat;
                feat=mat2cell(feat,size(feat,1),ones(size(feat,2),1));
                feat=repmat(feat,size(tabledata,1),1);
                tabledata(:,12:13)=feat;
                % Label Peak Height or Area
                if app.PeakAreaButton.Value==true
                    evalparameter="Peak Area";
                else
                    evalparameter="Peak Height";
                end
                colnames=["Feature", "Sample "+evalparameter,"Avg. "+evalparameter, "Standard Deviation","Fold Changes","p-Values","Significant Feature","Unique Feature in Group","Feature not found in Reference","Occurences in Group","Occurences over all","mz","Retention Time"];
                for p=1:size(app.Stats,1)
                    check=max(cellfun(@(X) numel(X),samplenames),[],"all")>63;
                    if check == true
                        samplenames=repmat("CombinedSample",size(samplenames));
                        nums=1:1:size(samplenames,2);
                        nums=repmat(nums,size(samplenames,1),1);
                        samplenames=samplenames+nums;
                    end
                    sampledata=array2table(tabledata{p,2},"VariableNames",samplenames(p,:));
                    folds=folddata{p,1};
                    ps=folddata{p,2};
                    signif=folddata{p,3};
                    Out=table(tabledata{p,1},sampledata,tabledata{p,3},tabledata{p,4},folds,ps,signif,tabledata{p,8},tabledata{p,9},tabledata{p,10},tabledata{p,11},tabledata{p,12},tabledata{p,13},'VariableNames',colnames);
                    id=Out{:,10}==0; %remove empty features in this group
                    Out(id,:)=[];
                    outputname="FeatureTable_"+app.Features{1,4}(p);
                    match = [" ","ü","ö","ä","<",">","-","!","%","$","&","/","(",")","=","?";];
                    outputname=erase(outputname,match);
                    assignin("base",outputname,Out)
                end
            else
                samplenames=app.Features{1,3}';
                FeatureNames=app.Features{1,2};
                FeatureData=app.Features{1,1};
                feat=app.NumericFeatDat;
                Avg=mean(FeatureData,2);
                deviation=std(FeatureData,[],2);
                Occurence=sum(FeatureData~=0,2);
                if app.PeakAreaButton.Value==true
                    evalparameter="Peak Area";
                else
                    evalparameter="Peak Height";
                end
                sampledata=array2table(FeatureData,"VariableNames",samplenames);
                colnames=["Feature", "Sample "+evalparameter,"Avg. "+evalparameter, "Standard Deviation","Occurences in Group","mz","Retention Time"];
                Out=table(FeatureNames,sampledata,Avg,deviation,Occurence,feat(:,1),feat(:,2),'VariableNames',colnames);
                outputname="FeatureTable_"+ app.Features{1,4};
                match = [" ","ü","ö","ä","<",">","-","!","%","$","&","/","(",")","=","?";];
                outputname=erase(outputname,match);
                assignin("base",outputname,Out)
            end
            uialert(app.Main,'Export Complete','Success','Icon','success');
        end

        % Callback function
        function IntResButtonPushed(app, event)
            % d = uialert(app.Main,'In develepment','WIP','Icon','info');
            %plots all EIC and Integration ranges for all Samples
            % c=size(app.Features,2);
            % for G=1:c
            %     for s=1:size(app.Features{1,G},1)
            %         IntData=app.Features{1,G}{s,1}(6,:);
            %         mzdata=app.Features{1,G}{s,1}(1,:);
            %         areaData=app.Features{1,G}{s,1}(3,:);
            %         timeData=app.Features{1,G}{s,2};
            %         locdata=app.Features{1,G}{s,1}(4,:);
            %         for n=1:size(mzdata,2)
            %             figure
            %             EIC=IntData{1,n};
            %             plot(timeData,EIC);
            %             title("EIC for Group "+G+" Sample "+s+",  ROI " + num2str(mzdata{1,n}) + " Da");
            %             xlabel("Time [sec]")
            %             ylabel("Intensity [arb]")
            %             if isempty(areaData{1,n})==true
            %                 continue
            %             else
            %                 multiEIC=repmat(EIC,1,size(areaData{1,n},1)); %repeat EIC for simultanious plotting
            %                 for p=1:size(areaData{1,n},1)
            %                     areastart=areaData{1,n}(p,2);
            %                     areaEnd=areaData{1,n}(p,3);
            %                     multiEIC([1:areastart-1 areaEnd+1:end],p)=0; % set values outside integration range to 0
            %                 end
            %                 loc=locdata{1,n}(:,1);
            %                 hold on
            %                 area(timeData,multiEIC)
            %                 plot(timeData,EIC,'v','MarkerIndices',loc,'MarkerFaceColor','black','MarkerEdgeColor','black')
            %             end
            %         end
            %     end
            % end
            % close(d)
        end

        % Button pushed function: DefineSampleSpecificScalingFactorsButton
        function DefineSampleSpecificScalingFactorsButtonPushed(app, event)
            nGroups=size(app.RawResults,2);
            maxSamp=cellfun('size', app.RawResults(1,:),1);
            maxSamp=max(maxSamp);
            app.SampScaleApp = DefSampScale(AriumMSEval, nGroups, maxSamp);
        end

        % Close request function: Main
        function MainCloseRequest(app, event)
            delete(app.SampScaleApp)
            delete(app)
        end

        % Selection changed function: ScalingOptionsButtonGroup
        function ScalingOptionsButtonGroupSelectionChanged(app, event)
            app.ScaleType = app.ScalingOptionsButtonGroup.SelectedObject;
        end

        % Button pushed function: PCAButton
        function PCAButtonPushed(app, event)
            d = uiprogressdlg(app.Main,'Title','Performing Proncipal Component Analysis','Message','Please Wait','Indeterminate','on');
            PerformPCA(app)
            close(d)
        end

        % Value changed function: Hochberg
        function HochbergValueChanged(app, event)
            app.FDR.Visible = app.Hochberg.Value;
            app.FalseDiscoveryRateEditFieldLabel.Visible=app.Hochberg.Value;
        end

        % Button pushed function: HeatmapButton
        function HeatmapButtonPushed(app, event)
            SampleData=app.Features{1,1};
            GroupData=vertcat(app.Stats{:,2});
            GroupData(isnan(GroupData))=0;
            GroupLabels=app.Features{1,4};
            SampleLabels=reshape(app.Features{1,3},[],1);
            %correlation between Samples
            corrSamp=corrcoef(SampleData);
            %Correlation between Groups
            corrGroup=corrcoef(GroupData');
            %plots
            Heats=figure("Name","Correlation Heatmaps");
            %GroupCorrelation
            subplot(1,2,1,'parent',Heats);
            h1=heatmap(GroupLabels,GroupLabels,corrGroup);
            h1.NodeChildren(3).XAxis.TickLabelInterpreter = 'none';
            h1.NodeChildren(3).YAxis.TickLabelInterpreter = 'none';
            title('Correlation between Groups')
            %SampleCorrelation
            subplot(1,2,2,'parent',Heats);
            h2=heatmap(SampleLabels,SampleLabels,corrSamp);
            h2.NodeChildren(3).XAxis.TickLabelInterpreter = 'none';
            h2.NodeChildren(3).YAxis.TickLabelInterpreter = 'none';
            title('Correlation between Samples')

        end

        % Button pushed function: Volcano
        function VolcanoButtonPushed(app, event)
            %get data
            folds=app.Stats(:,4);
            ps=app.Stats(:,5);
            FeatLabel=app.Features{1,2};
            labels=app.Features{1,4}; %get group labels
            %build permutations
            GroupFlags=app.Grouping;
            assignments=GroupFlags; %group assignment
            assignments=removecats(assignments);    %remove unused categories
            Combis=nchoosek(1:1:size(assignments,1),2);
            Combis2=nchoosek(size(assignments,1):-1:1,2);
            Combis=[Combis;Combis2];
            VulFig=figure("Name","Volcano Plots");
            for n=1:size(labels,2)
                x=folds{n,1};
                y=ps{n,1};
                if isempty(x)==true
                    continue
                end
                subplot(1,size(labels,2),n,'parent',VulFig);
                %replace NaN with 0
                if iscell(x)==false
                    lx{1,1}=log2(x);
                    lx{1,2}=-log10(y);
                    x=lx;
                else
                    x=cellfun(@(B) log2(B),x,'UniformOutput',false);
                    y=cellfun(@(A) -log10(A), y,'UniformOutput',false);
                end
                idx=Combis(:,1)==n;
                Co=Combis(idx,:);
                leg=cell(size(x,1),1);
                hold on
                for s=1:size(x,1)
                    scatter(x{s,1},y{s,1},'filled')
                    VulFig.Children(1).Children(1).DataTipTemplate.DataTipRows(1).Label="log2 Fold Change:";
                    VulFig.Children(1).Children(1).DataTipTemplate.DataTipRows(2).Label="-log10 p:";
                    VulFig.Children(1).Children(1).DataTipTemplate.DataTipRows(3)=dataTipTextRow("Feature:",FeatLabel);
                    leg{s,1}= labels{Co(s,1)} + " vs. "+labels{Co(s,2)};
                end
                title("Fold Changes of "+labels{n},Interpreter="none")
                xlabel("log2 FoldChange")
                ylabel("-log10 p")
                xline(log2(app.FoldChangeEditField.Value),'-.')
                xline(-log2(app.FoldChangeEditField.Value),'-.')
                yline(-log10(app.pVal.Value),'--r',"p Critical")
                legend(leg,Interpreter="none")
                hold off
            end
        end

        % Button pushed function: ScatterPlot
        function ScatterPlotButtonPushed(app, event)
            %scatter retention times vs mz, size: heigth bzw area
            Groups=app.Features{1,4}; %get group labels
            data=app.Stats(:,2); % Feature average Heigt or Area data
            data=vertcat(data{:});
            data(isnan(data))=0;
            rdata=abs(data);
            rdata=rdata.^2;
            rdata=rescale(rdata,0,1000);
            %get mz values and Time values from Feature Label
            mz=app.NumericFeatDat(:,1);
            rt=app.NumericFeatDat(:,2);
            % build average TIC for every group
            tics=app.RawResults(25,:);
            ticdat=cell(size(tics,2),2);
            for g=1:size(tics,2)
                ints=tics{1,g}(:,1);
                times=tics{1,g}{1,3};
                ints=cellfun(@(X) sum(X,2),ints,'UniformOutput',false);
                ints=horzcat(ints{:});
                ints=mean(ints,2);
                ints=rescale(ints,0,max(mz)*0.25);
                ticdat{g,1}=ints;
                ticdat{g,2}=times;
            end
            %Plot
            Scattr=figure("Name","Scatter Plot mz vs RT");
            if app.PeakAreaButton.Value==true
                heirea="Peak Area:";
            else
                heirea="Peak Height:";
            end
            hold on
            for n=1:size(data,1)
                idx=data(n,:)~=0;
                scatter(rt(idx),mz(idx),rdata(n,idx))
                Scattr.Children(1).Children(1).DataTipTemplate.DataTipRows(1).Label="RT:";
                Scattr.Children(1).Children(1).DataTipTemplate.DataTipRows(2).Label="mz:";
                Scattr.Children(1).Children(1).DataTipTemplate.DataTipRows(3)=dataTipTextRow(heirea,data(n,idx));
            end
            title("Scatter Plot RT vs mz, Circle size corresponds to "+heirea)
            for n=1:size(data,1)
                plot(ticdat{n,2},ticdat{n,1},'Color',[0.9,0.9,0.9])
            end
            legend(Groups,Interpreter='none')
            xlabel("RT [sec]")
            ylabel("mz [Da]")
            hold off
        end

        % Button pushed function: Chrom
        function ChromButtonPushed(app, event)
            %plot all TICs for every Group
            data=app.RawResults(25,:);
            numG=size(data,2);
            GroupNames=app.RawResults(38,:);
            filenames=app.RawResults(1,:);
            filenames=vertcat(filenames{:});
            filenames=cellfun(@(x) strsplit(x,'\'),filenames,'UniformOutput',false); %split string at \ to isolate Filename
            filenames=vertcat(filenames{:});
            filenames=filenames(:,end);
            filenames=reshape(filenames,[],numG);
            filenames=string(filenames);
            for g=1:numG
                ints=data{1,g}(:,1);
                times=data{1,g}(:,3);
                ints=cellfun(@(X) sum(X,2), ints,"UniformOutput",false);
                figure("Name","All Sample TICs for " + GroupNames{1,g});
                hold on;
                for n=1:size(ints,1)
                    plot(times{n,1},ints{n,1})
                end
                if size(filenames(:,g))<size(ints,1)
                    legend([filenames(:,g);"Average Blank File"],Interpreter='none')
                else
                    legend(filenames(:,g),Interpreter='none')
                end
                xlabel("RT [sec]")
                ylabel("Intensity [arb]")
                hold off
            end
        end

        % Button pushed function: Cloud
        function CloudButtonPushed(app, event)
            %get data
            folds=app.Stats(:,4);
            ps=app.Stats(:,5);
            Grouplabels=app.Features{1,4}; %get group labels
            %get mz values and Time values from Feature Label
            mz=app.NumericFeatDat(:,1);
            rt=app.NumericFeatDat(:,2);
            % build average TIC for every group
            tics=app.RawResults(25,:);
            ticdat=cell(size(tics,2),2);
            for g=1:size(tics,2)
                ints=tics{1,g}(:,1);
                times=tics{1,g}{1,3};
                ints=cellfun(@(X) sum(X,2),ints,'UniformOutput',false);
                ints=horzcat(ints{:});
                ints=mean(ints,2);
                ints=rescale(ints,0,max(mz)*0.25);
                ticdat{g,1}=[ints;ints*-1];
                ticdat{g,2}=[times;times];
            end
            SizeDat=cell(size(Grouplabels,2),1);
            ColDat=cell(size(Grouplabels,2),1);
            mzDat=cell(size(Grouplabels,2),1);
            for n=1:size(Grouplabels,2)
                if iscell(folds{n,1})==false
                    SizeDat{n,1}=folds{n,1};
                    ColDat{n,1}=ps{n,1};
                else
                    SizeDat=folds{n,1};
                    ColDat=ps{n,1};
                end
                if isempty(folds{n,1})==true
                    continue
                end
                %scale circlesize
                for j=1:size(SizeDat,1)
                    CircSize=SizeDat{j,1};
                    CircSize(isnan(CircSize))=0;
                    CircSize(isinf(CircSize))=0;
                    CircSize=abs(CircSize);
                    CircSize=rescale(CircSize,0,500);
                    SizeDat{j,2}=CircSize;
                    CircCol=ColDat{j,1};
                    CircCol(isnan(CircCol))=0;
                    CircCol(isinf(CircCol))=0;
                    ColDat{j,2}=CircCol;
                end

                if isempty(SizeDat)==true
                    continue
                end
                %change sign when Foldchange < 1
                if iscell(SizeDat)==false
                    idx=SizeDat(:,1) < 1;
                    mzTemp=mz;
                    mzTemp(idx)=mz(idx)*-1;
                    mzDat{n,1}=mzTemp;
                else
                    idx=cellfun(@(B) B < 1 ,SizeDat(:,1),'UniformOutput',false);
                    mztemp=mz;
                    for k=1:size(idx,1)
                        mztemp(idx{k,1})=mztemp(idx{k,1})*-1;
                    end
                    mzDat{n,1}=mztemp;
                end
                Siz{n,1}=SizeDat;
                Col{n,1}=ColDat;
            end
            for n=1:size(Grouplabels,2)
                figure("Name","Cloud Plot")
                hold on
                SizeDat=Siz{n,1};
                ColDat=Col{n,1};
                for s=1:size(SizeDat,1)
                    idx=SizeDat{s,2}~=0;
                    scatter(rt(idx),mzDat{s,1}(idx),SizeDat{s,2}(idx),ColDat{s,2}(idx),'filled')
                    colormap(gca,"copper")
                    ax=gca;
                    ax.Children(1).DataTipTemplate.DataTipRows(1).Label="Time:";
                    ax.Children(1).DataTipTemplate.DataTipRows(2).Label="mz:";
                    ax.Children(1).DataTipTemplate.DataTipRows(3)=dataTipTextRow("Fold Change:",SizeDat{s,1}(idx));
                    ax.Children(1).DataTipTemplate.DataTipRows(4)=dataTipTextRow("p-Value:",ColDat{s,1}(idx));
                end
                lebar = colorbar;
                lebar.Label.String = 'p-Value';
                title("Fold Changes of "+Grouplabels{n},Interpreter="none")
                ylabel("mz [Da]")
                xlabel("Time [sec]")
                for t=1:size(ticdat,1)
                    plot(ticdat{t,2},ticdat{t,1},'Color',[0.9,0.9,0.9])
                end
                hold off
            end
        end

        % Button pushed function: Reject
        function RejectButtonPushed(app, event)
            labels=app.Features{1,4};
            reject=app.RejectedFeat;
            cause= categorical(["Minimum Peak Width"; "Maximum Peak Width"; "Minimum Peak Height"; "Minimum Signal to Noise"; "Entropy";"Number of Occurences";"p-Value"]);
            cause=reordercats(cause,{'Minimum Peak Width','Maximum Peak Width','Minimum Peak Height','Minimum Signal to Noise','Entropy','Number of Occurences','p-Value'});
            RejFig=figure("Name","Number of rejected Features by Filter");
            ax=axes(RejFig);
            b=bar(ax,cause,reject);
            ylim([0,max(reject,[],'all')+max(reject,[],'all')*0.2])
            legend(labels)
            title("Number of Rejected Features")
            ylabel("Number of Features Rejected")
            for n=1:length(labels)
                xtips1 = b(n).XEndPoints;
                ytips1 = b(n).YEndPoints;
                labels1 = string(b(n).YData);
                text(xtips1,ytips1,labels1,'HorizontalAlignment','center','VerticalAlignment','bottom')
            end

        end

        % Button pushed function: EntroHist
        function EntroHistButtonPushed(app, event)
            meanEntropy=app.iEntropy{1,1};
            allEntropy=app.iEntropy{1,2}(1,:);
            labels=app.Features{1,4};
            EntroFig=figure("Name","Entropy and Signal to Noise Distributions");
            for n=1:size(labels,2)
                subplot(2,size(labels,2),n,'parent',EntroFig);
                histogram(allEntropy{1,n}(:,1))
                hold on
                xline(meanEntropy(n),"-.","Median Entropy")
                title("Entropy Distribution for "+labels(n))
                xlabel("Peak Entropy")
                ylabel("Counts per bin")
                hold off
                subplot(2,size(labels,2),n+size(labels,2),'parent',EntroFig);
                histogram(allEntropy{1,n}(:,2))
                title("Signal to Noise Distribution for "+labels(n))
                xlabel("Signal to Noise")
                ylabel("Counts per bin")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Main = uifigure('Visible', 'off');
            app.Main.Name = 'MATLAB App';
            app.Main.CloseRequestFcn = createCallbackFcn(app, @MainCloseRequest, true);
            app.AriumMSEvalLabel = uilabel(app.Main);
            app.AriumMSEvalLabel.Text = 'AriumMS Eval';
            app.MultivariateDataAnalysisPanel = uipanel(app.Main);
            app.MultivariateDataAnalysisPanel.Title = 'Multivariate Data Analysis';
            app.PCAButton = uibutton(app.MultivariateDataAnalysisPanel, 'push');
            app.PCAButton.ButtonPushedFcn = createCallbackFcn(app, @PCAButtonPushed, true);
            app.PCAButton.Enable = 'off';
            app.PCAButton.Text = 'PCA';
            app.HeatmapButton = uibutton(app.MultivariateDataAnalysisPanel, 'push');
            app.HeatmapButton.ButtonPushedFcn = createCallbackFcn(app, @HeatmapButtonPushed, true);
            app.HeatmapButton.Enable = 'off';
            app.HeatmapButton.Text = 'Heatmap';
            app.DataVisualizationPanel = uipanel(app.Main);
            app.DataVisualizationPanel.Title = 'Data Visualization';
            app.ScatterPlot = uibutton(app.DataVisualizationPanel, 'push');
            app.ScatterPlot.ButtonPushedFcn = createCallbackFcn(app, @ScatterPlotButtonPushed, true);
            app.ScatterPlot.Enable = 'off';
            app.ScatterPlot.Text = 'Scatter Plot';
            app.EntroHist = uibutton(app.DataVisualizationPanel, 'push');
            app.EntroHist.ButtonPushedFcn = createCallbackFcn(app, @EntroHistButtonPushed, true);
            app.EntroHist.Enable = 'off';
            app.EntroHist.Text = {'Entropy and S/N'; 'Distribution'};
            app.Volcano = uibutton(app.DataVisualizationPanel, 'push');
            app.Volcano.ButtonPushedFcn = createCallbackFcn(app, @VolcanoButtonPushed, true);
            app.Volcano.Enable = 'off';
            app.Volcano.Text = 'Volcano Plot';
            app.Cloud = uibutton(app.DataVisualizationPanel, 'push');
            app.Cloud.ButtonPushedFcn = createCallbackFcn(app, @CloudButtonPushed, true);
            app.Cloud.Enable = 'off';
            app.Cloud.Text = 'Cloud Plot';
            app.Chrom = uibutton(app.DataVisualizationPanel, 'push');
            app.Chrom.ButtonPushedFcn = createCallbackFcn(app, @ChromButtonPushed, true);
            app.Chrom.Text = 'Plot TICs';
            app.Reject = uibutton(app.DataVisualizationPanel, 'push');
            app.Reject.ButtonPushedFcn = createCallbackFcn(app, @RejectButtonPushed, true);
            app.Reject.Enable = 'off';
            app.Reject.Text = 'Rejected Features';
            app.ISTDProcessingoptionsPanel = uipanel(app.Main);
            app.ISTDProcessingoptionsPanel.Title = 'ISTD Processing options';
            app.ButtonGroup = uibuttongroup(app.ISTDProcessingoptionsPanel);
            app.ISTD_then_BLK = uiradiobutton(app.ButtonGroup);
            app.ISTD_then_BLK.Text = 'ISTD normalization bevor Blank subtraction';
            app.ISTD_then_BLK.Value = true;
            app.BLK_then_ISTD = uiradiobutton(app.ButtonGroup);
            app.BLK_then_ISTD.Text = 'ISTD normalization after Blank subtraction';
            app.ISTDnormalizationDropDownLabel = uilabel(app.ISTDProcessingoptionsPanel);
            app.ISTDnormalizationDropDownLabel.Text = 'ISTD normalization';
            app.ISTDnormalizationDropDown = uidropdown(app.ISTDProcessingoptionsPanel);
            app.ISTDnormalizationDropDown.Items = {'no Normalization', 'only Sample', 'Sample and Blank'};
            app.ISTDnormalizationDropDown.ItemsData = {'0', '1', '2'};
            app.ISTDnormalizationDropDown.Value = '2';
            app.MZCal = uicheckbox(app.ISTDProcessingoptionsPanel);
            app.MZCal.Text = 'Use ISTD as Mass Calibration';
            app.DataPreTreatmentPanel = uipanel(app.Main);
            app.DataPreTreatmentPanel.Title = 'Data Pre-Treatment';
            app.TransformationsButtonGroup = uibuttongroup(app.DataPreTreatmentPanel);
            app.TransformationsButtonGroup.Title = 'Transformations';
            app.noTransf = uiradiobutton(app.TransformationsButtonGroup);
            app.noTransf.Text = 'none';
            app.noTransf.Value = true;
            app.logTransf = uiradiobutton(app.TransformationsButtonGroup);
            app.logTransf.Text = 'log';
            app.powerTransf = uiradiobutton(app.TransformationsButtonGroup);
            app.powerTransf.Text = 'power';
            app.ScalingOptionsButtonGroup = uibuttongroup(app.DataPreTreatmentPanel);
            app.ScalingOptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ScalingOptionsButtonGroupSelectionChanged, true);
            app.ScalingOptionsButtonGroup.Title = 'Scaling Options';
            app.noScale = uiradiobutton(app.ScalingOptionsButtonGroup);
            app.noScale.Tooltip = {'no Scaling will be performed'};
            app.noScale.Text = 'none';
            app.noScale.Value = true;
            app.Pareto = uiradiobutton(app.ScalingOptionsButtonGroup);
            app.Pareto.Text = 'Pareto';
            app.STD = uiradiobutton(app.ScalingOptionsButtonGroup);
            app.STD.Tooltip = {'standard deviation as the scaling factor. All features have a standard deviation of  one and therefore the data is analyzed on the basis of  correlations instead of  covariance'};
            app.STD.Text = 'Auto';
            app.Vast = uiradiobutton(app.ScalingOptionsButtonGroup);
            app.Vast.Text = 'Vast';
            app.Range = uiradiobutton(app.ScalingOptionsButtonGroup);
            app.Range.Text = 'Range';
            app.Center = uiradiobutton(app.ScalingOptionsButtonGroup);
            app.Center.Tooltip = {'Mean Centering within Feature Groups.'};
            app.Center.Text = 'Centering';
            app.Level = uiradiobutton(app.ScalingOptionsButtonGroup);
            app.Level.Text = 'Level';
            app.ExportResultsButton = uibutton(app.Main, 'push');
            app.ExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultsButtonPushed, true);
            app.ExportResultsButton.Enable = 'off';
            app.ExportResultsButton.Text = 'Export Results';
            app.DefineSampleSpecificScalingFactorsButton = uibutton(app.Main, 'push');
            app.DefineSampleSpecificScalingFactorsButton.ButtonPushedFcn = createCallbackFcn(app, @DefineSampleSpecificScalingFactorsButtonPushed, true);
            app.DefineSampleSpecificScalingFactorsButton.Text = 'Define Sample Specific Scaling Factors';
            app.FeatureSortingParametersPanel = uipanel(app.Main);
            app.FeatureSortingParametersPanel.Title = 'Feature Sorting Parameters';
            app.mzToleranceDaEditFieldLabel = uilabel(app.FeatureSortingParametersPanel);
            app.mzToleranceDaEditFieldLabel.Text = 'mz Tolerance [Da]';
            app.mzTol = uieditfield(app.FeatureSortingParametersPanel, 'numeric');
            app.mzTol.Limits = [0 Inf];
            app.mzTol.Tooltip = {'maximum MZ tolerance for Feautures to be considered the same'};
            app.mzTol.Value = 0.001;
            app.RTTolerancesecEditFieldLabel = uilabel(app.FeatureSortingParametersPanel);
            app.RTTolerancesecEditFieldLabel.Text = 'RT Tolerance [sec]';
            app.RTTol = uieditfield(app.FeatureSortingParametersPanel, 'numeric');
            app.RTTol.Limits = [0 Inf];
            app.RTTol.Tooltip = {'maximum RT tolerance for Feautures to be considered the same'};
            app.RTTol.Value = 5;
            app.FeatureExtractionPanel = uipanel(app.Main);
            app.FeatureExtractionPanel.Title = 'Feature Extraction';
            app.SelectEvaluationParameterButtonGroup = uibuttongroup(app.FeatureExtractionPanel);
            app.SelectEvaluationParameterButtonGroup.Title = 'Select Evaluation Parameter:';
            app.PeakAreaButton = uiradiobutton(app.SelectEvaluationParameterButtonGroup);
            app.PeakAreaButton.Text = 'Peak Area';
            app.PeakAreaButton.Value = true;
            app.PeakHeightButton = uiradiobutton(app.SelectEvaluationParameterButtonGroup);
            app.PeakHeightButton.Text = 'Peak Height';
            app.FeatureFilterPanel = uipanel(app.FeatureExtractionPanel);
            app.FeatureFilterPanel.Title = 'Feature Filter';
            app.MinOccuranceperGroupLabel = uilabel(app.FeatureFilterPanel);
            app.MinOccuranceperGroupLabel.Text = 'Min. Occurrence per Group [%]';
            app.MinOcc = uieditfield(app.FeatureFilterPanel, 'numeric');
            app.MinOcc.Limits = [0 100];
            app.MinOcc.Tooltip = {'Features with lower number of occurences per group will be rejected.'};
            app.MinOcc.Value = 50;
            app.pValueEditFieldLabel = uilabel(app.FeatureFilterPanel);
            app.pValueEditFieldLabel.Text = 'p-Value';
            app.pVal = uieditfield(app.FeatureFilterPanel, 'numeric');
            app.pVal.Limits = [0 1];
            app.pVal.Value = 0.05;
            app.FoldChangeEditFieldLabel = uilabel(app.FeatureFilterPanel);
            app.FoldChangeEditFieldLabel.Text = 'Fold Change';
            app.FoldChangeEditField = uieditfield(app.FeatureFilterPanel, 'numeric');
            app.FoldChangeEditField.Limits = [0 Inf];
            app.FoldChangeEditField.Value = 2;
            app.Hochberg = uicheckbox(app.FeatureFilterPanel);
            app.Hochberg.ValueChangedFcn = createCallbackFcn(app, @HochbergValueChanged, true);
            app.Hochberg.Text = 'Benjamini Hochberg Filter';
            app.FalseDiscoveryRateEditFieldLabel = uilabel(app.FeatureFilterPanel);
            app.FalseDiscoveryRateEditFieldLabel.Visible = 'off';
            app.FalseDiscoveryRateEditFieldLabel.Text = 'False Discovery Rate [%]';
            app.FDR = uieditfield(app.FeatureFilterPanel, 'numeric');
            app.FDR.Limits = [0 100];
            app.FDR.Visible = 'off';
            app.FDR.Value = 10;
            app.MinimumPeakHeightEditFieldLabel = uilabel(app.FeatureFilterPanel);
            app.MinimumPeakHeightEditFieldLabel.Text = 'Minimum Peak Height [%]';
            app.MinPeakH = uieditfield(app.FeatureFilterPanel, 'numeric');
            app.MinPeakH.Limits = [0 100];
            app.MinPeakH.ValueDisplayFormat = '%.0f';
            app.MinPeakH.Tooltip = {'Minimum Peak height relative to the highest peak in each EIC'};
            app.MinPeakH.Value = 10;
            app.MinimumPeakWidthsecEditFieldLabel = uilabel(app.FeatureFilterPanel);
            app.MinimumPeakWidthsecEditFieldLabel.Text = 'Minimum Peak Width [sec]';
            app.MinPeakW = uieditfield(app.FeatureFilterPanel, 'numeric');
            app.MinPeakW.Limits = [0 Inf];
            app.MinPeakW.ValueDisplayFormat = '%.0f';
            app.MinPeakW.Tooltip = {'Peaks below this peak width will be rejected'};
            app.MinPeakW.Value = 4;
            app.MaximumPeakWidthsecEditFieldLabel = uilabel(app.FeatureFilterPanel);
            app.MaximumPeakWidthsecEditFieldLabel.Text = 'Maximum Peak Width [sec]';
            app.MaxPeakW = uieditfield(app.FeatureFilterPanel, 'numeric');
            app.MaxPeakW.Limits = [0 Inf];
            app.MaxPeakW.ValueDisplayFormat = '%.0f';
            app.MaxPeakW.Tooltip = {'Peaks above this peak width will be rejected'};
            app.MaxPeakW.Value = 45;
            app.minimumSignalNoiseLabel = uilabel(app.FeatureFilterPanel);
            app.minimumSignalNoiseLabel.Text = 'minimum Signal/Noise ';
            app.minSN = uieditfield(app.FeatureFilterPanel, 'numeric');
            app.minSN.Limits = [0 Inf];
            app.minSN.ValueDisplayFormat = '%.0f';
            app.minSN.Value = 3;
            app.EntroFilt = uicheckbox(app.FeatureFilterPanel);
            app.EntroFilt.Text = 'Peak Entropy Filter';
            app.ExtractFeaturesButton = uibutton(app.Main, 'push');
            app.ExtractFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractFeaturesButtonPushed, true);
            app.ExtractFeaturesButton.Text = 'Extract "Features"';
            app.Version10Label = uilabel(app.Main);
            app.Version10Label.Text = 'Version 1.0';
            % Show the figure after all components are created
            app.Main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AriumMSEval(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.Main)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.Main)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Main)
        end
    end
end