classdef DefISTD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        OKButton                      matlab.ui.control.Button
        DefineInternalStandardsLabel  matlab.ui.control.Label
        ISTDTable                     matlab.ui.control.Table
    end

    
    properties (Access = private)
        CallingApp % MainApp object
        numISTD    % user defined number of ISTD
        Group      % current main app Tab
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Group, numISTD)
            app.CallingApp = mainapp;
            app.Group = Group;
            app.numISTD = numISTD;
            if isempty(app.CallingApp.Paras{24,app.Group})==false
                dat = app.CallingApp.Paras{24,app.Group};
                [r,~]=size(dat);
                if r < numISTD
                    dat(numISTD,3) = 0;
                    num = (1:1:numISTD);
                    dat = [num' dat];
                else 
                    dat = dat(1:numISTD,:);
                    num = (1:1:numISTD);
                    dat = [num' dat];
                end
                app.ISTDTable.Data = dat;
            else
                dat=zeros(numISTD,4,'double');
                num = (1:1:numISTD);
                dat(:,1) = num';
                dat(:,3) = 1;
                app.ISTDTable.Data = dat;
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            %%Check Correct Inputs
            %Check missing MZ
            if any(app.ISTDTable.Data(:,2)<1) == true
                uialert(app.UIFigure,'Missing Internal Standard M/Z','Invalid Input');
            %Check missing Expected RT
            elseif any(app.ISTDTable.Data(:,3)<1) == true
                uialert(app.UIFigure,'Missing Expected Retention Time','Invalid Input');
            %Check missing Accepted Drift
            elseif any(app.ISTDTable.Data(:,4)<1) == true
                uialert(app.UIFigure,'Missing Accepted Drift','Invalid Input');
            else
                UIFigureCloseRequest(app)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            ISTD=app.ISTDTable.Data(:,2:end);
            app.CallingApp.Paras{24,app.Group} = ISTD; % Dump 
            Tag="ISTD_" + app.Group;
            Tag=convertStringsToChars(Tag);
            Check=findobj(app.CallingApp.TabGroup,'Tag',Tag);
            Check.Enable = 'on';
            Tag="ISLamp_" + app.Group;
            Tag=convertStringsToChars(Tag);
            Check=findobj(app.CallingApp.TabGroup,'Tag',Tag);
            Check.Color = [0.39,0.83,0.07];
            CheckReady(app.CallingApp)
            %Close Dialoge box
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ISTDTable = uitable(app.UIFigure);
            app.ISTDTable.ColumnName = {'Internal Standard'; 'm/z [Da]'; 'Expected RT [sec]'; 'Accepted Drift [sec]'};
            app.ISTDTable.RowName = {};
            app.ISTDTable.ColumnEditable = [false true true true];
            app.DefineInternalStandardsLabel = uilabel(app.UIFigure);
            app.DefineInternalStandardsLabel.Text = 'Define Internal Standards';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DefISTD(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end