classdef SelectAdductsNeg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Label                         matlab.ui.control.Label
        CosSim                        matlab.ui.control.NumericEditField
        minimumCosineSimilarityLabel  matlab.ui.control.Label
        DefineAdductsandNeutrallossesLabel  matlab.ui.control.Label
        AcceptButton                  matlab.ui.control.Button
        NeutralLossPanel              matlab.ui.container.Panel
        ConjungatesPanel              matlab.ui.container.Panel
        AllC                          matlab.ui.control.CheckBox
        C17                           matlab.ui.control.CheckBox
        C16                           matlab.ui.control.CheckBox
        C15                           matlab.ui.control.CheckBox
        C14                           matlab.ui.control.CheckBox
        C13                           matlab.ui.control.CheckBox
        C12                           matlab.ui.control.CheckBox
        C11                           matlab.ui.control.CheckBox
        C10                           matlab.ui.control.CheckBox
        C9                            matlab.ui.control.CheckBox
        C8                            matlab.ui.control.CheckBox
        C7                            matlab.ui.control.CheckBox
        C6                            matlab.ui.control.CheckBox
        C5                            matlab.ui.control.CheckBox
        C4                            matlab.ui.control.CheckBox
        C3                            matlab.ui.control.CheckBox
        C2                            matlab.ui.control.CheckBox
        C1                            matlab.ui.control.CheckBox
        SmallMoleculePanel            matlab.ui.container.Panel
        AllSmol                       matlab.ui.control.CheckBox
        Smol18                        matlab.ui.control.CheckBox
        Smol17                        matlab.ui.control.CheckBox
        Smol16                        matlab.ui.control.CheckBox
        Smol15                        matlab.ui.control.CheckBox
        Smol14                        matlab.ui.control.CheckBox
        Smol13                        matlab.ui.control.CheckBox
        Smol12                        matlab.ui.control.CheckBox
        Smol11                        matlab.ui.control.CheckBox
        Smol10                        matlab.ui.control.CheckBox
        Smol9                         matlab.ui.control.CheckBox
        Smol8                         matlab.ui.control.CheckBox
        Smol7                         matlab.ui.control.CheckBox
        Smol6                         matlab.ui.control.CheckBox
        Smol5                         matlab.ui.control.CheckBox
        Smol4                         matlab.ui.control.CheckBox
        Smol3                         matlab.ui.control.CheckBox
        Smol2                         matlab.ui.control.CheckBox
        Smol1                         matlab.ui.control.CheckBox
        AdductsPanel                  matlab.ui.container.Panel
        DualChargedPanel              matlab.ui.container.Panel
        Dual1                         matlab.ui.control.CheckBox
        TripleChargedPanel            matlab.ui.container.Panel
        T1                            matlab.ui.control.CheckBox
        AnalyteAdductsPanel           matlab.ui.container.Panel
        AllD                          matlab.ui.control.CheckBox
        D4                            matlab.ui.control.CheckBox
        D3                            matlab.ui.control.CheckBox
        D2                            matlab.ui.control.CheckBox
        D1                            matlab.ui.control.CheckBox
        SingleChargedPanel            matlab.ui.container.Panel
        AllSingle                     matlab.ui.control.CheckBox
        S8                            matlab.ui.control.CheckBox
        S7                            matlab.ui.control.CheckBox
        S6                            matlab.ui.control.CheckBox
        S5                            matlab.ui.control.CheckBox
        S4                            matlab.ui.control.CheckBox
        S3                            matlab.ui.control.CheckBox
        S2                            matlab.ui.control.CheckBox
        S1                            matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        CallingApp % MainApp object
        Group      % current main app Tab
        AdductSelect    % logic Vector with selected Adducts
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Group)
            app.CallingApp = mainapp;
            app.Group = Group;
            if isempty(app.CallingApp.Paras{26,app.Group}) == false && size(app.CallingApp.Paras{26,app.Group},1)==52 %previous data selected and data is negative adducts
                app.AdductSelect=app.CallingApp.Paras{26,app.Group};
                %Tick Boxes to previous Settings
                %%  Adducts
                %Single Charged
                app.S1.Value = app.AdductSelect(1);
                app.S2.Value = app.AdductSelect(3);
                app.S3.Value = app.AdductSelect(5);
                app.S4.Value = app.AdductSelect(6);
                app.S5.Value = app.AdductSelect(7);
                app.S6.Value = app.AdductSelect(8);
                app.S7.Value = app.AdductSelect(9);
                app.S8.Value = app.AdductSelect(10);

                % Dimer
                app.D1.Value = app.AdductSelect(11);
                app.D2.Value = app.AdductSelect(12);
                app.D3.Value = app.AdductSelect(13);
                app.D4.Value = app.AdductSelect(14);

                % Dual Charged
                app.Dual1.Value = app.AdductSelect(15);

                % Triple Charged
                app.T1.Value = app.AdductSelect(16);

                %% Neutral loss
                % Small Fragment
                app.Smol1.Value = app.AdductSelect(17);
                app.Smol2.Value = app.AdductSelect(18);
                app.Smol3.Value = app.AdductSelect(19);
                app.Smol4.Value = app.AdductSelect(20);
                app.Smol5.Value = app.AdductSelect(21);
                app.Smol6.Value = app.AdductSelect(22);
                app.Smol7.Value = app.AdductSelect(23);
                app.Smol8.Value = app.AdductSelect(24);
                app.Smol9.Value = app.AdductSelect(25);
                app.Smol10.Value = app.AdductSelect(26);
                app.Smol11.Value = app.AdductSelect(27);
                app.Smol12.Value = app.AdductSelect(28);
                app.Smol13.Value = app.AdductSelect(29);
                app.Smol14.Value = app.AdductSelect(30);
                app.Smol15.Value = app.AdductSelect(31);
                app.Smol16.Value = app.AdductSelect(32);
                app.Smol17.Value = app.AdductSelect(33);
                app.Smol18.Value = app.AdductSelect(34);
                % Conjungate
                app.C1.Value = app.AdductSelect(35);
                app.C2.Value = app.AdductSelect(36);
                app.C3.Value = app.AdductSelect(37);
                app.C4.Value = app.AdductSelect(38);
                app.C5.Value = app.AdductSelect(39);
                app.C6.Value = app.AdductSelect(40);
                app.C7.Value = app.AdductSelect(41);
                app.C8.Value = app.AdductSelect(42);
                app.C9.Value = app.AdductSelect(43);
                app.C10.Value = app.AdductSelect(44);
                app.C11.Value = app.AdductSelect(45);
                app.C12.Value = app.AdductSelect(46);
                app.C13.Value = app.AdductSelect(47);
                app.C14.Value = app.AdductSelect(48);
                app.C15.Value = app.AdductSelect(49);
                app.C16.Value = app.AdductSelect(50);
                app.C17.Value = app.AdductSelect(51);
                app.CosSim.Value=app.AdductSelect(52)*100;
            else
                app.AdductSelect=zeros(52,1);
            end

        end

        % Button pushed function: AcceptButton
        function AcceptButtonPushed(app, event)
            %Dump Values
            %%  Adducts
            %Single Charged
            app.AdductSelect(1) = app.S1.Value;
            app.AdductSelect(2) = app.S1.Value;
            app.AdductSelect(3) = app.S2.Value;
            app.AdductSelect(4) = app.S2.Value;
            app.AdductSelect(5) = app.S3.Value;
            app.AdductSelect(6) = app.S4.Value;
            app.AdductSelect(7) = app.S5.Value;
            app.AdductSelect(8) = app.S6.Value;
            app.AdductSelect(9) = app.S7.Value;
            app.AdductSelect(10) = app.S8.Value;

            % Dimer
            app.AdductSelect(11) = app.D1.Value;
            app.AdductSelect(12) = app.D2.Value;
            app.AdductSelect(13) = app.D3.Value;
            app.AdductSelect(14) = app.D4.Value;

            % Dual Charged
            app.AdductSelect(15) = app.Dual1.Value;

            % Triple Charged
            app.AdductSelect(16) = app.T1.Value;

            %% Neutral loss
            % Small Fragment
            app.AdductSelect(17) = app.Smol1.Value;
            app.AdductSelect(18) = app.Smol2.Value;
            app.AdductSelect(19) = app.Smol3.Value;
            app.AdductSelect(20) = app.Smol4.Value;
            app.AdductSelect(21) = app.Smol5.Value;
            app.AdductSelect(22) = app.Smol6.Value;
            app.AdductSelect(23) = app.Smol7.Value;
            app.AdductSelect(24) = app.Smol8.Value;
            app.AdductSelect(25) = app.Smol9.Value;
            app.AdductSelect(26) = app.Smol10.Value;
            app.AdductSelect(27) = app.Smol11.Value;
            app.AdductSelect(28) = app.Smol12.Value;
            app.AdductSelect(29) = app.Smol13.Value;
            app.AdductSelect(30) = app.Smol14.Value;
            app.AdductSelect(31) = app.Smol15.Value;
            app.AdductSelect(32) = app.Smol16.Value;
            app.AdductSelect(33) = app.Smol17.Value;
            app.AdductSelect(34) = app.Smol18.Value;
            % Conjungate
            app.AdductSelect(35) = app.C1.Value;
            app.AdductSelect(36) = app.C2.Value;
            app.AdductSelect(37) = app.C3.Value;
            app.AdductSelect(38) = app.C4.Value;
            app.AdductSelect(39) = app.C5.Value;
            app.AdductSelect(40) = app.C6.Value;
            app.AdductSelect(41) = app.C7.Value;
            app.AdductSelect(42) = app.C8.Value;
            app.AdductSelect(43) = app.C9.Value;
            app.AdductSelect(44) = app.C10.Value;
            app.AdductSelect(45) = app.C11.Value;
            app.AdductSelect(46) = app.C12.Value;
            app.AdductSelect(47) = app.C13.Value;
            app.AdductSelect(48) = app.C14.Value;
            app.AdductSelect(49) = app.C15.Value;
            app.AdductSelect(50) = app.C16.Value;
            app.AdductSelect(51) = app.C17.Value;
            
            app.AdductSelect(52) = app.CosSim.Value / 100;
            %%Check if any Filter is selected
            if any(app.AdductSelect) == false
                answer=uiconfirm(app.UIFigure,'No Adduct or Neutral Loss Fragment Selected','Confirm Close','Icon','warning');
            %continue?
            switch answer
                case "OK"
                    UIFigureCloseRequest(app)
                case "Cancel"
                    return
            end
            else
                UIFigureCloseRequest(app)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.Paras{26,app.Group} = app.AdductSelect; %dump data
            Tag="AddButton_" + app.Group; %reenable Select Button
            Tag=convertStringsToChars(Tag);
            Check=findobj(app.CallingApp.TabGroup,'Tag',Tag);
            Check.Enable = 'on';
            Tag="AdLamp_" + app.Group; %set Lamp to green
            Tag=convertStringsToChars(Tag);
            Check=findobj(app.CallingApp.TabGroup,'Tag',Tag);
            Check.Color = [0.39,0.83,0.07];
            CheckReady(app.CallingApp)
            %Close Dialoge box
            delete(app)
        end

        % Value changed function: AllSingle
        function AllSingleValueChanged(app, event)
            %Toogle entire Panel Single Charged Adducts
            app.S1.Value = app.AllSingle.Value;
            app.S2.Value = app.AllSingle.Value;
            app.S3.Value = app.AllSingle.Value;
            app.S4.Value = app.AllSingle.Value;
            app.S5.Value = app.AllSingle.Value;
            app.S6.Value = app.AllSingle.Value;
            app.S7.Value = app.AllSingle.Value;
            app.S8.Value = app.AllSingle.Value;
        end

        % Callback function
        function AllDualValueChanged(app, event)
            %Toogle entire Panel Dual Charged Adducts
            app.Dual1.Value = app.AllDual.Value;

        end

        % Value changed function: AllD
        function AllDValueChanged(app, event)
            %Toogle entire Panel Dimer Adducts
            app.D1.Value = app.AllD.Value;
            app.D2.Value = app.AllD.Value;
            app.D3.Value = app.AllD.Value;
            app.D4.Value = app.AllD.Value;

        end

        % Callback function
        function AllTValueChanged(app, event)
            %Toogle entire Panel Triple Charged Adducts
            app.T1.Value = app.AllT.Value;

        end

        % Value changed function: AllSmol
        function AllSmolValueChanged(app, event)
            %Toogle entire Panel Small Neutral Loss
            app.Smol1.Value = app.AllSmol.Value;
            app.Smol2.Value = app.AllSmol.Value;
            app.Smol3.Value = app.AllSmol.Value;
            app.Smol4.Value = app.AllSmol.Value;
            app.Smol5.Value = app.AllSmol.Value;
            app.Smol6.Value = app.AllSmol.Value;
            app.Smol7.Value = app.AllSmol.Value;
            app.Smol8.Value = app.AllSmol.Value;
            app.Smol9.Value = app.AllSmol.Value;
            app.Smol10.Value = app.AllSmol.Value;
            app.Smol11.Value = app.AllSmol.Value;
            app.Smol12.Value = app.AllSmol.Value;
            app.Smol13.Value = app.AllSmol.Value;
            app.Smol14.Value = app.AllSmol.Value;
            app.Smol15.Value = app.AllSmol.Value;
            app.Smol16.Value = app.AllSmol.Value;
            app.Smol17.Value = app.AllSmol.Value;
            app.Smol18.Value = app.AllSmol.Value;
        end

        % Value changed function: AllC
        function AllCValueChanged(app, event)
            app.C1.Value = app.AllC.Value;
            app.C2.Value = app.AllC.Value;
            app.C3.Value = app.AllC.Value;
            app.C4.Value = app.AllC.Value;
            app.C5.Value = app.AllC.Value;
            app.C6.Value = app.AllC.Value;
            app.C7.Value = app.AllC.Value;
            app.C8.Value = app.AllC.Value;
            app.C9.Value = app.AllC.Value;
            app.C10.Value = app.AllC.Value;
            app.C11.Value = app.AllC.Value;
            app.C12.Value = app.AllC.Value;
            app.C13.Value = app.AllC.Value;
            app.C14.Value = app.AllC.Value;
            app.C15.Value = app.AllC.Value;
            app.C16.Value = app.AllC.Value;
            app.C17.Value = app.AllC.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AdductsPanel = uipanel(app.UIFigure);
            app.AdductsPanel.Title = 'Adducts';
            app.SingleChargedPanel = uipanel(app.AdductsPanel);
            app.SingleChargedPanel.Title = 'Single Charged';
            app.S1 = uicheckbox(app.SingleChargedPanel);
            app.S1.Text = 'M+Cl';
            app.S2 = uicheckbox(app.SingleChargedPanel);
            app.S2.Text = 'M+Br';
            app.S3 = uicheckbox(app.SingleChargedPanel);
            app.S3.Text = 'M+Fac-H';
            app.S4 = uicheckbox(app.SingleChargedPanel);
            app.S4.Text = 'M+HAc-H';
            app.S5 = uicheckbox(app.SingleChargedPanel);
            app.S5.Text = 'M+NaAc-H';
            app.S6 = uicheckbox(app.SingleChargedPanel);
            app.S6.Text = 'M+TFA-H';
            app.S7 = uicheckbox(app.SingleChargedPanel);
            app.S7.Text = 'M+Na-2H';
            app.S8 = uicheckbox(app.SingleChargedPanel);
            app.S8.Text = 'M+K-2H';
            app.AllSingle = uicheckbox(app.SingleChargedPanel);
            app.AllSingle.ValueChangedFcn = createCallbackFcn(app, @AllSingleValueChanged, true);
            app.AllSingle.Text = 'All';
            app.AnalyteAdductsPanel = uipanel(app.AdductsPanel);
            app.AnalyteAdductsPanel.Title = 'Analyte Adducts';
            app.D1 = uicheckbox(app.AnalyteAdductsPanel);
            app.D1.Text = '2M-H';
            app.D2 = uicheckbox(app.AnalyteAdductsPanel);
            app.D2.Text = '2M+Fac-H';
            app.D3 = uicheckbox(app.AnalyteAdductsPanel);
            app.D3.Text = '2M+HAc-H';
            app.D4 = uicheckbox(app.AnalyteAdductsPanel);
            app.D4.Text = '3M-H';
            app.AllD = uicheckbox(app.AnalyteAdductsPanel);
            app.AllD.ValueChangedFcn = createCallbackFcn(app, @AllDValueChanged, true);
            app.AllD.Text = 'All';
            app.TripleChargedPanel = uipanel(app.AdductsPanel);
            app.TripleChargedPanel.Title = 'Triple Charged';
            app.T1 = uicheckbox(app.TripleChargedPanel);
            app.T1.Text = 'M-3H';
            app.DualChargedPanel = uipanel(app.AdductsPanel);
            app.DualChargedPanel.Title = 'Dual Charged';
            app.Dual1 = uicheckbox(app.DualChargedPanel);
            app.Dual1.Text = 'M-2H';
            app.NeutralLossPanel = uipanel(app.UIFigure);
            app.NeutralLossPanel.Title = 'Neutral Loss';
            app.SmallMoleculePanel = uipanel(app.NeutralLossPanel);
            app.SmallMoleculePanel.Title = 'Small Molecule';
            app.Smol1 = uicheckbox(app.SmallMoleculePanel);
            app.Smol1.Text = '-NH3';
            app.Smol2 = uicheckbox(app.SmallMoleculePanel);
            app.Smol2.Text = '-H2O';
            app.Smol3 = uicheckbox(app.SmallMoleculePanel);
            app.Smol3.Text = '-HCN';
            app.Smol4 = uicheckbox(app.SmallMoleculePanel);
            app.Smol4.Text = '-CO';
            app.Smol5 = uicheckbox(app.SmallMoleculePanel);
            app.Smol5.Text = '-H2CN';
            app.Smol6 = uicheckbox(app.SmallMoleculePanel);
            app.Smol6.Text = '-NO';
            app.Smol7 = uicheckbox(app.SmallMoleculePanel);
            app.Smol7.Text = '-CH2O';
            app.Smol8 = uicheckbox(app.SmallMoleculePanel);
            app.Smol8.Text = '-CH3OH';
            app.Smol9 = uicheckbox(app.SmallMoleculePanel);
            app.Smol9.Text = '-H2S';
            app.Smol10 = uicheckbox(app.SmallMoleculePanel);
            app.Smol10.Text = '-HCl';
            app.Smol11 = uicheckbox(app.SmallMoleculePanel);
            app.Smol11.Text = '-CO2';
            app.Smol12 = uicheckbox(app.SmallMoleculePanel);
            app.Smol12.Text = '-NO2';
            app.Smol13 = uicheckbox(app.SmallMoleculePanel);
            app.Smol13.Text = '-HCOOH';
            app.Smol14 = uicheckbox(app.SmallMoleculePanel);
            app.Smol14.Text = '-SO2';
            app.Smol15 = uicheckbox(app.SmallMoleculePanel);
            app.Smol15.Text = '-HPO3';
            app.Smol16 = uicheckbox(app.SmallMoleculePanel);
            app.Smol16.Text = '-H2SO3';
            app.Smol17 = uicheckbox(app.SmallMoleculePanel);
            app.Smol17.Text = '-H2PO4';
            app.Smol18 = uicheckbox(app.SmallMoleculePanel);
            app.Smol18.Text = '-HI';
            app.AllSmol = uicheckbox(app.SmallMoleculePanel);
            app.AllSmol.ValueChangedFcn = createCallbackFcn(app, @AllSmolValueChanged, true);
            app.AllSmol.Text = 'All';
            app.ConjungatesPanel = uipanel(app.NeutralLossPanel);
            app.ConjungatesPanel.Title = 'Conjungates';
            app.C1 = uicheckbox(app.ConjungatesPanel);
            app.C1.Text = '-Histidine';
            app.C2 = uicheckbox(app.ConjungatesPanel);
            app.C2.Text = '-Cysteine';
            app.C3 = uicheckbox(app.ConjungatesPanel);
            app.C3.Text = '-Didesoxyhexoside';
            app.C4 = uicheckbox(app.ConjungatesPanel);
            app.C4.Text = '-Pentoside';
            app.C5 = uicheckbox(app.ConjungatesPanel);
            app.C5.Text = '-Deoxyhexoside';
            app.C6 = uicheckbox(app.ConjungatesPanel);
            app.C6.Text = '-Glutamic Acid';
            app.C7 = uicheckbox(app.ConjungatesPanel);
            app.C7.Text = '-Hexoside';
            app.C8 = uicheckbox(app.ConjungatesPanel);
            app.C8.Text = '-N-acetylcysteine';
            app.C9 = uicheckbox(app.ConjungatesPanel);
            app.C9.Text = '-Rhamnoside';
            app.C10 = uicheckbox(app.ConjungatesPanel);
            app.C10.Text = '-Glucuronide';
            app.C11 = uicheckbox(app.ConjungatesPanel);
            app.C11.Text = '-Glucuronide (benzylic)';
            app.C12 = uicheckbox(app.ConjungatesPanel);
            app.C12.Text = '-N-acetylglucosamine (benzylic)';
            app.C13 = uicheckbox(app.ConjungatesPanel);
            app.C13.Text = '-N-acetylglucosamine';
            app.C14 = uicheckbox(app.ConjungatesPanel);
            app.C14.Text = '-Malonylglucuronide';
            app.C15 = uicheckbox(app.ConjungatesPanel);
            app.C15.Text = '-gamma GluCys';
            app.C16 = uicheckbox(app.ConjungatesPanel);
            app.C16.Text = '-Malonylglucuronide (benzylic)';
            app.C17 = uicheckbox(app.ConjungatesPanel);
            app.C17.Text = '-Gluthatione';
            app.AllC = uicheckbox(app.ConjungatesPanel);
            app.AllC.ValueChangedFcn = createCallbackFcn(app, @AllCValueChanged, true);
            app.AllC.Text = 'All';
            app.AcceptButton = uibutton(app.UIFigure, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
            app.AcceptButton.Text = 'Accept';
            app.DefineAdductsandNeutrallossesLabel = uilabel(app.UIFigure);
            app.DefineAdductsandNeutrallossesLabel.Text = 'Define Adducts and Neutral losses';
            app.minimumCosineSimilarityLabel = uilabel(app.UIFigure);
            app.minimumCosineSimilarityLabel.Text = {'minimum '; 'Peak Similarity'};
            app.CosSim = uieditfield(app.UIFigure, 'numeric');
            app.CosSim.Limits = [0 100];
            app.CosSim.RoundFractionalValues = 'on';
            app.CosSim.Value = 85;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SelectAdductsNeg(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end