classdef SelectAdductsPos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Label                         matlab.ui.control.Label
        CosSim                        matlab.ui.control.NumericEditField
        minimumCosineSimilarityLabel  matlab.ui.control.Label
        DefineAdductsandNeutrallossesLabel  matlab.ui.control.Label
        AcceptButton                  matlab.ui.control.Button
        NeutralLossPanel              matlab.ui.container.Panel
        ConjungatesPanel              matlab.ui.container.Panel
        AllC                          matlab.ui.control.CheckBox
        C17                           matlab.ui.control.CheckBox
        C16                           matlab.ui.control.CheckBox
        C15                           matlab.ui.control.CheckBox
        C14                           matlab.ui.control.CheckBox
        C13                           matlab.ui.control.CheckBox
        C12                           matlab.ui.control.CheckBox
        C11                           matlab.ui.control.CheckBox
        C10                           matlab.ui.control.CheckBox
        C9                            matlab.ui.control.CheckBox
        C8                            matlab.ui.control.CheckBox
        C7                            matlab.ui.control.CheckBox
        C6                            matlab.ui.control.CheckBox
        C5                            matlab.ui.control.CheckBox
        C4                            matlab.ui.control.CheckBox
        C3                            matlab.ui.control.CheckBox
        C2                            matlab.ui.control.CheckBox
        C1                            matlab.ui.control.CheckBox
        SmallMoleculePanel            matlab.ui.container.Panel
        AllSmol                       matlab.ui.control.CheckBox
        Smol18                        matlab.ui.control.CheckBox
        Smol17                        matlab.ui.control.CheckBox
        Smol16                        matlab.ui.control.CheckBox
        Smol15                        matlab.ui.control.CheckBox
        Smol14                        matlab.ui.control.CheckBox
        Smol13                        matlab.ui.control.CheckBox
        Smol12                        matlab.ui.control.CheckBox
        Smol11                        matlab.ui.control.CheckBox
        Smol10                        matlab.ui.control.CheckBox
        Smol9                         matlab.ui.control.CheckBox
        Smol8                         matlab.ui.control.CheckBox
        Smol7                         matlab.ui.control.CheckBox
        Smol6                         matlab.ui.control.CheckBox
        Smol5                         matlab.ui.control.CheckBox
        Smol4                         matlab.ui.control.CheckBox
        Smol3                         matlab.ui.control.CheckBox
        Smol2                         matlab.ui.control.CheckBox
        Smol1                         matlab.ui.control.CheckBox
        AdductsPanel                  matlab.ui.container.Panel
        DualChargedPanel              matlab.ui.container.Panel
        AllDual                       matlab.ui.control.CheckBox
        Dual8                         matlab.ui.control.CheckBox
        Dual7                         matlab.ui.control.CheckBox
        Dual6                         matlab.ui.control.CheckBox
        Dual5                         matlab.ui.control.CheckBox
        Dual4                         matlab.ui.control.CheckBox
        Dual3                         matlab.ui.control.CheckBox
        Dual2                         matlab.ui.control.CheckBox
        Dual1                         matlab.ui.control.CheckBox
        TripleChargedPanel            matlab.ui.container.Panel
        AllT                          matlab.ui.control.CheckBox
        T4                            matlab.ui.control.CheckBox
        T3                            matlab.ui.control.CheckBox
        T2                            matlab.ui.control.CheckBox
        T1                            matlab.ui.control.CheckBox
        AnalyteDimerPanel             matlab.ui.container.Panel
        AllD                          matlab.ui.control.CheckBox
        D6                            matlab.ui.control.CheckBox
        D5                            matlab.ui.control.CheckBox
        D4                            matlab.ui.control.CheckBox
        D3                            matlab.ui.control.CheckBox
        D2                            matlab.ui.control.CheckBox
        D1                            matlab.ui.control.CheckBox
        SingleChargedPanel            matlab.ui.container.Panel
        AllSingle                     matlab.ui.control.CheckBox
        S12                           matlab.ui.control.CheckBox
        S11                           matlab.ui.control.CheckBox
        S10                           matlab.ui.control.CheckBox
        S9                            matlab.ui.control.CheckBox
        S8                            matlab.ui.control.CheckBox
        S7                            matlab.ui.control.CheckBox
        S6                            matlab.ui.control.CheckBox
        S5                            matlab.ui.control.CheckBox
        S4                            matlab.ui.control.CheckBox
        S3                            matlab.ui.control.CheckBox
        S2                            matlab.ui.control.CheckBox
        S1                            matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        CallingApp % MainApp object
        Group      % current main app Tab
        AdductSelect    % logic Vector with selected Adducts
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Group)
            app.CallingApp = mainapp;
            app.Group = Group;
            if isempty(app.CallingApp.Paras{26,app.Group}) == false && size(app.CallingApp.Paras{26,app.Group},1)==66
                app.AdductSelect=app.CallingApp.Paras{26,app.Group};
                %Tick Boxes to previous Settings
                %%  Adducts
                %Single Charged
                app.S1.Value = app.AdductSelect(1);
                app.S2.Value = app.AdductSelect(2);
                app.S3.Value = app.AdductSelect(3);
                app.S4.Value = app.AdductSelect(4);
                app.S5.Value = app.AdductSelect(5);
                app.S6.Value = app.AdductSelect(6);
                app.S7.Value = app.AdductSelect(7);
                app.S8.Value = app.AdductSelect(8);
                app.S9.Value = app.AdductSelect(9);
                app.S10.Value = app.AdductSelect(10);
                app.S11.Value = app.AdductSelect(11);
                app.S12.Value = app.AdductSelect(12);
                % Dimer
                app.D1.Value = app.AdductSelect(13);
                app.D2.Value = app.AdductSelect(14);
                app.D3.Value = app.AdductSelect(15);
                app.D4.Value = app.AdductSelect(16);
                app.D5.Value = app.AdductSelect(17);
                app.D6.Value = app.AdductSelect(18);
                % Dual Charged
                app.Dual1.Value = app.AdductSelect(19);
                app.Dual2.Value = app.AdductSelect(20);
                app.Dual3.Value = app.AdductSelect(21);
                app.Dual4.Value = app.AdductSelect(22);
                app.Dual5.Value = app.AdductSelect(23);
                app.Dual6.Value = app.AdductSelect(24);
                app.Dual7.Value = app.AdductSelect(25);
                app.Dual8.Value = app.AdductSelect(26);
                % Triple Charged
                app.T1.Value = app.AdductSelect(27);
                app.T2.Value = app.AdductSelect(28);
                app.T3.Value = app.AdductSelect(29);
                app.T4.Value = app.AdductSelect(30);
                %% Neutral loss
                % Small Fragment
                app.Smol1.Value = app.AdductSelect(31);
                app.Smol2.Value = app.AdductSelect(32);
                app.Smol3.Value = app.AdductSelect(33);
                app.Smol4.Value = app.AdductSelect(34);
                app.Smol5.Value = app.AdductSelect(35);
                app.Smol6.Value = app.AdductSelect(36);
                app.Smol7.Value = app.AdductSelect(37);
                app.Smol8.Value = app.AdductSelect(38);
                app.Smol9.Value = app.AdductSelect(39);
                app.Smol10.Value = app.AdductSelect(40);
                app.Smol11.Value = app.AdductSelect(41);
                app.Smol12.Value = app.AdductSelect(42);
                app.Smol13.Value = app.AdductSelect(43);
                app.Smol14.Value = app.AdductSelect(44);
                app.Smol15.Value = app.AdductSelect(45);
                app.Smol16.Value = app.AdductSelect(46);
                app.Smol17.Value = app.AdductSelect(47);
                app.Smol18.Value = app.AdductSelect(48);
                % Conjungate
                app.C1.Value = app.AdductSelect(49);
                app.C2.Value = app.AdductSelect(50);
                app.C3.Value = app.AdductSelect(51);
                app.C4.Value = app.AdductSelect(52);
                app.C5.Value = app.AdductSelect(53);
                app.C6.Value = app.AdductSelect(54);
                app.C7.Value = app.AdductSelect(55);
                app.C8.Value = app.AdductSelect(56);
                app.C9.Value = app.AdductSelect(57);
                app.C10.Value = app.AdductSelect(58);
                app.C11.Value = app.AdductSelect(59);
                app.C12.Value = app.AdductSelect(60);
                app.C13.Value = app.AdductSelect(61);
                app.C14.Value = app.AdductSelect(62);
                app.C15.Value = app.AdductSelect(63);
                app.C16.Value = app.AdductSelect(64);
                app.C17.Value = app.AdductSelect(65);
                app.CosSim.Value=app.AdductSelect(66)*100;
            else
                app.AdductSelect=zeros(66,1);
            end

        end

        % Button pushed function: AcceptButton
        function AcceptButtonPushed(app, event)
            %Dump Values
            %%  Adducts
            %Single Charged
            app.AdductSelect(1) = app.S1.Value;
            app.AdductSelect(2) = app.S2.Value;
            app.AdductSelect(3) = app.S3.Value;
            app.AdductSelect(4) = app.S4.Value;
            app.AdductSelect(5) = app.S5.Value;
            app.AdductSelect(6) = app.S6.Value;
            app.AdductSelect(7) = app.S7.Value;
            app.AdductSelect(8) = app.S8.Value;
            app.AdductSelect(9) = app.S9.Value;
            app.AdductSelect(10) = app.S10.Value;
            app.AdductSelect(11) = app.S11.Value;
            app.AdductSelect(12) = app.S12.Value;
            % Dimer
            app.AdductSelect(13) = app.D1.Value;
            app.AdductSelect(14) = app.D2.Value;
            app.AdductSelect(15) = app.D3.Value;
            app.AdductSelect(16) = app.D4.Value;
            app.AdductSelect(17) = app.D5.Value;
            app.AdductSelect(18) = app.D6.Value;
            % Dual Charged
            app.AdductSelect(19) = app.Dual1.Value;
            app.AdductSelect(20) = app.Dual2.Value;
            app.AdductSelect(21) = app.Dual3.Value;
            app.AdductSelect(22) = app.Dual4.Value;
            app.AdductSelect(23) = app.Dual5.Value;
            app.AdductSelect(24) = app.Dual6.Value;
            app.AdductSelect(25) = app.Dual7.Value;
            app.AdductSelect(26) = app.Dual8.Value;
            % Triple Charged
            app.AdductSelect(27) = app.T1.Value;
            app.AdductSelect(28) = app.T2.Value;
            app.AdductSelect(29) = app.T3.Value;
            app.AdductSelect(30) = app.T4.Value;
            %% Neutral loss
            % Small Fragment
            app.AdductSelect(31) = app.Smol1.Value;
            app.AdductSelect(32) = app.Smol2.Value;
            app.AdductSelect(33) = app.Smol3.Value;
            app.AdductSelect(34) = app.Smol4.Value;
            app.AdductSelect(35) = app.Smol5.Value;
            app.AdductSelect(36) = app.Smol6.Value;
            app.AdductSelect(37) = app.Smol7.Value;
            app.AdductSelect(38) = app.Smol8.Value;
            app.AdductSelect(39) = app.Smol9.Value;
            app.AdductSelect(40) = app.Smol10.Value;
            app.AdductSelect(41) = app.Smol11.Value;
            app.AdductSelect(42) = app.Smol12.Value;
            app.AdductSelect(43) = app.Smol13.Value;
            app.AdductSelect(44) = app.Smol14.Value;
            app.AdductSelect(45) = app.Smol15.Value;
            app.AdductSelect(46) = app.Smol16.Value;
            app.AdductSelect(47) = app.Smol17.Value;
            app.AdductSelect(48) = app.Smol18.Value;
            % Conjungate
            app.AdductSelect(49) = app.C1.Value;
            app.AdductSelect(50) = app.C2.Value;
            app.AdductSelect(51) = app.C3.Value;
            app.AdductSelect(52) = app.C4.Value;
            app.AdductSelect(53) = app.C5.Value;
            app.AdductSelect(54) = app.C6.Value;
            app.AdductSelect(55) = app.C7.Value;
            app.AdductSelect(56) = app.C8.Value;
            app.AdductSelect(57) = app.C9.Value;
            app.AdductSelect(58) = app.C10.Value;
            app.AdductSelect(59) = app.C11.Value;
            app.AdductSelect(60) = app.C12.Value;
            app.AdductSelect(61) = app.C13.Value;
            app.AdductSelect(62) = app.C14.Value;
            app.AdductSelect(63) = app.C15.Value;
            app.AdductSelect(64) = app.C16.Value;
            app.AdductSelect(65) = app.C17.Value;
            
            app.AdductSelect(66) = app.CosSim.Value / 100;
            %%Check if any Filter is selected
            if any(app.AdductSelect) == false
                answer=uiconfirm(app.UIFigure,'No Adduct or Neutral Loss Fragment Selected','Confirm Close','Icon','warning');
            %continue?
            switch answer
                case "OK"
                    UIFigureCloseRequest(app)
                case "Cancel"
                    return
            end
            else
                UIFigureCloseRequest(app)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.Paras{26,app.Group} = app.AdductSelect; %dump data
            Tag="AddButton_" + app.Group; %reenable Select Button
            Tag=convertStringsToChars(Tag);
            Check=findobj(app.CallingApp.TabGroup,'Tag',Tag);
            Check.Enable = 'on';
            Tag="AdLamp_" + app.Group; %set Lamp to green
            Tag=convertStringsToChars(Tag);
            Check=findobj(app.CallingApp.TabGroup,'Tag',Tag);
            Check.Color = [0.39,0.83,0.07];
            CheckReady(app.CallingApp)
            %Close Dialoge box
            delete(app)
        end

        % Value changed function: AllSingle
        function AllSingleValueChanged(app, event)
            %Toogle entire Panel Single Charged Adducts
            app.S1.Value = app.AllSingle.Value;
            app.S2.Value = app.AllSingle.Value;
            app.S3.Value = app.AllSingle.Value;
            app.S4.Value = app.AllSingle.Value;
            app.S5.Value = app.AllSingle.Value;
            app.S6.Value = app.AllSingle.Value;
            app.S7.Value = app.AllSingle.Value;
            app.S8.Value = app.AllSingle.Value;
            app.S9.Value = app.AllSingle.Value;
            app.S10.Value = app.AllSingle.Value;
            app.S11.Value = app.AllSingle.Value;
            app.S12.Value = app.AllSingle.Value;
        end

        % Value changed function: AllDual
        function AllDualValueChanged(app, event)
            %Toogle entire Panel Dual Charged Adducts
            app.Dual1.Value = app.AllDual.Value;
            app.Dual2.Value = app.AllDual.Value;
            app.Dual3.Value = app.AllDual.Value;
            app.Dual4.Value = app.AllDual.Value;
            app.Dual5.Value = app.AllDual.Value;
            app.Dual6.Value = app.AllDual.Value;
            app.Dual7.Value = app.AllDual.Value;
            app.Dual8.Value = app.AllDual.Value;
        end

        % Value changed function: AllD
        function AllDValueChanged(app, event)
            %Toogle entire Panel Dimer Adducts
            app.D1.Value = app.AllD.Value;
            app.D2.Value = app.AllD.Value;
            app.D3.Value = app.AllD.Value;
            app.D4.Value = app.AllD.Value;
            app.D5.Value = app.AllD.Value;
            app.D6.Value = app.AllD.Value;
        end

        % Value changed function: AllT
        function AllTValueChanged(app, event)
            %Toogle entire Panel Triple Charged Adducts
            app.T1.Value = app.AllT.Value;
            app.T2.Value = app.AllT.Value;
            app.T3.Value = app.AllT.Value;
            app.T4.Value = app.AllT.Value;
        end

        % Value changed function: AllSmol
        function AllSmolValueChanged(app, event)
            %Toogle entire Panel Small Neutral Loss
            app.Smol1.Value = app.AllSmol.Value;
            app.Smol2.Value = app.AllSmol.Value;
            app.Smol3.Value = app.AllSmol.Value;
            app.Smol4.Value = app.AllSmol.Value;
            app.Smol5.Value = app.AllSmol.Value;
            app.Smol6.Value = app.AllSmol.Value;
            app.Smol7.Value = app.AllSmol.Value;
            app.Smol8.Value = app.AllSmol.Value;
            app.Smol9.Value = app.AllSmol.Value;
            app.Smol10.Value = app.AllSmol.Value;
            app.Smol11.Value = app.AllSmol.Value;
            app.Smol12.Value = app.AllSmol.Value;
            app.Smol13.Value = app.AllSmol.Value;
            app.Smol14.Value = app.AllSmol.Value;
            app.Smol15.Value = app.AllSmol.Value;
            app.Smol16.Value = app.AllSmol.Value;
            app.Smol17.Value = app.AllSmol.Value;
            app.Smol18.Value = app.AllSmol.Value;
        end

        % Value changed function: AllC
        function AllCValueChanged(app, event)
            app.C1.Value = app.AllC.Value;
            app.C2.Value = app.AllC.Value;
            app.C3.Value = app.AllC.Value;
            app.C4.Value = app.AllC.Value;
            app.C5.Value = app.AllC.Value;
            app.C6.Value = app.AllC.Value;
            app.C7.Value = app.AllC.Value;
            app.C8.Value = app.AllC.Value;
            app.C9.Value = app.AllC.Value;
            app.C10.Value = app.AllC.Value;
            app.C11.Value = app.AllC.Value;
            app.C12.Value = app.AllC.Value;
            app.C13.Value = app.AllC.Value;
            app.C14.Value = app.AllC.Value;
            app.C15.Value = app.AllC.Value;
            app.C16.Value = app.AllC.Value;
            app.C17.Value = app.AllC.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AdductsPanel = uipanel(app.UIFigure);
            app.AdductsPanel.Title = 'Adducts';
            app.SingleChargedPanel = uipanel(app.AdductsPanel);
            app.SingleChargedPanel.Title = 'Single Charged';
            app.S1 = uicheckbox(app.SingleChargedPanel);
            app.S1.Text = 'M+NH4';
            app.S2 = uicheckbox(app.SingleChargedPanel);
            app.S2.Text = 'M+Na';
            app.S3 = uicheckbox(app.SingleChargedPanel);
            app.S3.Text = 'M+K';
            app.S4 = uicheckbox(app.SingleChargedPanel);
            app.S4.Text = 'M+ACN+H';
            app.S5 = uicheckbox(app.SingleChargedPanel);
            app.S5.Text = 'M+MeOH+H';
            app.S6 = uicheckbox(app.SingleChargedPanel);
            app.S6.Text = 'M+IPA+H';
            app.S7 = uicheckbox(app.SingleChargedPanel);
            app.S7.Text = 'M+DMSO+H';
            app.S8 = uicheckbox(app.SingleChargedPanel);
            app.S8.Text = 'M+ACN+Na';
            app.S9 = uicheckbox(app.SingleChargedPanel);
            app.S9.Text = 'M+2ACN+H';
            app.S10 = uicheckbox(app.SingleChargedPanel);
            app.S10.Text = 'M+IPA+Na';
            app.S11 = uicheckbox(app.SingleChargedPanel);
            app.S11.Text = 'M+2Na-H';
            app.S12 = uicheckbox(app.SingleChargedPanel);
            app.S12.Text = 'M+2K-H';
            app.AllSingle = uicheckbox(app.SingleChargedPanel);
            app.AllSingle.ValueChangedFcn = createCallbackFcn(app, @AllSingleValueChanged, true);
            app.AllSingle.Text = 'All';
            app.AnalyteDimerPanel = uipanel(app.AdductsPanel);
            app.AnalyteDimerPanel.Title = 'Analyte Dimer';
            app.D1 = uicheckbox(app.AnalyteDimerPanel);
            app.D1.Text = '2M+H';
            app.D2 = uicheckbox(app.AnalyteDimerPanel);
            app.D2.Text = '2M+NH4';
            app.D3 = uicheckbox(app.AnalyteDimerPanel);
            app.D3.Text = '2M+Na';
            app.D4 = uicheckbox(app.AnalyteDimerPanel);
            app.D4.Text = '2M+K';
            app.D5 = uicheckbox(app.AnalyteDimerPanel);
            app.D5.Text = '2M+ACN+H';
            app.D6 = uicheckbox(app.AnalyteDimerPanel);
            app.D6.Text = '2M+ACN+Na';
            app.AllD = uicheckbox(app.AnalyteDimerPanel);
            app.AllD.ValueChangedFcn = createCallbackFcn(app, @AllDValueChanged, true);
            app.AllD.Text = 'All';
            app.TripleChargedPanel = uipanel(app.AdductsPanel);
            app.TripleChargedPanel.Title = 'Triple Charged';
            app.T1 = uicheckbox(app.TripleChargedPanel);
            app.T1.Text = 'M+3H';
            app.T2 = uicheckbox(app.TripleChargedPanel);
            app.T2.Text = 'M+Na+2H';
            app.T3 = uicheckbox(app.TripleChargedPanel);
            app.T3.Text = 'M+2Na+H';
            app.T4 = uicheckbox(app.TripleChargedPanel);
            app.T4.Text = 'M+3Na';
            app.AllT = uicheckbox(app.TripleChargedPanel);
            app.AllT.ValueChangedFcn = createCallbackFcn(app, @AllTValueChanged, true);
            app.AllT.Text = 'All';
            app.DualChargedPanel = uipanel(app.AdductsPanel);
            app.DualChargedPanel.Title = 'Dual Charged';
            app.Dual1 = uicheckbox(app.DualChargedPanel);
            app.Dual1.Text = 'M+2H';
            app.Dual2 = uicheckbox(app.DualChargedPanel);
            app.Dual2.Text = 'M+NH4+H';
            app.Dual3 = uicheckbox(app.DualChargedPanel);
            app.Dual3.Text = 'M+Na+H';
            app.Dual4 = uicheckbox(app.DualChargedPanel);
            app.Dual4.Text = 'M+K+H';
            app.Dual5 = uicheckbox(app.DualChargedPanel);
            app.Dual5.Text = 'M+2Na';
            app.Dual6 = uicheckbox(app.DualChargedPanel);
            app.Dual6.Text = 'M+ACN+2H';
            app.Dual7 = uicheckbox(app.DualChargedPanel);
            app.Dual7.Text = 'M+2ACN+2H';
            app.Dual8 = uicheckbox(app.DualChargedPanel);
            app.Dual8.Text = 'M+3ACN+2H';
            app.AllDual = uicheckbox(app.DualChargedPanel);
            app.AllDual.ValueChangedFcn = createCallbackFcn(app, @AllDualValueChanged, true);
            app.AllDual.Text = 'All';
            app.NeutralLossPanel = uipanel(app.UIFigure);
            app.NeutralLossPanel.Title = 'Neutral Loss';
            app.SmallMoleculePanel = uipanel(app.NeutralLossPanel);
            app.SmallMoleculePanel.Title = 'Small Molecule';
            app.Smol1 = uicheckbox(app.SmallMoleculePanel);
            app.Smol1.Text = '-NH3';
            app.Smol2 = uicheckbox(app.SmallMoleculePanel);
            app.Smol2.Text = '-H2O';
            app.Smol3 = uicheckbox(app.SmallMoleculePanel);
            app.Smol3.Text = '-HCN';
            app.Smol4 = uicheckbox(app.SmallMoleculePanel);
            app.Smol4.Text = '-CO';
            app.Smol5 = uicheckbox(app.SmallMoleculePanel);
            app.Smol5.Text = '-H2CN';
            app.Smol6 = uicheckbox(app.SmallMoleculePanel);
            app.Smol6.Text = '-NO';
            app.Smol7 = uicheckbox(app.SmallMoleculePanel);
            app.Smol7.Text = '-CH2O';
            app.Smol8 = uicheckbox(app.SmallMoleculePanel);
            app.Smol8.Text = '-CH3OH';
            app.Smol9 = uicheckbox(app.SmallMoleculePanel);
            app.Smol9.Text = '-H2S';
            app.Smol10 = uicheckbox(app.SmallMoleculePanel);
            app.Smol10.Text = '-HCl';
            app.Smol11 = uicheckbox(app.SmallMoleculePanel);
            app.Smol11.Text = '-CO2';
            app.Smol12 = uicheckbox(app.SmallMoleculePanel);
            app.Smol12.Text = '-NO2';
            app.Smol13 = uicheckbox(app.SmallMoleculePanel);
            app.Smol13.Text = '-HCOOH';
            app.Smol14 = uicheckbox(app.SmallMoleculePanel);
            app.Smol14.Text = '-SO2';
            app.Smol15 = uicheckbox(app.SmallMoleculePanel);
            app.Smol15.Text = '-HPO3';
            app.Smol16 = uicheckbox(app.SmallMoleculePanel);
            app.Smol16.Text = '-H2SO3';
            app.Smol17 = uicheckbox(app.SmallMoleculePanel);
            app.Smol17.Text = '-H2PO4';
            app.Smol18 = uicheckbox(app.SmallMoleculePanel);
            app.Smol18.Text = '-HI';
            app.AllSmol = uicheckbox(app.SmallMoleculePanel);
            app.AllSmol.ValueChangedFcn = createCallbackFcn(app, @AllSmolValueChanged, true);
            app.AllSmol.Text = 'All';
            app.ConjungatesPanel = uipanel(app.NeutralLossPanel);
            app.ConjungatesPanel.Title = 'Conjungates';
            app.C1 = uicheckbox(app.ConjungatesPanel);
            app.C1.Text = '-Histidine';
            app.C2 = uicheckbox(app.ConjungatesPanel);
            app.C2.Text = '-Cysteine';
            app.C3 = uicheckbox(app.ConjungatesPanel);
            app.C3.Text = '-Didesoxyhexoside';
            app.C4 = uicheckbox(app.ConjungatesPanel);
            app.C4.Text = '-Pentoside';
            app.C5 = uicheckbox(app.ConjungatesPanel);
            app.C5.Text = '-Deoxyhexoside';
            app.C6 = uicheckbox(app.ConjungatesPanel);
            app.C6.Text = '-Glutamic Acid';
            app.C7 = uicheckbox(app.ConjungatesPanel);
            app.C7.Text = '-Hexoside';
            app.C8 = uicheckbox(app.ConjungatesPanel);
            app.C8.Text = '-N-acetylcysteine';
            app.C9 = uicheckbox(app.ConjungatesPanel);
            app.C9.Text = '-Rhamnoside';
            app.C10 = uicheckbox(app.ConjungatesPanel);
            app.C10.Text = '-Glucuronide';
            app.C11 = uicheckbox(app.ConjungatesPanel);
            app.C11.Text = '-Glucuronide (benzylic)';
            app.C12 = uicheckbox(app.ConjungatesPanel);
            app.C12.Text = '-N-acetylglucosamine (benzylic)';
            app.C13 = uicheckbox(app.ConjungatesPanel);
            app.C13.Text = '-N-acetylglucosamine';
            app.C14 = uicheckbox(app.ConjungatesPanel);
            app.C14.Text = '-Malonylglucuronide';
            app.C15 = uicheckbox(app.ConjungatesPanel);
            app.C15.Text = '-gamma GluCys';
            app.C16 = uicheckbox(app.ConjungatesPanel);
            app.C16.Text = '-Malonylglucuronide (benzylic)';
            app.C17 = uicheckbox(app.ConjungatesPanel);
            app.C17.Text = '-Gluthatione';
            app.AllC = uicheckbox(app.ConjungatesPanel);
            app.AllC.ValueChangedFcn = createCallbackFcn(app, @AllCValueChanged, true);
            app.AllC.Text = 'All';
            app.AcceptButton = uibutton(app.UIFigure, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
            app.AcceptButton.Text = 'Accept';
            app.DefineAdductsandNeutrallossesLabel = uilabel(app.UIFigure);
            app.DefineAdductsandNeutrallossesLabel.Text = 'Define Adducts and Neutral losses';
            app.minimumCosineSimilarityLabel = uilabel(app.UIFigure);
            app.minimumCosineSimilarityLabel.Text = {'minimum '; 'Peak Similarity'};
            app.CosSim = uieditfield(app.UIFigure, 'numeric');
            app.CosSim.Limits = [0 100];
            app.CosSim.RoundFractionalValues = 'on';
            app.CosSim.Value = 85;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SelectAdductsPos(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end